classdef ICP_Base < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BaseMenu                     matlab.ui.container.Menu
        OpenBaseMenu                 matlab.ui.container.Menu
        SaveBaseMenu                 matlab.ui.container.Menu
        SaveBaseasMenu               matlab.ui.container.Menu
        ProjectMenu                  matlab.ui.container.Menu
        ImportProjectsMenu           matlab.ui.container.Menu
        ExportProjectsMenu           matlab.ui.container.Menu
        SessionMenu                  matlab.ui.container.Menu
        ImportSessionsMenu           matlab.ui.container.Menu
        ExportSessionMenu            matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        MontoringModuleButton        matlab.ui.control.Button
        VisModuleExperimentalButton  matlab.ui.control.Button
        DuplicateProjectButton       matlab.ui.control.Button
        DuplicateSessionButton       matlab.ui.control.Button
        SelectButton                 matlab.ui.control.Button
        WorkingDirectoryEditField    matlab.ui.control.EditField
        WorkingDirectoryLabel        matlab.ui.control.Label
        ResultsButton                matlab.ui.control.Button
        QuantificationButton         matlab.ui.control.Button
        DriftButton                  matlab.ui.control.Button
        ClassificationButton         matlab.ui.control.Button
        DeleteProjectButton          matlab.ui.control.Button
        AddProjectButton             matlab.ui.control.Button
        ProjectTable                 matlab.ui.control.Table
        IntervalButton               matlab.ui.control.Button
        DeleteSessionButton          matlab.ui.control.Button
        SessionTable                 matlab.ui.control.Table
        AddSessionButton             matlab.ui.control.Button
        ImportButton                 matlab.ui.control.Button
    end


    properties (Access = public)
        base
        installpath
        workingdirectory
    end

    properties (Access = private)

        LastSelectedProjectRow
        CurrentReleaseTag = 'GitHubTag';
        ICP_Base_version ='1.00.60210';
        
    end


    methods (Access = private)

        function update_base_GUI(app, project_id)

            update_project_table(app)
            update_session_table(app, project_id)

        end

        function update_project_table(app)

            % preallocate the columns
            project_name=cell(1,numel(app.base));
            %project_comment=cell(1,numel(app.base));

            % get the session names and other variables
            for i = 1:numel(app.base)
                % since the project name is now initialzed at
                % AddSessionButtonDown it will always have 'New Project'
                if isfield(app.base(i), 'project_name') && ~isempty(app.base(i).project_name)

                    project_name{i} = app.base(i).project_name;

                else
                    project_name{i}='New Project';
                end

            end

            % generate a table with the variables
            T=table((1:numel(project_name))', project_name');
            T.Properties.VariableNames={'#', 'Project Name'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.ProjectTable.Data = T;
            app.ProjectTable.ColumnName = T.Properties.VariableNames;

        end

        function update_session_table(app, project_id)

            % preallocate the columns
            session_name=cell(1,numel(app.base(project_id).project));
            session_comment=cell(1,numel(app.base(project_id).project));
            session_date = cell(1,numel(app.base(project_id).project));
            session_analyst = cell(1,numel(app.base(project_id).project));
            session_processor = cell(1,numel(app.base(project_id).project));

            % get the session names and other variables
            for i = 1:numel(app.base(project_id).project)

                session_name{i} = app.base(project_id).project(i).session.session_name;


                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'session_comment')...
                        && ~isempty(app.base(project_id).project(i).session.meta.session_comment)
                    session_comment{i} = app.base(project_id).project(i).session.meta.session_comment;
                else
                    session_comment{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'date')...
                        && ~isempty(app.base(project_id).project(i).session.meta.date)
                    session_date{i} = app.base(project_id).project(i).session.meta.date;
                else
                    session_date{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'analyst')...
                        && ~isempty(app.base(project_id).project(i).session.meta.analyst)
                    session_analyst{i} = app.base(project_id).project(i).session.meta.analyst;
                else
                    session_analyst{i}='';
                end

                if isfield(app.base(project_id).project(i).session, 'meta')...
                        && isfield(app.base(project_id).project(i).session.meta, 'processed_by')...
                        && ~isempty(app.base(project_id).project(i).session.meta.processed_by)
                    session_processor{i} = app.base(project_id).project(i).session.meta.processed_by;
                else
                    session_processor{i}='';
                end

            end

            % generate a table with the variables
            T=table((1:numel(session_name))', session_name', session_date', session_comment', session_analyst', session_processor');
            T.Properties.VariableNames={'#', 'Session Name', 'Date YYYYMMDD', 'Comment', 'Analyst', 'Processed by'};

            %push this table to the displayed SessionTable and give them
            %the same column names
            app.SessionTable.Data = T;
            app.SessionTable.ColumnName = T.Properties.VariableNames;

        end

        function update_progress_buttons(app, project_id, session_id)

            status = app.base(project_id).project(session_id).session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            app.ImportButton.Visible = "on";
            app.IntervalButton.Visible = "on";
            app.ClassificationButton.Visible = "on";
            app.DriftButton.Visible = "on";
            app.QuantificationButton.Visible = "on";
            app.ResultsButton.Visible = "on";

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end
        end


        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'GeoSebastianStumpf';
                repo = 'ICP-Base';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag,app.CurrentReleaseTag))

                        message = sprintf(['A new version of ICP-Base is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/GeoSebastianStumpf/ICP-Base/releases/latest');

                        uialert(app.UIFigure,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of ICP-Base installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.UIFigure,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/GeoSebastianStumpf/ICP-Base/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayError(ME,'Startup New Version Check Failed')
                end

            end
        end


        %%%%%% for VisModule %%%%%%%%

        % with errors, the original function for _ppm and _lod
        %         function Base_Table = build_table_from_base(app)
        %
        %             allProjects = {};
        %             allSessions = {};
        %             allSpotnames = {};
        %             alldates = {};
        %             allSamples = {};
        %             allMaterials = {};
        %             allTypes = {};
        %             allRefMaterials = {};
        %
        %             allIntStdElements = {};
        %             allIntStdValues = {};
        %             allIntStdOxValues = {};
        %             allFeRatios = {};
        %             allIntTimePoints = {};
        %             allbracket_id = {};
        %             allQuantifiedWith = {};
        %             allusedBracket_id = {};
        %             allIntStdElements_Incl = {};
        %             allIntStdValues_Incl = {};
        %
        %             allSensitivitiesRows = {};
        %             allmatrixcompRows = {};
        %             allinclcompRows = {};
        %             allmatrixlodRows = {};
        %             allincllodRows = {};
        %
        %
        %
        %             for i = 1:numel(app.base)
        %                 projectName = app.base(i).project_name;
        %                 for j = 1:numel(app.base(i).project)
        %
        %                     session = app.base(i).project(j).session;
        %
        %                     sessionName = char(session.session_name);
        %
        %                     if isfield(session, 'config') && isfield(session.config, 'date')
        %                         sessiondate = session.config.date;
        %                     else
        %                         sessiondate = [];
        %                     end
        %
        %                     spotnames = session.spot.name;
        %                     nSpots = numel(spotnames);
        %
        %                     % Handle missing fields for sample and material
        %                     if isfield(session.spot, 'sample') && ~isempty(session.spot.sample)
        %                         sample = session.spot.sample;
        %                     else
        %                         sample = repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'material') && ~isempty(session.spot.material)
        %                         material = session.spot.material;
        %                     else
        %                         material = repmat({''}, nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'type')
        %                         type = session.spot.type;
        %                     else
        %                         type = repmat('', nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'referencematerial')
        %                         referencematerial = session.spot.referencematerial;
        %                     else
        %                         referencematerial = repmat('', nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'bracket_id')
        %                         bracket_id = session.spot.bracket_id;
        %                     else
        %                         bracket_id = repmat('', nSpots, 1);
        %                     end
        %
        %                     if isfield(session.spot, 'integer_time_points')
        %                         integer_time_points = session.spot.integer_time_points;
        %                     else
        %                         integer_time_points = repmat('', nSpots, 1);
        %                     end
        %
        %                     prm_indices = strcmp(session.spot.type, "PRM");
        %                     prm_materials = session.spot.referencematerial(prm_indices);
        %                     unique_PRMs = unique(prm_materials);
        %
        %                     uniqueBracketIds = unique(session.spot.bracket_id);
        %                     uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
        %
        %
        %                     if isfield(session.spot, 'internal_standard')
        %                         internal_standard_element = session.spot.internal_standard;
        %                     else
        %
        %                         internal_standard_element = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 internal_standard_element{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %                     if isfield(session.spot, 'internal_standard_mass_fraction')
        %                         internal_standard_value = session.spot.internal_standard_mass_fraction;
        %                     else
        %
        %                         internal_standard_value = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 internal_standard_value{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %                     if isfield(session.spot, 'internal_standard_oxide_wt')
        %                         internal_standard_value_ox = session.spot.internal_standard_oxide_wt;
        %                     else
        %
        %                         internal_standard_value_ox = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 internal_standard_value_ox{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %                     if isfield(session.spot, 'Fe_ratio_for_total_oxide')
        %                         Fe_ratio_for_total_oxide = session.spot.Fe_ratio_for_total_oxide;
        %                     else
        %
        %                         Fe_ratio_for_total_oxide = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 Fe_ratio_for_total_oxide{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %                     if isfield(session.spot, 'sensitivities')
        %                         sensitivities = session.spot.sensitivities;
        %                     else
        %
        %                         sensitivities = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         analyteNames = session.spot.analytes; % 42x1 cell array
        %                         analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 sensitivities{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                     'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                     'VariableNames', analyteNames);
        %                             end
        %                         end
        %                     end
        %
        %                     if isfield(session.spot, 'matrix_composition')
        %                         matrix_composition = session.spot.matrix_composition;
        %                     else
        %
        %                         matrix_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         analyteNames = session.spot.analytes; % 42x1 cell array
        %                         analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 matrix_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                     'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                     'VariableNames', analyteNames);
        %                             end
        %                         end
        %                     end
        %
        %                     if isfield(session.spot, 'matrix_limits_of_detection')
        %                         matrix_limits_of_detection = session.spot.matrix_limits_of_detection;
        %                     else
        %
        %                         matrix_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         analyteNames = session.spot.analytes; % 42x1 cell array
        %                         analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 matrix_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                     'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                     'VariableNames', analyteNames);
        %                             end
        %                         end
        %                     end
        %
        %
        %                     %%%%%%%%%%
        %
        %                     if isfield(session.spot, 'internal_standard_inclusion')
        %                         internal_standard_inclusion = session.spot.internal_standard_inclusion;
        %                     else
        %
        %                         internal_standard_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 internal_standard_inclusion{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %                     if isfield(session.spot, 'internal_standard_mass_fraction_inclusion')
        %                         internal_standard_mass_fraction_inclusion = session.spot.internal_standard_mass_fraction_inclusion;
        %                     else
        %
        %                         internal_standard_mass_fraction_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 internal_standard_mass_fraction_inclusion{k, l} = repmat({''}, nSpots, 1);
        %                             end
        %                         end
        %
        %                     end
        %
        %
        %                     if isfield(session.spot, 'inclusion_composition')
        %                         inclusion_composition = session.spot.inclusion_composition;
        %                     else
        %
        %                         inclusion_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         analyteNames = session.spot.analytes; % 42x1 cell array
        %                         analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 inclusion_composition{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                     'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                     'VariableNames', analyteNames);
        %                             end
        %                         end
        %                     end
        %
        %                     if isfield(session.spot, 'inclusion_limits_of_detection')
        %                         inclusion_limits_of_detection = session.spot.inclusion_limits_of_detection;
        %                     else
        %
        %                         inclusion_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
        %
        %                         analyteNames = session.spot.analytes; % 42x1 cell array
        %                         analyteNames = strtrim(string(analyteNames)); % Convert to string and trim spaces
        %
        %                         for k = 1:numel(uniqueBracketIds)
        %                             for l = 1:numel(unique_PRMs)
        %                                 inclusion_limits_of_detection{k, l} = table('Size', [nSpots, numel(analyteNames)], ...
        %                                     'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
        %                                     'VariableNames', analyteNames);
        %                             end
        %                         end
        %                     end
        %
        %
        %
        %
        %                     % Now, for each combination of unique_PRM and uniqueBracketId, duplicate the session data
        %                     for p = 1:numel(unique_PRMs)
        %                         for b = 1:numel(uniqueBracketIds)
        %                             for k = 1:nSpots
        %                                 allProjects{end+1,1} = projectName;
        %
        %
        %                                 allSessions{end+1,1} = sessionName;
        %                                 alldates{end+1,1} = sessiondate;
        %                                 allSpotnames{end+1,1} = spotnames{k};
        %                                 allSamples{end+1,1} = sample{k};
        %                                 allMaterials{end+1,1} = material{k};
        %                                 allTypes{end+1,1} = type(k);
        %                                 allRefMaterials{end+1,1} = referencematerial(k);
        %                                 allIntTimePoints{end+1,1} = integer_time_points(k);
        %                                 allbracket_id{end+1,1} = bracket_id(k);
        %
        %
        %                                 allIntStdElements{end+1,1} = internal_standard_element{b,p}{k};
        %                                 allIntStdValues{end+1,1} = internal_standard_value{b,p}{k};
        %                                 allIntStdOxValues{end+1,1} = internal_standard_value_ox{b,p}{k};
        %                                 allFeRatios{end+1,1} = Fe_ratio_for_total_oxide{b,p}{k};
        %
        %                                 allIntStdElements_Incl{end+1,1} = internal_standard_inclusion{b,p}{k};
        %                                 allIntStdValues_Incl{end+1,1} = internal_standard_mass_fraction_inclusion{b,p}{k};
        %
        %                                 allQuantifiedWith{end+1,1} = unique_PRMs(p);
        %                                 allusedBracket_id{end+1,1} = uniqueBracketIds(b);
        %
        %                             end
        %
        %                             allSensitivitiesRows{end+1,1} = sensitivities{b,p};
        %
        %                             allmatrixcompRows{end+1,1} = matrix_composition{b,p};
        %                             allinclcompRows{end+1,1} = inclusion_composition{b,p};
        %                             allmatrixlodRows{end+1,1} = matrix_limits_of_detection{b,p};
        %                             allincllodRows{end+1,1} = inclusion_limits_of_detection{b,p};
        %
        %                         end
        %                     end
        %                 end
        %             end
        %
        %             alldates(cellfun(@isnumeric, alldates)) = {''};
        %
        %             % Create table
        %             Metadata_Table = table(allProjects, allSessions, alldates, allSpotnames, ...
        %                 allSamples, allMaterials, allTypes, allRefMaterials, ...
        %                 allIntTimePoints, allbracket_id, ...
        %                 allIntStdElements, allIntStdValues, allIntStdOxValues, allFeRatios,  ...
        %                 allIntStdElements_Incl, allIntStdValues_Incl,...
        %                 allQuantifiedWith, allusedBracket_id, ...
        %                 'VariableNames', {'Project', 'Session', 'Date', 'Spotname', ...
        %                 'Sample', 'Material', 'Type', 'Reference_Material', ...
        %                 'Integer_Time_Points', 'bracket_id', ...
        %                 'Internal_Standard_Element', 'Internal_Standard_Value', ...
        %                 'Internal_Standard_Oxide_Value', 'Fe_Ratio_For_Total_Oxide', ...
        %                 'Internal_Standard_Element_Inclusion', 'Internal_Standard_Value_Inclusion', ...
        %                 'Quantified_with', 'used_bracket_id'});
        %
        %
        %
        %             Metadata_Table.Date_Str = datetime(Metadata_Table.Date, 'InputFormat', 'yyyyMMDD');
        %             Metadata_Table.Date_Num = datenum(Metadata_Table.Date_Str);
        %
        %
        %             %%%%%%%%%%% Sensitivities %%%%%%%%%%%
        %             % Step 1: Get all unique headers from all tables
        %             allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allSensitivitiesRows, 'UniformOutput', false);
        %             allHeaders = unique([allHeadersCell{:}]);
        %
        %             % Step 2: Sort headers based on numeric values in their names
        %             numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
        %             [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %             sortedHeaders = allHeaders(sortIdx);
        %
        %             % Step 3: Process all tables using cellfun
        %             allSensitivitiesRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allSensitivitiesRows, 'UniformOutput', false);
        %
        %             % Step 4: Add "_sensitivity" suffix to column headers (except 'Data_Source')
        %             allSensitivitiesRows = cellfun(@(t) addSuffixToHeaders(t, '_sensitivity'), allSensitivitiesRows, 'UniformOutput', false);
        %
        %             % Step 5: Concatenate all tables vertically
        %             allSensitivitiesTable = vertcat(allSensitivitiesRows{:});
        %            % allSensitivitiesTable = addvars(allSensitivitiesTable, repmat({'Sensitivities'}, height(allSensitivitiesTable), 1), ...
        %              %   'Before', 1, 'NewVariableNames', 'Data_Source');
        %
        %             %%%%%%%%%%% Matrix %%%%%%%%%%%
        %             % Repeat the same steps for Matrix tables
        %             allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixcompRows, 'UniformOutput', false);
        %             allHeaders = unique([allHeadersCell{:}]);
        %             [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %             sortedHeaders = allHeaders(sortIdx);
        %             allmatrixcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixcompRows, 'UniformOutput', false);
        %             allmatrixcompRows = cellfun(@(t) addSuffixToHeaders(t, '_ppm'), allmatrixcompRows, 'UniformOutput', false);
        %             allmatrixcompTable = vertcat(allmatrixcompRows{:});
        %             %allmatrixcompTable = addvars(allmatrixcompTable, repmat({'Matrix_Composition'}, height(allmatrixcompTable), 1), ...
        %              %   'Before', 1, 'NewVariableNames', 'Data_Source');
        %
        %             %%%%%%%%%%% LOD %%%%%%%%%%%
        %             % Repeat the same steps for LOD tables
        %             allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allmatrixlodRows, 'UniformOutput', false);
        %             allHeaders = unique([allHeadersCell{:}]);
        %             [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %             sortedHeaders = allHeaders(sortIdx);
        %             allmatrixlodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allmatrixlodRows, 'UniformOutput', false);
        %             allmatrixlodRows = cellfun(@(t) addSuffixToHeaders(t, '_LOD'), allmatrixlodRows, 'UniformOutput', false);
        %             allmatrixlodTable = vertcat(allmatrixlodRows{:});
        %             %allmatrixlodTable = addvars(allmatrixlodTable, repmat({'Matrix_LOD'}, height(allmatrixlodTable), 1), ...
        %              %   'Before', 1, 'NewVariableNames', 'Data_Source');
        %
        %             %%%%%%%%%%% Inclusion %%%%%%%%%%%
        %             % Repeat the same steps for Inclusion tables
        %             allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allincllodRows, 'UniformOutput', false);
        %             allHeaders = unique([allHeadersCell{:}]);
        %             [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %             sortedHeaders = allHeaders(sortIdx);
        %             allincllodRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allincllodRows, 'UniformOutput', false);
        %             allincllodRows = cellfun(@(t) addSuffixToHeaders(t, '_incl_LOD'), allincllodRows, 'UniformOutput', false);
        %             allinclusionlodTable = vertcat(allincllodRows{:});
        %             %allinclusionlodTable = addvars(allinclusionlodTable, repmat({'Inclusion_LOD'}, height(allinclusionlodTable), 1), ...
        %              %   'Before', 1, 'NewVariableNames', 'Data_Source');
        %
        %             % Step 1: Get all unique headers from all tables
        %             allHeadersCell = cellfun(@(t) t.Properties.VariableNames, allinclcompRows, 'UniformOutput', false);
        %             allHeaders = unique([allHeadersCell{:}]);
        %             numericSort = @(header) sscanf(header, '%*[^0-9]%d'); % Extract numeric part
        %             [~, sortIdx] = sort(cellfun(numericSort, allHeaders));
        %             sortedHeaders = allHeaders(sortIdx);
        %             allinclcompRows = cellfun(@(t) addMissingColumns(t, sortedHeaders), allinclcompRows, 'UniformOutput', false);
        %             allinclcompRows = cellfun(@(t) addSuffixToHeaders(t, '_incl_ppm'), allinclcompRows, 'UniformOutput', false);
        %             allinclusioncompTable = vertcat(allinclcompRows{:});
        %             %allinclusioncompTable = addvars(allinclusioncompTable, repmat({'Inclusion_Composition'}, height(allinclusioncompTable), 1), ...
        %              %   'Before', 1, 'NewVariableNames', 'Data_Source');
        %
        %
        %             %%%%%%%%%%% Helper Function %%%%%%%%%%%
        %             function t = addSuffixToHeaders(t, suffix)
        %                 % Add a suffix to all column headers except 'Data_Source'
        %                 headers = t.Properties.VariableNames;
        %                 for i = 1:length(headers)
        %                     if ~strcmp(headers{i}, 'Data_Source')
        %                         headers{i} = [headers{i}, suffix];
        %                     end
        %                 end
        %                 t.Properties.VariableNames = headers;
        %             end
        %             % Helper function to add missing columns and reorder
        %             function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
        %                 % Create a table with zeros for all missing columns
        %                 missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
        %                 missingData = zeros(height(inputTable), numel(missingCols));
        %                 missingTable = array2table(missingData, 'VariableNames', missingCols);
        %
        %                 % Combine existing table with missing columns and reorder
        %                 tableWithAllColumns = [inputTable missingTable];
        %                 tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
        %             end
        %
        %
        %             % concatenate all the tables
        %             Base_Table = [Metadata_Table, allmatrixcompTable]; % , allinclusioncompTable, allmatrixlodTable, allinclusionlodTable, allSensitivitiesTable
        %
        %             Base_Table.Sample_Material = join([Base_Table.Sample Base_Table.Material],' ');
        %             Base_Table.SRM_Check = join([cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false) cellfun(@char, Base_Table.Type, 'UniformOutput', false)],' ');
        %
        %
        %             Base_Table.Type = cellfun(@char, Base_Table.Type, 'UniformOutput', false);
        %             Base_Table.Material = cellfun(@char, Base_Table.Material, 'UniformOutput', false);
        %             Base_Table.Reference_Material = cellfun(@char, Base_Table.Reference_Material, 'UniformOutput', false);
        %             Base_Table.Quantified_with = cellfun(@char, Base_Table.Quantified_with, 'UniformOutput', false);
        %
        %
        %             % Identify numeric columns in the table
        %             numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
        %
        %             % Replace 0 with NaN only in numeric columns
        %             Base_Table{:, numericCols}(Base_Table{:, numericCols} == 0) = NaN;
        %
        %         end
        %


        function Base_Table = build_table_from_base(app)

            allProjects = {};
            allSessions = {};
            allSpotnames = {};
            alldates = {};
            allSamples = {};
            allMaterials = {};
            allTypes = {};
            allRefMaterials = {};

            allIntStdElements = {};
            allIntStdValues = {};
            allIntStdOxValues = {};
            allFeRatios = {};
            allIntTimePoints = {};
            allbracket_id = {};
            allQuantifiedWith = {};
            allusedBracket_id = {};
            allIntStdElements_Incl = {};
            allIntStdValues_Incl = {};

            allSensitivitiesRows = {};
            allmatrixcompRows = {};
            allinclcompRows = {};
            allmatrixlodRows = {};
            allincllodRows = {};

            for i = 1:numel(app.base)
                projectName = app.base(i).project_name;

                for j = 1:numel(app.base(i).project)

                    if isfield(app.base(i).project(j), 'session')
                        session = app.base(i).project(j).session;
                    else
                        continue
                    end


                    % Session meta
                    if isfield(session, 'session_name') && ~isempty(session.session_name)
                        sessionName = char(session.session_name);
                    else
                        sessionName = '';
                    end

                    if isfield(session, 'config') && isfield(session.config, 'date') && ~isempty(session.config.date)
                        sessiondate = session.config.date;
                    else
                        sessiondate = '';
                    end

                    % Spot block
                    if ~isfield(session, 'spot') || isempty(session.spot)
                        continue
                    end

                    spotnames = session.spot.name;
                    nSpots = numel(spotnames);

                    % Sample, material
                    if isfield(session.spot, 'sample') && ~isempty(session.spot.sample)
                        sample = session.spot.sample(:);
                    else
                        sample = repmat({''}, nSpots, 1);
                    end

                    if isfield(session.spot, 'material') && ~isempty(session.spot.material)
                        material = session.spot.material(:);
                    else
                        material = repmat({''}, nSpots, 1);
                    end

                    % Type and PRMs
                    if isfield(session.spot, 'type') && ~isempty(session.spot.type)
                        type = string(session.spot.type(:));
                    else
                        type = strings(nSpots, 1);
                    end

                    % Reference material
                    if isfield(session.spot, 'referencematerial') && ~isempty(session.spot.referencematerial)
                        referencematerial = string(session.spot.referencematerial(:));
                    else
                        referencematerial = strings(nSpots, 1);
                    end

                    % Determine PRMs from type == "PRM"
                    prm_indices = (type == "PRM");
                    if any(prm_indices)
                        prm_materials = referencematerial(prm_indices);
                        unique_PRMs = unique(prm_materials);
                    else
                        unique_PRMs = string.empty(0,1);
                    end

                    % Bracket IDs
                    if isfield(session.spot, 'bracket_id') && ~isempty(session.spot.bracket_id)
                        bracket_id = session.spot.bracket_id(:);
                        if ~isnumeric(bracket_id)
                            bracket_id = zeros(nSpots,1);
                        end
                        uniqueBracketIds = unique(bracket_id);
                        uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
                    else
                        bracket_id = zeros(nSpots, 1); % 0 marks missing
                        uniqueBracketIds = [];
                    end

                    % Integer time points
                    if isfield(session.spot, 'integer_time_points') && ~isempty(session.spot.integer_time_points)
                        integer_time_points = session.spot.integer_time_points(:);
                        if ~isnumeric(integer_time_points)
                            integer_time_points = NaN(nSpots,1);
                        end
                    else
                        integer_time_points = NaN(nSpots, 1);
                    end

                    % Analyte names
                    if isfield(session.spot, 'analytes') && ~isempty(session.spot.analytes)
                        analyteNames = strtrim(string(session.spot.analytes(:)));
                    else
                        analyteNames = strings(0,1);
                    end

                    % Internal standard elements (strings)
                    if isfield(session.spot, 'internal_standard')
                        internal_standard_element = session.spot.internal_standard;
                    else
                        internal_standard_element = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                internal_standard_element{b, p} = repmat({''}, nSpots, 1);
                            end
                        end
                    end

                    % Internal standard mass fraction (numeric)
                    if isfield(session.spot, 'internal_standard_mass_fraction')
                        internal_standard_value = session.spot.internal_standard_mass_fraction;
                    else
                        internal_standard_value = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                internal_standard_value{b, p} = NaN(nSpots, 1);
                            end
                        end
                    end

                    % Internal standard oxide wt (numeric)
                    if isfield(session.spot, 'internal_standard_oxide_wt')
                        internal_standard_value_ox = session.spot.internal_standard_oxide_wt;
                    else
                        internal_standard_value_ox = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                internal_standard_value_ox{b, p} = NaN(nSpots, 1);
                            end
                        end
                    end

                    % Fe ratio for total oxide (numeric)
                    if isfield(session.spot, 'Fe_ratio_for_total_oxide')
                        Fe_ratio_for_total_oxide = session.spot.Fe_ratio_for_total_oxide;
                    else
                        Fe_ratio_for_total_oxide = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                Fe_ratio_for_total_oxide{b, p} = NaN(nSpots, 1);
                            end
                        end
                    end

                    % Sensitivities tables
                    if isfield(session.spot, 'sensitivities')
                        sensitivities = session.spot.sensitivities;
                    else
                        sensitivities = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                sensitivities{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', cellstr(analyteNames));
                            end
                        end
                    end

                    % Matrix composition tables
                    if isfield(session.spot, 'matrix_composition')
                        matrix_composition = session.spot.matrix_composition;
                    else
                        matrix_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                matrix_composition{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', cellstr(analyteNames));
                            end
                        end
                    end

                    % Matrix LOD tables
                    if isfield(session.spot, 'matrix_limits_of_detection')
                        matrix_limits_of_detection = session.spot.matrix_limits_of_detection;
                    else
                        matrix_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                matrix_limits_of_detection{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', cellstr(analyteNames));
                            end
                        end
                    end

                    % Inclusion internal standard element (strings)
                    if isfield(session.spot, 'internal_standard_inclusion')
                        internal_standard_inclusion = session.spot.internal_standard_inclusion;
                    else
                        internal_standard_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                internal_standard_inclusion{b, p} = repmat({''}, nSpots, 1);
                            end
                        end
                    end

                    % Inclusion internal standard mass fraction (numeric)
                    if isfield(session.spot, 'internal_standard_mass_fraction_inclusion')
                        internal_standard_mass_fraction_inclusion = session.spot.internal_standard_mass_fraction_inclusion;
                    else
                        internal_standard_mass_fraction_inclusion = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                internal_standard_mass_fraction_inclusion{b, p} = NaN(nSpots, 1);
                            end
                        end
                    end

                    % Inclusion composition tables
                    if isfield(session.spot, 'inclusion_composition')
                        inclusion_composition = session.spot.inclusion_composition;
                    else
                        inclusion_composition = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                inclusion_composition{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', cellstr(analyteNames));
                            end
                        end
                    end

                    % Inclusion LOD tables
                    if isfield(session.spot, 'inclusion_limits_of_detection')
                        inclusion_limits_of_detection = session.spot.inclusion_limits_of_detection;
                    else
                        inclusion_limits_of_detection = cell(numel(uniqueBracketIds), numel(unique_PRMs));
                        for b = 1:numel(uniqueBracketIds)
                            for p = 1:numel(unique_PRMs)
                                inclusion_limits_of_detection{b, p} = table('Size', [nSpots, numel(analyteNames)], ...
                                    'VariableTypes', repmat("double", 1, numel(analyteNames)), ...
                                    'VariableNames', cellstr(analyteNames));
                            end
                        end
                    end

                    % Skip if no PRMs or no brackets (as requested: disregard empties)
                    if isempty(unique_PRMs) || isempty(uniqueBracketIds)
                        continue
                    end

                    % Duplicate per PRM and per bracket
                    for p = 1:numel(unique_PRMs)
                        for b = 1:numel(uniqueBracketIds)
                            for k = 1:nSpots
                                allProjects{end+1,1} = projectName;
                                allSessions{end+1,1} = sessionName;
                                alldates{end+1,1} = sessiondate;
                                allSpotnames{end+1,1} = spotnames{k};
                                allSamples{end+1,1} = sample{k};
                                allMaterials{end+1,1} = material{k};

                                if numel(type) >= k && ~ismissing(type(k))
                                    allTypes{end+1,1} = type(k);
                                else
                                    allTypes{end+1,1} = "";
                                end

                                if numel(referencematerial) >= k && ~ismissing(referencematerial(k))
                                    allRefMaterials{end+1,1} = referencematerial(k);
                                else
                                    allRefMaterials{end+1,1} = "";
                                end

                                allIntTimePoints{end+1,1} = integer_time_points(k);
                                allbracket_id{end+1,1} = bracket_id(k);

                                allIntStdElements{end+1,1}     = getNestedValue(internal_standard_element, b, p, k, "");
                                allIntStdValues{end+1,1}       = getNestedValue(internal_standard_value, b, p, k, NaN);
                                allIntStdOxValues{end+1,1}     = getNestedValue(internal_standard_value_ox, b, p, k, NaN);
                                allFeRatios{end+1,1}           = getNestedValue(Fe_ratio_for_total_oxide, b, p, k, NaN);

                                allIntStdElements_Incl{end+1,1} = getNestedValue(internal_standard_inclusion, b, p, k, "");
                                allIntStdValues_Incl{end+1,1}   = getNestedValue(internal_standard_mass_fraction_inclusion, b, p, k, NaN);

                                allQuantifiedWith{end+1,1} = unique_PRMs(p);
                                allusedBracket_id{end+1,1} = uniqueBracketIds(b);
                            end

                            % Ensure analyte tables are valid for this {b,p}
                            allSensitivitiesRows{end+1,1} = ensureAnalyteTable(sensitivities{b,p}, analyteNames, nSpots);
                            allmatrixcompRows{end+1,1}   = ensureAnalyteTable(matrix_composition{b,p}, analyteNames, nSpots);
                            allinclcompRows{end+1,1}     = ensureAnalyteTable(inclusion_composition{b,p}, analyteNames, nSpots);
                            allmatrixlodRows{end+1,1}    = ensureAnalyteTable(matrix_limits_of_detection{b,p}, analyteNames, nSpots);
                            allincllodRows{end+1,1}      = ensureAnalyteTable(inclusion_limits_of_detection{b,p}, analyteNames, nSpots);
                        end
                    end
                end
            end

            % Clean dates if numerics slipped in
            if ~isempty(alldates)
                try
                    numMask = cellfun(@isnumeric, alldates);
                    alldates(numMask) = {''};
                catch
                    % ignore
                end
            end

            % Create metadata table
            Metadata_Table = table(allProjects, allSessions, alldates, allSpotnames, ...
                allSamples, allMaterials, allTypes, allRefMaterials, ...
                allIntTimePoints, allbracket_id, ...
                allIntStdElements, allIntStdValues, allIntStdOxValues, allFeRatios,  ...
                allIntStdElements_Incl, allIntStdValues_Incl, ...
                allQuantifiedWith, allusedBracket_id, ...
                'VariableNames', {'Project', 'Session', 'Date', 'Spotname', ...
                'Sample', 'Material', 'Type', 'Reference_Material', ...
                'Integer_Time_Points', 'bracket_id', ...
                'Internal_Standard_Element', 'Internal_Standard_Value', ...
                'Internal_Standard_Oxide_Value', 'Fe_Ratio_For_Total_Oxide', ...
                'Internal_Standard_Element_Inclusion', 'Internal_Standard_Value_Inclusion', ...
                'Quantified_with', 'used_bracket_id'});

            % Date parsing
            if ~isempty(Metadata_Table)
                try
                    Metadata_Table.Date_Str = datetime(Metadata_Table.Date, 'InputFormat', 'yyyyMMdd');
                catch
                    Metadata_Table.Date_Str = datetime(Metadata_Table.Date);
                end
                Metadata_Table.Date_Num = datenum(Metadata_Table.Date_Str);
            end

            % Build each numeric block safely
            % Matrix composition
            allmatrixcompTable = buildNumericBlock(allmatrixcompRows, 'Matrix_Composition');
            % Matrix LOD
            allmatrixlodTable = buildNumericBlock(allmatrixlodRows, 'Matrix_LOD');
            % Inclusion LOD
            allinclusionlodTable = buildNumericBlock(allincllodRows, 'Inclusion_LOD');
            % Inclusion composition
            allinclusioncompTable = buildNumericBlock(allinclcompRows, 'Inclusion_Composition');
            % Sensitivities (if you need it, keep; else we skip concatenation later)
            allSensitivitiesTable = buildNumericBlock(allSensitivitiesRows, 'Sensitivities');

            % Concatenate with metadata and drop all-zero rows
            Matrix_Comp_Table = attachMetaAndFilter(Metadata_Table, allmatrixcompTable);
            Matrix_LOD_Table  = attachMetaAndFilter(Metadata_Table, allmatrixlodTable);
            Incl_Comp_Table   = attachMetaAndFilter(Metadata_Table, allinclusioncompTable);
            Incl_LOD_Table    = attachMetaAndFilter(Metadata_Table, allinclusionlodTable);
            % Sensitivities_Table = attachMetaAndFilter(Metadata_Table, allSensitivitiesTable);

            % Final base table
            Base_Table = vertcat(Matrix_Comp_Table, Matrix_LOD_Table, Incl_Comp_Table, Incl_LOD_Table);

            % Derived string keys
            if ~isempty(Base_Table)
                Base_Table.Sample_Material_DataSource = join([string(Base_Table.Sample), string(Base_Table.Material), string(Base_Table.Data_Source)], ' ');
                Base_Table.SRM_Check = join([string(Base_Table.Reference_Material), string(Base_Table.Type), string(Base_Table.Data_Source)], ' ');

                % Normalize some textual columns to cellstr for downstream compatibility
                Base_Table.Type = normalizeToCellstr(Base_Table.Type);
                Base_Table.Material = normalizeToCellstr(Base_Table.Material);
                Base_Table.Reference_Material = normalizeToCellstr(Base_Table.Reference_Material);
                Base_Table.Quantified_with = normalizeToCellstr(Base_Table.Quantified_with);

                % Replace 0 with NaN in numeric columns only
                if ~isempty(Base_Table)
                    numericCols = varfun(@isnumeric, Base_Table, 'OutputFormat', 'uniform');
                    if any(numericCols)
                        X = Base_Table{:, numericCols};
                        X(X == 0) = NaN;
                        Base_Table{:, numericCols} = X;
                    end
                end
            end

            % ----------------- Helpers -----------------
            function val = getNestedValue(C, b, p, k, defaultVal)
                % Safely extracts C{b,p}{k} allowing various shapes and types
                val = defaultVal;
                try
                    if iscell(C) && numel(C) >= (p-1)*size(C,1)+b && ~isempty(C)
                        X = C{b,p};
                        if iscell(X)
                            if numel(X) >= k && ~isempty(X{k})
                                val = X{k};
                            end
                        elseif isstring(X) || ischar(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        elseif isnumeric(X)
                            if numel(X) >= k
                                val = X(k);
                            end
                        end
                    end
                catch
                    val = defaultVal;
                end
            end

            function T = ensureAnalyteTable(T, analyteNames_, nRows_)
                % Ensure a table with nRows_ rows and analyte columns exists
                if ~istable(T) || isempty(T)
                    T = table('Size', [nRows_, numel(analyteNames_)], ...
                        'VariableTypes', repmat("double", 1, numel(analyteNames_)), ...
                        'VariableNames', cellstr(analyteNames_));
                    return
                end
                % Coerce non-double variables to double
                for v = 1:width(T)
                    if ~isnumeric(T{:,v})
                        try
                            T{:,v} = double(T{:,v});
                        catch
                            T{:,v} = zeros(height(T),1);
                        end
                    end
                end
                % Fix height
                if height(T) ~= nRows_
                    headers = T.Properties.VariableNames;
                    TT = table('Size', [nRows_, numel(headers)], ...
                        'VariableTypes', repmat("double", 1, numel(headers)), ...
                        'VariableNames', headers);
                    nCopy = min(height(T), nRows_);
                    if nCopy > 0
                        TT(1:nCopy, :) = T(1:nCopy, :);
                    end
                    T = TT;
                end
            end

            function sortedHeaders = getSortedHeaders(rowsCell)
                if isempty(rowsCell)
                    sortedHeaders = {};
                    return
                end
                isT = cellfun(@istable, rowsCell);
                if ~any(isT)
                    sortedHeaders = {};
                    return
                end
                headersCell = cellfun(@(t) t.Properties.VariableNames, rowsCell(isT), 'UniformOutput', false);
                if isempty(headersCell)
                    sortedHeaders = {};
                    return
                end
                allHeaders = unique([headersCell{:}], 'stable');
                nums = cellfun(@localExtractNum, allHeaders);
                [~, sortIdx] = sort(nums); % NaNs go last
                sortedHeaders = allHeaders(sortIdx);
            end

            function n = localExtractNum(h)
                tok = regexp(h, '\d+', 'match', 'once');
                if isempty(tok)
                    n = NaN;
                else
                    n = str2double(tok);
                end
            end

            function tableWithAllColumns = addMissingColumns(inputTable, targetHeaders)
                if isempty(targetHeaders)
                    tableWithAllColumns = inputTable;
                    return
                end
                missingCols = setdiff(targetHeaders, inputTable.Properties.VariableNames, 'stable');
                if isempty(missingCols)
                    tableWithAllColumns = inputTable(:, targetHeaders);
                    return
                end
                missingData = zeros(height(inputTable), numel(missingCols));
                missingTable = array2table(missingData, 'VariableNames', missingCols);
                tableWithAllColumns = [inputTable missingTable];
                tableWithAllColumns = tableWithAllColumns(:, targetHeaders);
            end

            function outTable = buildNumericBlock(rowsCell, label)
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                headers = getSortedHeaders(rowsCell);
                if ~isempty(rowsCell)
                    rowsCell = cellfun(@(t) addMissingColumns(t, headers), rowsCell, 'UniformOutput', false);
                end
                if isempty(rowsCell)
                    outTable = table();
                    return
                end
                outTable = vertcat(rowsCell{:});
                if isempty(outTable)
                    return
                end
                outTable = addvars(outTable, repmat({label}, height(outTable), 1), ...
                    'Before', 1, 'NewVariableNames', 'Data_Source');
            end

            function T = attachMetaAndFilter(metaT, dataT)
                if isempty(dataT)
                    T = table();
                    return
                end
                % Align row counts
                if height(metaT) ~= height(dataT)
                    % Best effort: truncate to min rows
                    n = min(height(metaT), height(dataT));
                    metaT = metaT(1:n, :);
                    dataT = dataT(1:n, :);
                end
                T = [metaT, dataT];

                % Remove rows where all analyte values (after Data_Source) are zero
                colIdx = find(strcmp(T.Properties.VariableNames, 'Data_Source'), 1);
                if ~isempty(colIdx) && colIdx < width(T)
                    numericData = T{:, colIdx+1:end};
                    if ~isempty(numericData)
                        rowsToRemove = all(numericData == 0, 2);
                        T = T(~rowsToRemove, :);
                    end
                end
            end

            function C = normalizeToCellstr(C)
                if istable(C) || isstruct(C)
                    return
                end
                if iscell(C)
                    try
                        C = cellfun(@char, C, 'UniformOutput', false);
                    catch
                        C = cellfun(@(x) char(string(x)), C, 'UniformOutput', false);
                    end
                else
                    C = cellstr(string(C));
                end
            end


        end


    end
    methods (Access = public)

        % this function needs to sort everything back in the base
        function [project_id, session_id] = save_to_base(app, project_id, session, session_id, project_name, settings)
            try
                %% sort in the session using the ids
                %% if the id is a number higher than 1, it will just go to that place

                % if I open the import module from the base, this is not needed
                % because the project_id is sent to the Import_Module
                % if the user decides to input 0 for all the project_ids
                if isequal(project_id, 0) || isempty(project_id)
                    project_id = numel(app.base) + 1;
                end

                % make an empty struct if a new project is created throught the
                % API
                if project_id > numel(app.base)
                    app.base(project_id).project = struct();
                end

                % these are for sorting the sessions back into the right projects
                if numel(app.base) == 0 % completely new base initialzed by API
                    session_id = 1;
                elseif isempty(fieldnames(app.base(project_id).project)) || ~isfield(app.base(project_id).project, 'session') || isempty(app.base(project_id).project)
                    session_id = 1;
                elseif isequal(session_id, 0) || isempty(session_id)
                    session_id = numel(app.base(project_id).project) + 1;
                end

                % save the session at the right place in the base
                app.base(project_id).project(session_id).session = session;

                %% session and project names

                app.base(project_id).project_name = project_name;

                % check if there is a session_name already in the session
                % struct, if not name it New Session
                if ~isfield(app.base(project_id).project(session_id).session, 'session_name') || isempty(app.base(project_id).project(session_id).session.session_name)
                    app.base(project_id).project(session_id).session.session_name = 'New Session';
                end

                %% GUI update
                if isfield(settings, 'ICP_Base_GUI_update_state') && settings.ICP_Base_GUI_update_state == true
                    update_base_GUI(app, project_id)

                    update_progress_buttons(app, project_id, session_id)
                end
            catch ME
                DisplayError(ME,'Error - save_to_base')
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            if isdeployed %standalone mode.
                try
                    if ispc
                        [~, result] = system('path');
                        app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    elseif ismac
                        path=ctfroot;
                        disp('ctfroot:')
                        disp(path)

                        path_split=split(path,{'ICP_Base.app'});
                        disp('path_split')
                        disp(path_split)

                        app.installpath=path_split{1};
                        disp('Installation Directory 0:')
                        disp(app.installpath)
                        disp('')

                        if not(exist(fullfile(app.installpath,'RM_Files','SRM_610.csv'),'file')) % this is shit and needs to be changed
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                        end
                    elseif isunix
                        try
                            app.installpath=pwd;
                        catch
                            answer = uiconfirm(app.UIFigure,{'Application directory not found - Select ICP-Base directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');

                        end
                    end

                    tmp_path='';

                    if exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')
                        tmp_path= fileread(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'));
                        disp('tmp_path 0:')
                        disp(tmp_path)
                        if exist(tmp_path,'dir')
                            try
                                diary(fullfile(tmp_path,'ICP-Base_log.txt'))
                                user_path=tmp_path;
                            catch
                                disp('could not use user_path to create logfile')
                                tmp_path='There was an error logfile could not be created';
                            end
                        end
                    end

                    if isempty(tmp_path) || not(exist(tmp_path,'dir'))

                        user_path=userpath;
                        disp('Userpath -2:')
                        disp(user_path)

                        user_path=replace(user_path,'/MATLAB','');
                        user_path=replace(user_path,'\MATLAB','');
                        disp('Userpath -1:')
                        disp(user_path)
                        user_path=fullfile(user_path,'ICP-Base');

                        disp('Userpath 0:')
                        disp(user_path)


                        if ~exist(user_path,'dir')
                            try
                                disp('Userpath does not exist')
                                [status, msg, ~] = mkdir(user_path);
                                if status==0
                                    answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                                    disp('Userpath folder could not be created:')
                                    disp(msg)
                                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                    delete(f)
                                    %   disp('Userpath folder could not be created')
                                else
                                    disp('Userpath folder created:')
                                    disp(user_path)
                                end

                            catch ME
                                DisplayError(ME,'Startup Error mkdir')

                                if ~exist(user_path,'dir')
                                    answer = uiconfirm(app.UIFigure,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                                    % uialert(app.UIFigure,'To avoide this message create a folder called ICP-Base in your Documents folder',' Error');
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                end
                            end
                        end
                    end

                    diary(fullfile(user_path,'ICP-Base_log.txt'));

                    disp('Userpath 1:')
                    disp(user_path)

                    disp('Installation Directory:')
                    disp(app.installpath)
                    disp('')

                    disp('tmp_path 1:')
                    disp(tmp_path)
                    disp('')


                    app.WorkingDirectoryEditField.Value=user_path;
                    app.WorkingDirectoryEditField.Tooltip=user_path;

                    cd(user_path);
                    if not(exist(fullfile(app.installpath,'Dev','ICP-BaseWorkingDir'),'file')) || isempty(tmp_path)
                        try
                            dir2exp=fullfile(app.installpath,'Dev','ICP-BaseWorkingDir');
                            disp('dir2exp:')
                            disp(dir2exp)

                            [fileID,errmsg] = fopen(dir2exp,'w');
                            if fileID==-1
                                dir2exp2=fullfile(user_path,'ICP-BaseWorkingDir');
                                [fileID,errmsg] = fopen(dir2exp2,'w');
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                                destination=fullfile(app.installpath,'Dev');
                                [status,msg,~]= movefile(dir2exp2, destination,'f');


                                if status==false
                                    disp('Error msg:')
                                    disp(msg)
                                end
                            else
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                            end
                        catch ME
                            disp('errmsg:')
                            disp(errmsg)

                            DisplayError(ME,'Startup Error ICP-BaseWorkingDir file')

                            ans1= uiconfirm(app.UIFigure, ...
                                {'ICP-BaseWorkingDir file - Permission Error!',...
                                'To fix this Error you have to options:', '-'...
                                '1) Execute the following command in the terminal:',...
                                [ 'chmod -R 755 ' app.installpath ],'-',...
                                '2) Manually add a path to your default working directory folder with read/write permission to the file:'...
                                , fullfile(app.installpath,'Dev','ICP-BaseWorkingDir') },"Permission Error", ...
                                "Icon","warning");
                            disp(ans1)

                        end
                    end
                catch ME
                    DisplayError(ME,'Startup Error 1')
                end

            else
                user_path=fileparts(which('ICP_Base.mlapp'));
                app.installpath=user_path;
                app.WorkingDirectoryEditField.Value=pwd;
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            end


            %% check for new version
            % check_for_update_fun(app)


        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)

            %% get the project_id as the selection of the ProjectTable

            project_id = app.LastSelectedProjectRow;

            if isempty(project_id)
                message = "Select a Project first";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return
            end

            session_id = 0;
            Import_Module(app, app.base(project_id).project_name, project_id, session_id)


        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton, ResultsButton
        function ImportButtonPushed(app, event)
            try
                % check if selection is not empty
                if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                    % session_id is the selection
                    session_id = app.SessionTable.Selection(1);
                    % the project_id is the last selected project in the
                    % project table
                    project_id = app.LastSelectedProjectRow;

                    switch event.Source.Parent.Parent.Parent.CallbackObject.Text
                        case 'Show Session in Import Module'
                            Import_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Interval Module'
                            Interval_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Classification Module'
                            Classification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Drift Module'
                            Drift_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Quantification Module'
                            Quantification_Module(app, app.base(project_id).project_name, project_id, session_id)
                        case 'Show Session in Results Module'
                            Results_Module(app, app.base(project_id).project_name, project_id, session_id)
                    end

                end

            catch ME
                DisplayError(ME,'Error when proceeding from the module buttons')
            end


        end

        % Cell edit callback: SessionTable
        function SessionTableCellEdit(app, event)

            % get the session table selection
            sel_row_session = app.SessionTable.Selection(1);
            sel_col_session = app.SessionTable.Selection(2);

            % this takes the LastSelectedProjectRow
            if strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Session Name')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.session_name = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Comment')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.meta.session_comment = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Date YYYYMMDD')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.meta.date = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Analyst')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.meta.analyst = event.NewData;
            elseif strcmp(app.SessionTable.ColumnName{sel_col_session}, 'Processed by')
                app.base(app.LastSelectedProjectRow).project(sel_row_session).session.meta.processed_by = event.NewData;
            end

        end

        % Callback function
        function SaveProjectMenuSelected(app, event)
            %% this also needs the project_id to know which project to save

            % if isprop(app,'projectpath') && not(isempty(app.base.projectpath))
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            %
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            %
            %     save(app.base.projectpath, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            %
            % else
            %     SaveProjectasMenuSelected(app)
            % end
            %
            % if isprop(app,'projectpath')
            %
            %     app.UIFigure.Name = app.base.projectpath;
            %
            % end
        end

        % Callback function
        function SaveProjectasMenuSelected(app, event)


            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            % delete(f);
            % figure(app.UIFigure);
            %
            % if not(isequal(filename,0))
            %     FileName = fullfile(filepath, filename);
            %     app.base.projectpath = FileName;
            %     app.UIFigure.Name = [app.base.projectpath ' [... Saving Project]'] ;
            %
            %     data2save.project = app.base.project;
            %     data2save.projectpath = app.base.projectpath;
            %
            %     save(FileName, 'data2save', '-v7.3');
            %     app.UIFigure.Name = app.base.projectpath;
            % end
        end

        % Callback function
        function OpenProjectMenuSelected(app, event)

            %     if isfield(app.base,'projectpath') && not(isempty(app.base.projectpath))
            %         [~, baseFileNameNoExt, ~] = fileparts(app.base.projectpath);
            %         projectname=[' ' baseFileNameNoExt];
            %
            %     else
            %         projectname='';
            %     end
            %     answer = questdlg({['Do you want to save your project' projectname '?']},...
            %         '','Yes','No','Cancel','');
            %     switch answer
            %         case 'Yes'
            %             SaveProjectMenuSelected(app)
            %         case 'Cancel'
            %             return
            %     end
            %
            %
            % f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            % [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            % delete(f);
            % figure(app.UIFigure);
            %
            % if not(isequal(file,0))
            %     data2import = load(fullfile(path,file));
            %
            %     app.base.project = data2import.data2save.project;
            %     app.base.projectpath = data2import.data2save.projectpath;
            % else
            %     return
            % end
            %
            % update_session_table(app)


        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            %% initializing empty projects in the base
            if not(isfield(app.base, 'project'))

                app.base(1).project = struct();
                app.base(1).project_name = 'New Project';

            else % the entry_id

                entry_id = numel(app.base) + 1;

                app.base(entry_id).project = struct();
                app.base(entry_id).project_name = 'New Project';

            end

            update_project_table(app)
        end

        % Callback function: ProjectTable
        function ProjectTableSelectionChanged(app, event)
            %% updates the session table when a project is selected
            if ~isempty(app.ProjectTable.Selection)
                if not(isempty(app.ProjectTable.Selection(1)))
                    selection = app.ProjectTable.Selection(1);
                else
                    return
                end

                if not(isempty(fieldnames(app.base(selection).project))) && isfield(app.base(selection).project, 'session')
                    update_session_table(app, selection)
                else
                    app.SessionTable.Data = {};
                end
            else
                return
            end

            %% failsafe if a project is clicked on but then it is clicked
            % somewhere in the ProjectTable where there is no project, the
            % selection gets set to [] and the conncection to the project
            % is lost
            % so the last clicked on row is always saved to always get back
            % to the project
            % Check if there is a selection in the ProjectTable
            if isempty(app.ProjectTable.Selection)
                % Use the last stored selection if the current selection is empty
                app.LastSelectedProjectRow = app.LastSelectedProjectRow;
            else
                % Update the last stored selection with the current selection
                app.LastSelectedProjectRow = app.ProjectTable.Selection(1);
            end

            % if Tableselection changed make all buttons just grey

            app.ImportButton.Visible = "off";
            app.IntervalButton.Visible = "off";
            app.ClassificationButton.Visible = "off";
            app.DriftButton.Visible = "off";
            app.QuantificationButton.Visible = "off";
            app.ResultsButton.Visible = "off";

            app.SessionTable.Selection = [];
        end

        % Menu selected function: SaveBaseMenu
        function SaveBaseMenuSelected(app, event)

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');


            if isfield(app.base, 'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;
                try

                    save(app.base(1).meta.basepath, 'data2save', '-v7.3');

                catch

                    message = "Saving the base failed. Did you change the file location of the base (.mat) file? Use the 'Save Base as' option.";
                    uialert(app.UIFigure,message,"Warning", ...
                        "Icon","warning");

                end

                app.UIFigure.Name = app.base(1).meta.basepath;

            else
                SaveBaseasMenuSelected(app)
            end

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath')

                app.UIFigure.Name = app.base(1).meta.basepath;

            end

            delete(progress)
        end

        % Menu selected function: OpenBaseMenu
        function OpenBaseMenuSelected(app, event)

            if isfield(app.base,'meta') && isfield(app.base(1).meta, 'basepath') && not(isempty(app.base(1).meta.basepath))
                [~, baseFileNameNoExt, ~] = fileparts(app.base(1).meta.basepath);
                basename=[' ' baseFileNameNoExt];

            else
                basename='';
            end
            if ~isempty(app.base)
                answer = questdlg({['Do you want to save' basename '?']},...
                    '','Yes','No','Cancel','');
                switch answer
                    case 'Yes'
                        SaveBaseMenuSelected(app)
                    case 'Cancel'
                        return
                end
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Base');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(file,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Opening Base...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                data2import = load(fullfile(path,file));

                app.base = data2import.data2save.base;

                app.base(1).meta.basepath = fullfile(path,file);

                delete(progress)
            else
                return
            end

            update_project_table(app)


        end

        % Cell edit callback: ProjectTable
        function ProjectTableCellEdit(app, event)
            % get the session table selection
            sel_row_project = app.ProjectTable.Selection(1);
            sel_col_project = app.ProjectTable.Selection(2);


            if strcmp(app.ProjectTable.ColumnName{sel_col_project}, 'Project Name')
                app.base(sel_row_project).project_name = event.NewData;
            end
        end

        % Menu selected function: SaveBaseasMenu
        function SaveBaseasMenuSelected(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the base file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Base...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                FileName = fullfile(filepath, filename);
                app.base(1).meta.basepath = FileName;
                app.UIFigure.Name = [app.base(1).meta.basepath ' [... Saving Base]'] ;

                data2save.base = app.base;

                save(FileName, 'data2save', '-v7.3');
                app.UIFigure.Name = app.base(1).meta.basepath;

                delete(progress)
            end
        end

        % Button pushed function: DuplicateProjectButton
        function DuplicateProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(end+1)= app.base(selection);
            else
                return
            end

            update_project_table(app)
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            if ~isempty(app.ProjectTable.Selection)
                %% deleting selected project
                selection = app.ProjectTable.Selection(1);
                app.base(selection) = [];
            else
                return
            end

            update_project_table(app)
        end

        % Callback function: SessionTable
        function SessionTableSelectionChanged(app, event)
            % session_id is the selection

            if ~isempty(app.SessionTable.Selection) && not(isequal(app.SessionTable.Selection(1),0))

                session_id = app.SessionTable.Selection(1);
                % the project_id is the last selected project in the
                % project table
                project_id = app.LastSelectedProjectRow;
                update_progress_buttons(app, project_id, session_id)
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            figure(app.UIFigure);

            if path==0
                app.WorkingDirectoryEditField.Value=pwd;
            else
                app.WorkingDirectoryEditField.Value = path;
                app.workingdirectory = path;
            end
            app.WorkingDirectoryEditField.Tooltip = app.WorkingDirectoryEditField.Value;

            WorkingDirectoryEditFieldValueChanged(app)


        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;

            if not(exist(value,'dir'))
                uiconfirm(app.UIFigure,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                figure(app.UIFigure);

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value = path;
                else
                    app.WorkingDirectoryEditField.Value=pwd;
                end
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;
                diary('ICP-Base_log.txt');
            else
                diary('ICP-Base_log.txt');
            end
        end

        % Button pushed function: DeleteSessionButton
        function DeleteSessionButtonPushed(app, event)

            if ~isempty(app.SessionTable.Selection)
                %% deleting selected session
                selection = app.SessionTable.Selection(1);

                app.base(app.ProjectTable.Selection(1)).project(selection) = [];

            else
                return
            end

            update_session_table(app, app.ProjectTable.Selection(1))


        end

        % Button pushed function: DuplicateSessionButton
        function DuplicateSessionButtonPushed(app, event)
            if ~isempty(app.SessionTable.Selection)
                %% deleting selected session
                selection = app.SessionTable.Selection(1);

                app.base(app.ProjectTable.Selection(1)).project(end + 1) = app.base(app.ProjectTable.Selection(1)).project(selection);

            else
                return
            end

            update_session_table(app, app.ProjectTable.Selection(1))


        end

        % Button pushed function: VisModuleExperimentalButton
        function VisModuleExperimentalButtonPushed(app, event)

            Table_builder_Submodule(app)
        
        end

        % Button pushed function: MontoringModuleButton
        function MontoringModuleButtonPushed(app, event)
          
            Base_Table = build_table_from_base(app);

            VisModule(Base_Table)
            
            
           %Monitoring_Module(app)
        end

        % Menu selected function: ExportProjectsMenu
        function ExportProjectsMenuSelected2(app, event)
            
            if isempty(app.LastSelectedProjectRow)

                message = "No project selected";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return

            end

            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Project...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                FileName = fullfile(filepath, filename);
                app.UIFigure.Name = [FileName ' [... Saving Project]'] ;

                data2save.project = app.base(app.LastSelectedProjectRow).project;
                data2save.project_name = app.base(app.LastSelectedProjectRow).project_name;

                save(FileName, 'data2save', '-v7.3');
                app.UIFigure.Name = FileName;

                delete(progress)
            end

        end

        % Menu selected function: ImportProjectsMenu
        function ImportProjectsMenuSelected(app, event)
            

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","on", 'Import Project(s)');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(file,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Importing Project(s)...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');


                % Normalize file selection to cell array for uniform handling
                if ischar(file)
                    file = {file};
                end

                % Import each selected project
                for i = 1:numel(file)
                    data2import = load(fullfile(path, file{i}));
                    entry_id = numel(app.base) + 1;
                    app.base(entry_id).project = data2import.data2save.project;
                    app.base(entry_id).project_name = data2import.data2save.project_name;
                end

                delete(progress)
            else
                return
            end

            update_project_table(app)


        end

        % Menu selected function: ImportSessionsMenu
        function ImportSessionsMenuSelected(app, event)
            
            if isempty(app.LastSelectedProjectRow)

                message = "No project selected";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return

            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","on", 'Import Session(s)');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(file,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Importing Session(s)...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');


                % Normalize file selection to cell array for uniform handling
                if ischar(file)
                    file = {file};
                end

                % Import each selected project
                for i = 1:numel(file)

                    data2import = load(fullfile(path, file{i}));

                    if isempty(fieldnames(app.base(app.LastSelectedProjectRow).project))
                        entry_id = 1;
                    else
                        entry_id = numel(app.base(app.LastSelectedProjectRow).project) + 1;
                    end

                    app.base(app.LastSelectedProjectRow).project(entry_id).session = data2import.data2save.session;

                end

                delete(progress)
            else
                return
            end

            update_session_table(app, app.LastSelectedProjectRow)


        end

        % Menu selected function: ExportSessionMenu
        function ExportSessionMenuSelected(app, event)


            if isempty(app.LastSelectedProjectRow)

                message = "No project selected";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return

            elseif isempty(app.SessionTable.Selection)

                message = "No session selected";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                return

            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the session file:');
            delete(f);
            figure(app.UIFigure);

            if not(isequal(filename,0))

                progress = uiprogressdlg(app.UIFigure, 'Title', 'Saving Session...', ...
                    'Message', 'This may take a few seconds', 'Cancelable', 'off', 'Indeterminate','on');

                FileName = fullfile(filepath, filename);
                app.UIFigure.Name = [FileName ' [... Saving Session]'] ;

                session_id = app.SessionTable.Selection(1);

                data2save.session = app.base(app.LastSelectedProjectRow).project(session_id).session;

                save(FileName, 'data2save', '-v7.3');
                app.UIFigure.Name = FileName;

                delete(progress)
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Prompt user to save before closing
            selection = uiconfirm(app.UIFigure, ...
                'Did you save the base to your computer?', ...
                'Confirm Close', ...
                'Options', {'Save and Close', 'Close without Saving', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);

            switch selection
                case 'Save and Close'
                    
                    SaveBaseMenuSelected(app)
                    delete(app);
                case 'Close without Saving'
                    delete(app);
                case 'Cancel'
                    % Do nothing - keep app open
                    return;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ICP-Base';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.OpenBaseMenu = uimenu(app.BaseMenu);
            app.OpenBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenBaseMenuSelected, true);
            app.OpenBaseMenu.Text = 'Open Base';
            app.SaveBaseMenu = uimenu(app.BaseMenu);
            app.SaveBaseMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseMenuSelected, true);
            app.SaveBaseMenu.Text = 'Save Base';
            app.SaveBaseasMenu = uimenu(app.BaseMenu);
            app.SaveBaseasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBaseasMenuSelected, true);
            app.SaveBaseasMenu.Text = 'Save Base as';
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.ImportProjectsMenu = uimenu(app.ProjectMenu);
            app.ImportProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportProjectsMenuSelected, true);
            app.ImportProjectsMenu.Text = 'Import Project(s)';
            app.ExportProjectsMenu = uimenu(app.ProjectMenu);
            app.ExportProjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportProjectsMenuSelected2, true);
            app.ExportProjectsMenu.Text = 'Export Project(s)';
            app.SessionMenu = uimenu(app.UIFigure);
            app.SessionMenu.Text = 'Session';
            app.ImportSessionsMenu = uimenu(app.SessionMenu);
            app.ImportSessionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportSessionsMenuSelected, true);
            app.ImportSessionsMenu.Text = 'Import Session(s)';
            app.ExportSessionMenu = uimenu(app.SessionMenu);
            app.ExportSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSessionMenuSelected, true);
            app.ExportSessionMenu.Text = 'Export Session';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImportButton = uibutton(app.GridLayout, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Show Session in Import Module';
            app.AddSessionButton = uibutton(app.GridLayout, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.SessionTable = uitable(app.GridLayout);
            app.SessionTable.ColumnName = '';
            app.SessionTable.RowName = {};
            app.SessionTable.ColumnEditable = [false true true true true true];
            app.SessionTable.CellEditCallback = createCallbackFcn(app, @SessionTableCellEdit, true);
            app.SessionTable.SelectionChangedFcn = createCallbackFcn(app, @SessionTableSelectionChanged, true);
            app.SessionTable.Multiselect = 'off';
            app.DeleteSessionButton = uibutton(app.GridLayout, 'push');
            app.DeleteSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSessionButtonPushed, true);
            app.DeleteSessionButton.Text = 'Delete Session';
            app.IntervalButton = uibutton(app.GridLayout, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Show Session in Interval Module';
            app.ProjectTable = uitable(app.GridLayout);
            app.ProjectTable.ColumnName = '';
            app.ProjectTable.RowName = {};
            app.ProjectTable.ColumnEditable = [false true];
            app.ProjectTable.CellEditCallback = createCallbackFcn(app, @ProjectTableCellEdit, true);
            app.ProjectTable.SelectionChangedFcn = createCallbackFcn(app, @ProjectTableSelectionChanged, true);
            app.ProjectTable.Multiselect = 'off';
            app.AddProjectButton = uibutton(app.GridLayout, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Text = 'Add Project';
            app.DeleteProjectButton = uibutton(app.GridLayout, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Text = 'Delete Project';
            app.ClassificationButton = uibutton(app.GridLayout, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Show Session in Classification Module';
            app.DriftButton = uibutton(app.GridLayout, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Show Session in Drift Module';
            app.QuantificationButton = uibutton(app.GridLayout, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.QuantificationButton.Text = 'Show Session in Quantification Module';
            app.ResultsButton = uibutton(app.GridLayout, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Show Session in Results Module';
            app.WorkingDirectoryLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryLabel.Text = 'Working Directory:';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.WorkingDirectoryEditField.Editable = 'off';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.DuplicateSessionButton = uibutton(app.GridLayout, 'push');
            app.DuplicateSessionButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateSessionButtonPushed, true);
            app.DuplicateSessionButton.Text = 'Duplicate Session';
            app.DuplicateProjectButton = uibutton(app.GridLayout, 'push');
            app.DuplicateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateProjectButtonPushed, true);
            app.DuplicateProjectButton.Text = 'Duplicate Project';
            app.VisModuleExperimentalButton = uibutton(app.GridLayout, 'push');
            app.VisModuleExperimentalButton.ButtonPushedFcn = createCallbackFcn(app, @VisModuleExperimentalButtonPushed, true);
            app.VisModuleExperimentalButton.Text = 'VisModule (Experimental)';
            app.MontoringModuleButton = uibutton(app.GridLayout, 'push');
            app.MontoringModuleButton.ButtonPushedFcn = createCallbackFcn(app, @MontoringModuleButtonPushed, true);
            app.MontoringModuleButton.Enable = 'off';
            app.MontoringModuleButton.Text = 'Montoring Module';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICP_Base

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end