classdef Quantification_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BaseMenu                       matlab.ui.container.Menu
        SaveSessiontoBaseandExitMenu   matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ChangeTotalOxidesFolderMenu    matlab.ui.container.Menu
        ShowOxidesthatwillbeusedMenu   matlab.ui.container.Menu
        GridLayout2                    matlab.ui.container.GridLayout
        ProgressPanel                  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ResultsButton                  matlab.ui.control.Button
        QuantificationButton           matlab.ui.control.Button
        DriftButton                    matlab.ui.control.Button
        ClassificationButton           matlab.ui.control.Button
        ImportButton                   matlab.ui.control.Button
        IntervalButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        InternalStandardizationTab     matlab.ui.container.Tab
        ImportTableButton              matlab.ui.control.Button
        ExportTableButton_2            matlab.ui.control.Button
        ProceedtoResultsModuleButton   matlab.ui.control.Button
        CalibratorDropDown             matlab.ui.control.DropDown
        CalibratorDropDownLabel        matlab.ui.control.Label
        BracketidDropDown              matlab.ui.control.DropDown
        BracketidDropDownLabel         matlab.ui.control.Label
        HostInclusionDropDown          matlab.ui.control.DropDown
        InclusionTable                 matlab.ui.control.Table
        MatrixTable                    matlab.ui.control.Table
        fromCALListBox                 matlab.ui.control.ListBox
        AnalyteListBox                 matlab.ui.control.ListBox
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        SpotnamecontainsEditField      matlab.ui.control.EditField
        ContainsLabel                  matlab.ui.control.Label
        ChooseButton                   matlab.ui.control.Button
        StringCompareDropDown          matlab.ui.control.DropDown
        StringCompareInputEditField    matlab.ui.control.NumericEditField
        ApplyButton                    matlab.ui.control.Button
        ShowDwelltimesButton           matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        ApplytoallCalibratorsButton    matlab.ui.control.Button
        MatrixSensitivityFactorMSFTab  matlab.ui.container.Tab
        ExportTableButton              matlab.ui.control.Button
        ComparetoDropDown              matlab.ui.control.DropDown
        ComparetoDropDownLabel         matlab.ui.control.Label
        SensitivitybasedonDropDown     matlab.ui.control.DropDown
        SensitivitybasedonDropDownLabel  matlab.ui.control.Label
        ShowLegendCheckBox             matlab.ui.control.CheckBox
        DenominatorListBox             matlab.ui.control.ListBox
        RatioListBox                   matlab.ui.control.ListBox
        MSFAxes                        matlab.ui.control.UIAxes
    end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                     %%%      ICP-Base     %%%                        %
%                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
%                                                                      %
%  Copyright (C) 2026 Sebastian Stumpf                                 %
%                                                                      %
%  This program is free software: you can redistribute it and/or       %
%  modify it under the terms of the GNU General Public License as      %
%  published by the Free Software Foundation, either version 3 of      %
%  the License, or any later version.                                  %
%                                                                      %
%  This program is distributed in the hope that it will be useful,     %
%  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
%  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
%  GNU General Public License for more details.                        %
%                                                                      %
%  You should have received a copy of the GNU General Public License   %
%  along with this program. If not, see <https://www.gnu.org/licenses/>%
%                                                                      %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        base
        project_id
        session_id
    end


    properties (Access = private)
        sourceapp

        selected_CAL
        selected_bracket_id

        selected_spot
        selected_spot_inclusion
        selected_table
        selected_host_inclusion
        selected_column
        two_IS_rows
        MSF_ratios

        %%%%%%%%%%


        %%%% for quantification %%%%
        sensitivity_relative_to_internal_standard
        sensitivity_relative_to_internal_standard_inclusion
        sensitivity_relative_to_second_internal_standard_inclusion

        %%% for total oxides %%%%
        Fe2_3
        internal_standard_indices_total_oxides
        internal_standard_indices_total_oxides_inclusion

        %%%
        editstatus = false % once this is true it asks if the data should be saved

    end

    methods (Access = private)


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            app.QuantificationButton.Enable = "on";
            app.QuantificationButton.BackgroundColor = orange;
            app.QuantificationButton.FontWeight = 'bold';

            if isfield(status, 'results') && status.results == true
                app.ResultsButton.Enable = "on";
                app.ResultsButton.BackgroundColor = green;
            else
                app.ResultsButton.Enable = "off";
                app.ResultsButton.BackgroundColor = red;
            end

        end

        function changes = check_changes(app)

            status = app.base.project.session.config.module_status;

            if  status.results

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "You changed something, do you want to reset the results?";
                title = "Attention";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Yes'
                        app.base.project.session.config.module_status.results = false;

                        % delete all variables that depend on this

                        if isfield(app.base.project.session.spot, 'matrix_composition')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_composition');
                        end
                        if isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'matrix_limits_of_detection');
                        end
                        if isfield(app.base.project.session.spot, 'Fe_2_3')
                            app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_2_3');
                        end

                        if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                            if isfield(app.base.project.session.spot, 'inclusion_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_composition');
                            end
                            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'inclusion_limits_of_detection');
                            end
                            if isfield(app.base.project.session.spot, 'mixing_ratio')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'mixing_ratio');
                            end
                            if isfield(app.base.project.session.spot, 'mix_composition')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'mix_composition');
                            end
                            if isfield(app.base.project.session.spot, 'Fe_2_3_incl')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'Fe_2_3_incl');
                            end
                            if isfield(app.base.project.session.spot, 'bkgd_corrected_cps_mix')
                                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'bkgd_corrected_cps_mix');
                            end                            

                        end

                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        changes = true;

                        update_progress_buttons(app)

                    case 'Cancel'
                        update_quantification_module_GUI(app)
                        changes = false;
                        return
                end
            else
                changes = true;
            end
        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        function DisplayError(app, ME,WhichOperation)
            %DisplayError - Displays detailed error information
            % Description:
            %    This function displays detailed information about an error, including the
            %    time of occurrence, the operation during which the error occurred, and the
            %    stack trace of the error. If the WhichOperation parameter is not provided,
            %    it defaults to '?'. In deployed software the error message is written into the log_file.txt
            % Author: Nils B. Gies
            % Last edit: 2025-01-05
            % Inputs:
            %    ME - The MException object containing error information
            %    WhichOperation - A string describing the operation during which the error occurred
            %
            try
                if not(exist("WhichOperation","var"))
                    disp('Error! Missing ME!')
                    return
                end

                if not(exist("WhichOperation","var"))
                    WhichOperation='?';
                end

                disp(' ')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%   Error   %%%%%%%%%%%%%%%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('%%')
                disp(['%%        ' char(datetime('now'))])
                disp('%%')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp(['Error occured during: ' WhichOperation])
                % for n=1
                disp('_________________________________________')
                k=1;
                for n=numel(ME.stack):-1:1
                    disp(['%%' num2str(k,'%03.f') '%%'])
                    k=1+k;
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
                disp(['Error in message: ' ME.message])
                disp('  ')
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
                disp('_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_-_')
                disp('  ')
                disp('  ')
            catch ME
                disp('Error occured during: DisplayError(app, ME,WhichOperation)')
                for n=numel(ME.stack):-1:1
                    disp('Error in file:')
                    disp(ME.stack(n).file)
                    disp(['Error in line: ',num2str(ME.stack(n).line)])
                    disp('_________________________________________')
                    disp('  ')
                end
            end
        end


        function updateDwellTime(app, src, event)

            % Get edited value
            %newValue = event.EditData;
            newValue = str2double(event.EditData);
            % Validate input is numeric and positive
            if isnumeric(newValue) && newValue > 0
                % Update the table in memory
                app.base.project.session.spot.dwelltimes{1, event.Indices(2)} = newValue;
            else
                % Revert invalid changes
                src.Data(event.Indices(1), event.Indices(2)) = event.PreviousData;
            end

            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_quantification_module_GUI(app)

            % Get indices where type is "CAL"
            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(cal_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validCALs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate CAL dropdown
            app.CalibratorDropDown.Items = validCALs;
            app.CalibratorDropDown.ItemsData = 1:numel(app.CalibratorDropDown.Items);

            % Populate stringcompare dropdown
            app.StringCompareDropDown.Items = ["µg/g", "% Total Oxides", "FeO/(FeO + Fe2O3)", "µg/g (I.S.1)", "µg/g (I.S.2)"];
            app.StringCompareDropDown.ItemsData = app.StringCompareDropDown.Items;

            % Get selected reference material and find matching indices
            selectedRM = validCALs(app.CalibratorDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);
            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);
            % Update bracket ID dropdown
            app.BracketidDropDown.Items = string(uniqueBracketIds);
            app.BracketidDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_CAL is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_CAL)
                app.selected_CAL = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketidDropDown.Value = uniqueBracketIds(1);
            end

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')
                app.HostInclusionDropDown.Items = ["Host", "Inclusion"];
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            elseif isequal(app.base.project.session.config.reductiontype, 'Matrix')
                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';
                app.HostInclusionDropDown.Items = "Host";
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            end

            if isempty(app.selected_host_inclusion)
                app.selected_host_inclusion = app.HostInclusionDropDown.ItemsData{1};
            end

            if isequal(app.selected_host_inclusion, 'Inclusion')
                app.MatrixTable.Visible = 'off';
                app.InclusionTable.Visible = 'on';

                update_inclusion_table(app)

            elseif isequal(app.selected_host_inclusion, 'Host')
                app.MatrixTable.Visible = 'on';
                app.InclusionTable.Visible = 'off';

                update_matrix_table(app)

            end

            %%%%%%%%%%%%%%%%%%%%

            if strcmp(app.selected_table, 'InclusionTable')
                % Get selected column index and name of InclusionTable
                if ~isempty(app.InclusionTable.Selection)
                    selectedCol = unique(app.InclusionTable.Selection(:, 2));
                    colName = app.InclusionTable.ColumnName{selectedCol};
                else
                    return;
                end
            elseif strcmp(app.selected_table, 'MatrixTable')
                % Get selected column index and name of Matrix Table
                if ~isempty(app.MatrixTable.Selection)
                    selectedCol = unique(app.MatrixTable.Selection(:, 2));
                    colName = app.MatrixTable.ColumnName{selectedCol};
                else
                    return;
                end
            end


            if isempty(app.selected_spot)
                app.selected_spot = 1;
            end

            if ~exist('colName') %#ok
                colName = 'Analyte';
            end

            %%%%%%%%%%%%%%%%%%%%

            if contains(colName, 'Analyte') && strcmp(app.selected_table, 'MatrixTable')

                % Get analytes for this CAL from sensitivities
                if ~isempty(app.selected_CAL)
                    % Update ListBox
                    app.AnalyteListBox.Visible = 'on';
                    app.AnalyteListBox.Items = ['=================='; 'Total Oxides'; app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}.Properties.VariableNames'];
                    app.AnalyteListBox.ItemsData = app.AnalyteListBox.Items;
                    app.fromCALListBox.Visible = 'off';
                end

            elseif contains(colName, '% Total Oxides') || contains(colName, 'FeO/(FeO + Fe2O3)')

                    app.AnalyteListBox.Visible = 'off';
                    app.fromCALListBox.Visible = 'off';

            elseif contains(colName, 'Analyte (I.S.1)') && strcmp(app.selected_table, 'InclusionTable')

                % Get analytes for this CAL from sensitivities
                if ~isempty(app.selected_CAL)
                    % Update ListBox
                    app.AnalyteListBox.Visible = 'on';

                    if ~isequal(app.base.project.session.spot.unmixing_via{app.selected_spot}, 'Two internal standards')
                        app.AnalyteListBox.Items = ['=================='; 'Total Oxides'; app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}.Properties.VariableNames'];
                    else
                         app.AnalyteListBox.Items = ['=================='; app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}.Properties.VariableNames'];                    
                    end
                    
                    app.AnalyteListBox.ItemsData = app.AnalyteListBox.Items;
                    app.fromCALListBox.Visible = 'off';
                end

            elseif contains(colName, 'Analyte (I.S.2)') && strcmp(app.selected_table, 'InclusionTable')

                % Get analytes for this CAL from sensitivities
                if ~isempty(app.selected_CAL)
                    % Update ListBox
                    app.AnalyteListBox.Visible = 'on';
                    app.AnalyteListBox.Items = ['=================='; app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}.Properties.VariableNames'];
                    app.AnalyteListBox.ItemsData = app.AnalyteListBox.Items;
                    app.fromCALListBox.Visible = 'off';
                end

            elseif contains(colName, 'µg/g') && strcmp(app.selected_table, 'MatrixTable')

                % Hide Analyte ListBox
                app.AnalyteListBox.Visible = 'off';

                internalStandardValues = app.base.project.session.spot.internal_standard{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot);

                if any(strcmp(internalStandardValues, 'Total Oxides'))
                    % Hide the fromCALListBox
                    app.fromCALListBox.Visible = 'off';
                else
                    % Get available CALs for all selected rows
                    availableCALs = [];
                    for cal = 1:numel(validCALs)
                        % Skip current CAL
                        if strcmp(validCALs{cal}, validCALs(app.selected_CAL))
                            continue;
                        end

                        % Check if sensitivity data exists and is valid
                        sens = app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, cal};
                        if ~isempty(sens) && ~isequal(sens, 0) && istable(sens)
                            % Check if selected rows have non-zero sensitivities
                            sens_data = table2array(sens);
                            row_sums = sum(sens_data(app.selected_spot, :), 2);
                            if all(row_sums ~= 0)
                                availableCALs = [availableCALs; validCALs(cal)];
                            end
                        end
                    end

                    % Only show and populate fromCALListBox if there are available CALs
                    if ~isempty(availableCALs)
                        app.fromCALListBox.Visible = 'on';
                        otherCALs = "from " + availableCALs;
                        app.fromCALListBox.Items = ['=================='; otherCALs];
                    else
                        app.fromCALListBox.Visible = 'off';
                    end
                end

            elseif contains(colName, 'µg/g') && strcmp(app.selected_table, 'InclusionTable') % && ~contains(colName, '(I.S.2)')

                % Hide Analyte ListBox
                app.AnalyteListBox.Visible = 'off';

                if contains(colName, '(I.S.1)')
                    internalStandardValues = app.base.project.session.spot.internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot);
                elseif contains(colName, '(I.S.2)')
                    internalStandardValues = app.base.project.session.spot.second_internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot);
                end

                if any(strcmp(internalStandardValues, 'Total Oxides'))
                    % Hide the fromCALListBox
                    app.fromCALListBox.Visible = 'off';
                else
                    % Get available CALs for all selected rows
                    availableCALs = [];
                    for cal = 1:numel(validCALs)
                        % Skip current CAL
                        if strcmp(validCALs{cal}, validCALs(app.selected_CAL))
                            continue;
                        end

                        % Check if sensitivity data exists and is valid
                        sens = app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, cal};
                        if ~isempty(sens) && ~isequal(sens, 0) && istable(sens)
                            % Check if selected rows have non-zero sensitivities
                            sens_data = table2array(sens);
                            row_sums = sum(sens_data(app.selected_spot, :), 2);
                            if all(row_sums ~= 0)
                                availableCALs = [availableCALs; validCALs(cal)];
                            end
                        end
                    end

                    % Only show and populate fromCALListBox if there are available CALs
                    if ~isempty(availableCALs)
                        app.fromCALListBox.Visible = 'on';
                        otherCALs = "from " + availableCALs;
                        app.fromCALListBox.Items = ['=================='; otherCALs];
                    else
                        app.fromCALListBox.Visible = 'off';
                    end
                end
            end


        end

        function update_matrix_table(app)

            % Filter spots where type is not "CAL"
            nonCALIndices = ~strcmp(app.base.project.session.spot.type, "CAL");
            spotnames = app.base.project.session.spot.name(nonCALIndices);
            spotNumbers = find(nonCALIndices); % Get spot numbers for non-CAL spots

            % Get data for current CAL
            internal_std = app.base.project.session.spot.internal_standard{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            oxide_fraction = app.base.project.session.spot.internal_standard_oxide_wt{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);

            % Initialize data cell array with correct dimensions
            data = [num2cell(spotNumbers), spotnames, internal_std, mass_fraction, oxide_fraction, fe_ratio];
            colNames = {'#', 'Spotname', 'Analyte', '[µg/g]', '% Total Oxides', 'FeO/(FeO + Fe2O3)'};

            % Create and update table
            T = cell2table(data, 'VariableNames', colNames);

            % Find zero and nonzero rows based on sensitivities
            sens_data = table2array(app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices,:));
            zero_rows = sum(sens_data, 2) == 0;
            % Remove zero_rows from the table
            % these are the rows e.g. 1,2,3 if the first CAL starts at 4
            T(zero_rows, :) = []; % Remove rows from the table

            app.MatrixTable.Data = T;
            app.MatrixTable.ColumnName = colNames;

            % Create a centered style for any cells that don't get grey or white background
            s_center = uistyle('HorizontalAlignment', 'center');
            % First apply center alignment to all cells
            addStyle(app.MatrixTable, s_center);

        end

        function update_inclusion_table(app)

            % Filter spots where type is not "CAL"
            nonCALIndices = ~strcmp(app.base.project.session.spot.type, "CAL");
            spotnames = app.base.project.session.spot.name(nonCALIndices);
            spotNumbers = find(nonCALIndices); % Get spot numbers for non-CAL spots

            % Get data for current CAL
            internal_std_inclusion = app.base.project.session.spot.internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            mass_fraction_inclusion = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);

            oxide_fraction_inclusion = app.base.project.session.spot.internal_standard_oxide_wt_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            fe_ratio_inclusion = app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);

            second_internal_std_inclusion = app.base.project.session.spot.second_internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);
            second_mass_fraction_inclusion = app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices);

            % Initialize data cell array with correct dimensions
            data = [num2cell(spotNumbers), spotnames, internal_std_inclusion, mass_fraction_inclusion,oxide_fraction_inclusion, fe_ratio_inclusion, second_internal_std_inclusion, second_mass_fraction_inclusion];
            colNames = {'#', 'Spotname', 'Analyte (I.S.1)', '[µg/g] (I.S.1)', '% Total Oxides', 'FeO/(FeO + Fe2O3)', 'Analyte (I.S.2)', '[µg/g] (I.S.2)'};

            % Create and update table
            T = cell2table(data, 'VariableNames', colNames);
            % Find zero and nonzero rows based on sensitivities
            sens_data = table2array(app.base.project.session.spot.sensitivities{app.BracketidDropDown.Value, app.selected_CAL}(nonCALIndices,:));
            zero_rows = sum(sens_data, 2) == 0;
            % Remove zero_rows from the table
            T(zero_rows, :) = []; % Remove rows from the table

            app.InclusionTable.Data = T;
            app.InclusionTable.ColumnName = colNames;

        end

        function update_inclusion_table_style(app)
            all_grey_cells = [];

            % Find row indices for each case
            app.two_IS_rows = find(strcmp(app.base.project.session.spot.unmixing_via, 'Two internal standards'));
            none_rows = find(strcmp(app.base.project.session.spot.unmixing_via, 'None'));
            display_rows = table2array(app.InclusionTable.DisplayData(:,1));

            % Regular columns for non-Two IS rows
            grey_cells = find(~ismember(display_rows, app.two_IS_rows));
            if ~isempty(grey_cells)
                grey_analyte = [grey_cells, repmat(7, length(grey_cells), 1)];
                grey_mass = [grey_cells, repmat(8, length(grey_cells), 1)];
                all_grey_cells = [all_grey_cells; grey_analyte; grey_mass];
            end

            % Grey out columns 5 and 6 for "Two internal standards" rows
            two_IS_cells = find(ismember(display_rows, app.two_IS_rows));
            if ~isempty(two_IS_cells)
                grey_oxides = [two_IS_cells, repmat(5, length(two_IS_cells), 1)];
                grey_fe_ratio = [two_IS_cells, repmat(6, length(two_IS_cells), 1)];
                all_grey_cells = [all_grey_cells; grey_oxides; grey_fe_ratio];
            end

            % Grey out columns 3 and 4 for "None" rows
            none_cells = find(ismember(display_rows, none_rows));
            if ~isempty(none_cells)
                grey_is1 = [none_cells, repmat(3, length(none_cells), 1)];
                grey_mass1 = [none_cells, repmat(4, length(none_cells), 1)];
                all_grey_cells = [all_grey_cells; grey_is1; grey_mass1];
            end

            removeStyle(app.InclusionTable);
            s_grey = uistyle('BackgroundColor', [0.9, 0.9, 0.9]);
            if ~isempty(all_grey_cells)
                addStyle(app.InclusionTable, s_grey, 'cell', all_grey_cells);
            end

            s_center = uistyle('HorizontalAlignment', 'center');
            addStyle(app.InclusionTable, s_center);
        end


        %%%%%%%%%%%%%%%% Mass Fraction Calculation %%%%%%%%%%%%%%%%%%%%%
        % these are basically the same functions twice every time, once
        % for matrix and once for inclusion.
        % There is probably a smoother way to do this but ¯\_(ツ)_/¯

        %%%% 1. %%%
        % the background corrected cps for the matrix are already
        % calculated in the drift module
        % the background corrected cps for the inclusions if MOT is used
        % are already calculated in the interval module
        
        function calculate_background_corrected_mix_intensities(app)

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                all_UNK = find(~strcmp(app.base.project.session.spot.type, "CAL"));

                if ~isfield(app.base.project.session.spot, 'bkgd_corrected_cps_mix')
                    app.base.project.session.spot.bkgd_corrected_cps_mix = array2table(zeros(numel(app.base.project.session.spot.name), numel(app.base.project.session.spot.analytes)), 'VariableNames', app.base.project.session.spot.analytes);
                end

                for i = 1:numel(all_UNK)
                    unk = all_UNK(i);

                    inclusion_1_start = app.base.project.session.spot.inclusion_1_starts{unk};
                    inclusion_1_end = app.base.project.session.spot.inclusion_1_ends{unk};

                    if isempty(inclusion_1_start) || isempty(inclusion_1_end)
                        continue
                    end

                    if isequal(app.base.project.session.config.datatype, 'Continuous')
                        data_incl = table2array(app.base.project.session.spot.signal_data(:, 2:end));
                    elseif isequal(app.base.project.session.config.datatype, 'Single')
                        data_incl = table2array(app.base.project.session.spot.signal_data{unk}(:, 2:end));
                    end

                    % Background correction for inclusion data
                    background_start_incl = app.base.project.session.spot.background_starts{unk};
                    background_end_incl = app.base.project.session.spot.background_ends{unk};
                    background_values_incl = data_incl(background_start_incl:background_end_incl, :);
                    background_values_mean_incl = mean(background_values_incl, 1);
                    data_incl_corrected = data_incl - background_values_mean_incl;

                    % Get data for all elements in the inclusion interval
                    mix_data = data_incl_corrected(inclusion_1_start:inclusion_1_end, :);

                    % this setting to zero has to be done here to stay consistent
                    % with the matrix-only tracer
                    mix_data(mix_data < 0) = 0;

                    mean_mix_all_elements = mean(mix_data, 1);

                    % Get column headers from active_spot table
                    column_headers = app.base.project.session.spot.analytes;
                    % Convert matrix to table with headers
                    mix_table = array2table(mean_mix_all_elements, 'VariableNames', column_headers);

                    app.base.project.session.spot.bkgd_corrected_cps_mix(unk,:) =  mix_table;
                end
            end
        end

        %%%% 2. %%%
        function calculate_sensitivity_relative_to_the_internal_standard(app)

            % first calculate the analytical sensitivity relative to the
            % internal standard element

            % Get input sensitivities and internal standards
            sensitivities = app.base.project.session.spot.sensitivities;
            internal_std = app.base.project.session.spot.internal_standard;

            % Initialize output cell array
            app.sensitivity_relative_to_internal_standard = cell(size(sensitivities,1), size(sensitivities,2));

            % Process each table in the cell array
            for cal_idx = 1:size(sensitivities,2)
                for bracket_idx = 1:size(sensitivities,1)
                    if ~isempty(sensitivities{bracket_idx,cal_idx})
                        % Get current sensitivity table and internal standards
                        curr_sens = sensitivities{bracket_idx,cal_idx};
                        curr_std = internal_std{bracket_idx,cal_idx};
                    else
                        continue
                    end
                    % Get column names
                    colNames = curr_sens.Properties.VariableNames;

                    % Convert to matrix for calculations
                    sens_matrix = table2array(curr_sens);
                    relative_sens = zeros(size(sens_matrix));

                    % Process each row
                    for unk = 1:size(sens_matrix, 1)
                        % Check if internal standard exists
                        if ~isempty(curr_std{unk})
                            % Get internal standard element name and column index
                            std_element = curr_std{unk};

                            if isequal(std_element, 'Total Oxides')

                                % let the analyte with the highest net-cps be
                                % the internal standard, same as in old
                                % SILLS

                                if all(app.base.project.session.spot.bkgd_corrected_cps{unk, :} == 0)
                                    % If there was no matrix and background
                                    % set, the bkgd_corrected_cps are all
                                    % 0, therefore no relative sensitivity
                                    % needs to be calculated
                                    continue
                                else

                                    % Get column names from sensitivity table
                                    elements_to_total = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.Properties.VariableNames;

                                    % Get oxide information from session
                                    element_names = app.base.project.session.oxides.element_names;
                                    preferred_isotopes = app.base.project.session.oxides.preferred_isotopes;

                                    % First: Filter to only keep elements that have oxides defined
                                    temp_elements = {};
                                    for i = 1:length(elements_to_total)
                                        current = elements_to_total{i};
                                        element_name = current(isstrprop(current, 'alpha'));

                                        % Check if this element has an oxide defined
                                        if ismember(element_name, element_names)
                                            temp_elements{end+1} = current;
                                        end
                                    end

                                    % Second: Filter to keep only one preferred isotope per element
                                    filtered_elements = {};

                                    for i = 1:length(element_names)
                                        element_name = element_names{i};

                                        % Find all measured isotopes for this element
                                        measured_isotopes = temp_elements( ...
                                            cellfun(@(x) isequal(x(isstrprop(x, 'alpha')), element_name), temp_elements));

                                        if ~isempty(measured_isotopes)
                                            % Get preferred isotope for this element
                                            preferred = preferred_isotopes{i};

                                            if ~isempty(preferred)
                                                % If preferred isotope is measured, use it
                                                idx = find(strcmp(measured_isotopes, preferred), 1);
                                                if ~isempty(idx)
                                                    filtered_elements{end+1} = preferred;
                                                else
                                                    % Preferred isotope not measured, use first measured
                                                    filtered_elements{end+1} = measured_isotopes{1};
                                                end
                                            else
                                                % No preferred isotope, use first measured
                                                filtered_elements{end+1} = measured_isotopes{1};
                                            end
                                        end
                                    end

                                    % Convert back to the same format as the original
                                    elements_to_total = filtered_elements(:)';

                                    % For total mass calculations, the
                                    % internal standards has been defined
                                    % here
                                    % in theory, the IS is from now on the
                                    % analyte with the relative_sens of 1

                                    [~, intstd_element_indices] = max(app.base.project.session.spot.bkgd_corrected_cps{unk, elements_to_total});
                                    intstd_element_name = elements_to_total(intstd_element_indices);

                                    internal_standard_sensitivity = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.(intstd_element_name{1})(unk);
                                    all_analytes_sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk,:));

                                    relative_sens(unk,:) = all_analytes_sensitivity ./ internal_standard_sensitivity;

                                    internal_standard_indices(unk,:) = intstd_element_indices; % right now this indice is dependent on elements_to_total
                                end

                            else

                                std_col = find(strcmp(colNames, std_element));
                                % Get internal standard value
                                std_value = sens_matrix(unk, std_col);
                                % Calculate relative sensitivities for this row
                                relative_sens(unk,:) = sens_matrix(unk,:) ./ std_value;

                            end
                        end
                    end

                    % Convert result back to table and store in cell array
                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx} = ...
                        array2table(relative_sens, 'VariableNames', colNames);

                    % store these names temporary, they get then used in the
                    % total_oxide_calculation function
                    if exist('internal_standard_indices')
                        app.internal_standard_indices_total_oxides{bracket_idx,cal_idx} = ...
                            array2table(internal_standard_indices);
                    end
                end
            end
        end

        function calculate_sensitivity_relative_to_the_internal_standard_incl(app)

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                % Get input sensitivities and internal standards
                sensitivities = app.base.project.session.spot.sensitivities;
                internal_std = app.base.project.session.spot.internal_standard_inclusion;

                % Initialize output cell array
                app.sensitivity_relative_to_internal_standard_inclusion = cell(size(sensitivities,1), size(sensitivities,2));

                % Process each table in the cell array

                for cal_idx = 1:size(sensitivities,2)
                    for bracket_idx = 1:size(sensitivities,1)
                        if ~isempty(sensitivities{bracket_idx,cal_idx})
                            % Get current sensitivity table and internal standards
                            curr_sens = sensitivities{bracket_idx,cal_idx};
                            curr_std = internal_std{bracket_idx,cal_idx};
                        else
                            continue
                        end
                        % Get column names
                        colNames = curr_sens.Properties.VariableNames;

                        % Convert to matrix for calculations
                        sens_matrix = table2array(curr_sens);
                        relative_sens = zeros(size(sens_matrix));
                        internal_standard_indices = zeros(size(sens_matrix, 1), 1);

                        % Process each row
                        for unk = 1:size(sens_matrix, 1)
                            % Check if internal standard exists
                            if ~isempty(curr_std{unk})
                                % Get internal standard element name and column index
                                std_element = curr_std{unk};
                                if isequal(std_element, 'Total Oxides')
                                    % let the analyte with the highest net-cps be
                                    % the internal standard, same as in old
                                    % SILLS

                                    if all(app.base.project.session.spot.bkgd_corrected_cps_incl{unk, :} == 0)
                                        % If there was no matrix and background
                                        % set, the bkgd_corrected_cps are all
                                        % 0, therefore no relative sensitivity
                                        % needs to be calculated
                                        continue
                                    else

                                        % Get column names from sensitivity table
                                        elements_to_total = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.Properties.VariableNames;

                                        % Get oxide information from session
                                        element_names = app.base.project.session.oxides.element_names;
                                        preferred_isotopes = app.base.project.session.oxides.preferred_isotopes;

                                        % First: Filter to only keep elements that have oxides defined
                                        temp_elements = {};
                                        for i = 1:length(elements_to_total)
                                            current = elements_to_total{i};
                                            element_name = current(isstrprop(current, 'alpha'));

                                            % Check if this element has an oxide defined
                                            if ismember(element_name, element_names)
                                                temp_elements{end+1} = current;
                                            end
                                        end

                                        % Second: Filter to keep only one preferred isotope per element
                                        filtered_elements = {};

                                        for i = 1:length(element_names)
                                            element_name = element_names{i};

                                            % Find all measured isotopes for this element
                                            measured_isotopes = temp_elements( ...
                                                cellfun(@(x) isequal(x(isstrprop(x, 'alpha')), element_name), temp_elements));

                                            if ~isempty(measured_isotopes)
                                                % Get preferred isotope for this element
                                                preferred = preferred_isotopes{i};

                                                if ~isempty(preferred)
                                                    % If preferred isotope is measured, use it
                                                    idx = find(strcmp(measured_isotopes, preferred), 1);
                                                    if ~isempty(idx)
                                                        filtered_elements{end+1} = preferred;
                                                    else
                                                        % Preferred isotope not measured, use first measured
                                                        filtered_elements{end+1} = measured_isotopes{1};
                                                    end
                                                else
                                                    % No preferred isotope, use first measured
                                                    filtered_elements{end+1} = measured_isotopes{1};
                                                end
                                            end
                                        end

                                        % Convert back to the same format as the original
                                        elements_to_total = filtered_elements(:)';

                                        [~, intstd_element_indices] = max(app.base.project.session.spot.bkgd_corrected_cps_incl{unk, elements_to_total});
                                        intstd_element_name = elements_to_total(intstd_element_indices);

                                        internal_standard_sensitivity = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.(intstd_element_name{1})(unk);
                                        all_analytes_sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk,:));

                                        relative_sens(unk,:) = all_analytes_sensitivity ./ internal_standard_sensitivity;

                                        internal_standard_indices(unk,:) = intstd_element_indices;
                                    end
                                else
                                    std_col = find(strcmp(colNames, std_element));

                                    % Get internal standard value
                                    std_value = sens_matrix(unk, std_col);

                                    % Calculate relative sensitivities for this row
                                    relative_sens(unk,:) = sens_matrix(unk,:) ./ std_value;
                                end
                            end
                        end

                        % Convert result back to table and store in cell array
                        app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx} = ...
                            array2table(relative_sens, 'VariableNames', colNames);

                        % store these names temporary, they get then used in the
                        % total_oxide_calculation function
                        if exist('internal_standard_indices', 'var')
                            app.internal_standard_indices_total_oxides_inclusion{bracket_idx,cal_idx} = ...
                                array2table(internal_standard_indices);
                        end
                    end
                end
            end
        end

        function calculate_sensitivity_relative_to_sec_internal_standard_incl(app)

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                % Get input sensitivities and internal standards
                sensitivities = app.base.project.session.spot.sensitivities;
                internal_std = app.base.project.session.spot.second_internal_standard_inclusion;

                % Initialize output cell array
                app.sensitivity_relative_to_second_internal_standard_inclusion = cell(size(sensitivities,1), size(sensitivities,2));

                % Process each table in the cell array
                for cal_idx = 1:size(sensitivities,2)
                    for bracket_idx = 1:size(sensitivities,1)
                        if ~isempty(sensitivities{bracket_idx,cal_idx})
                            % Get current sensitivity table and internal standards
                            curr_sens = sensitivities{bracket_idx,cal_idx};
                            curr_std = internal_std{bracket_idx,cal_idx};
                        else
                            continue
                        end
                        % Get column names
                        colNames = curr_sens.Properties.VariableNames;

                        % Convert to matrix for calculations
                        sens_matrix = table2array(curr_sens);
                        relative_sens = zeros(size(sens_matrix));

                        % Process each row
                        for unk = 1:size(sens_matrix, 1)
                            % Check if internal standard exists
                            if ~isempty(curr_std{unk})
                                % Get internal standard element name and column index
                                std_element = curr_std{unk};
                                if isequal(std_element, 'Total Oxides')
                                    continue
                                end
                                std_col = find(strcmp(colNames, std_element));

                                % Get internal standard value
                                std_value = sens_matrix(unk, std_col);

                                % Calculate relative sensitivities for this row
                                relative_sens(unk,:) = sens_matrix(unk,:) ./ std_value;
                            end
                        end

                        % Convert result back to table and store in cell array
                        app.sensitivity_relative_to_second_internal_standard_inclusion{bracket_idx,cal_idx} = ...
                            array2table(relative_sens, 'VariableNames', colNames);
                    end
                end
            end
        end

        %%%% 3. %%%
        % for "normal" mineral spot analysis calculations and the hosts of
        % inclusions
        function calculate_matrix_mass_fractions(app)

            %%%%% this function calculates the mass fractions in several
            %%%%% passes through the dataset. it makes one pass per primary
            %%%%% reference material. this is done to make a sequential
            %%%%% data reduction possible, using the values calculated with
            %%%%% one CAL as an internal standard for another CAL

            cal_indices = strcmp(app.base.project.session.spot.type, "CAL");
            % Get reference materials only for CAL spots
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_CALs = unique(cal_materials);

            % Find all Unknown spots
            all_UNK = find(~strcmp(app.base.project.session.spot.type, "CAL"));

            % calculate the mass fractions for all unknowns

            for cal_idx = 1:size(app.base.project.session.spot.sensitivities,2)
                for bracket_idx = 1:size(app.base.project.session.spot.sensitivities,1)
                    % Initialize the matrix_composition table for current CAL
                    current_matrix_composition = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                        'VariableNames', app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames);
                    current_matrix_limit_of_detection = array2table(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes)), ...
                        'VariableNames', app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames);

                    for i = 1:numel(all_UNK)

                        unk = all_UNK(i);

                        if isempty(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk})
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx,cal_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx,cal_idx}{unk}) && isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from') && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if all(app.base.project.session.spot.bkgd_corrected_cps{unk, :} == 0)
                            % If there was no matrix and background
                            % set, the bkgd_corrected_cps are all
                            % 0, therefore no relative sensitivity
                            % needs to be calculated
                            continue
                        end

                        % Initialize mass_fractions_matrix with zeros
                        mass_fractions_matrix = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps));
                        LOD_matrix = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps));

                        % Get column names from sensitivity table
                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.Properties.VariableNames;

                        internal_standard = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};

                        if isequal(internal_standard, 'Total Oxides')

                            [internal_standard_intensity, internal_standard_mass_fraction, internal_standard_name] = total_oxide_calculation(app, bracket_idx, cal_idx, unk);

                        else % this is for the normal case
                            internal_standard_name = internal_standard;
                            internal_standard_intensity = app.base.project.session.spot.bkgd_corrected_cps.(internal_standard_name)(unk);
                            internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};

                        end

                        % Calculate mass fractions only for columns present in
                        % sensitivity table since this table represents the analytes
                        % that are also in the external standard
                        for col = 1:length(sensitivity_cols)
                            if ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames)

                                col_idx = find(strcmp(app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames, sensitivity_cols{col}));


                                % actual calculation
                                mass_fractions_matrix(col_idx) = (app.base.project.session.spot.bkgd_corrected_cps.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                    (internal_standard_mass_fraction / ...
                                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));
                                % limits of detection
                                LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);

                            end
                        end

                        % Update the specific row in current matrix_composition with calculated mass fractions
                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                        % Update the specific row in current
                        % matrix_limit_of_detection with calculated
                        % detection limit
                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                    end

                    % Store the matrix_composition table for current CAL in the cell array
                    app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx} = current_matrix_composition;
                    % Store the matrix_limits_of_detection table for current CAL in the cell array
                    app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,cal_idx} = current_matrix_limit_of_detection;

                    % delete the empyt matrix_composition tables to make it
                    % look pretty
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx} = zeros(0,0);
                    end
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,cal_idx} = zeros(0,0);
                    end

                end
            end

            % Dynamic passes based on number of unique CALs
            % this is necessary for the sequential data reduction
            max_passes = length(unique_CALs);
            for pass = 1:max_passes
                changes_made = false; % Flag to track if any values were updated in this pass

                for cal_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                    for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)
                        current_matrix_composition = array2table(single(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes))), ...
                            'VariableNames', app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames);
                        current_matrix_limit_of_detection = array2table(single(zeros(length(app.base.project.session.spot.signal_starts), numel(app.base.project.session.spot.analytes))), ...
                            'VariableNames', app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames);

                        for i = 1:numel(all_UNK)
                            unk = all_UNK(i);

                            % Skip if no internal standard is defined
                            if isempty(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk})
                                continue
                            end

                            % Get internal standard element for current spot
                            is_element = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};

                            % Check if internal_standard_mass_fraction contains 'from' indicating we should use another CAL's result
                            if ischar(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}) && ...
                                    startsWith(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from')  && ~isequal(app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk}, 'Total Oxides')

                                % Extract source CAL name
                                source_cal = extractAfter(app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk}, 'from ');
                                source_cal_idx = find(strcmp(unique_CALs, source_cal));

                                % Get calculated value from source CAL if available
                                if ~isempty(source_cal_idx) && ~isempty(app.base.project.session.spot.matrix_composition{bracket_idx,source_cal_idx})
                                    calculated_is_value = app.base.project.session.spot.matrix_composition{bracket_idx,source_cal_idx}{unk,is_element};

                                    if calculated_is_value == 0
                                        current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:));
                                        current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,cal_idx}(unk,:));
                                    elseif calculated_is_value > 0
                                        % Store original value
                                        original_is_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};
                                        old_values = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:));

                                        % Use calculated value for this calculation
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk} = calculated_is_value;

                                        % Calculate mass fractions with sensitivity table
                                        sensitivity_cols = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.Properties.VariableNames;
                                        mass_fractions_matrix = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps));
                                        LOD_matrix = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps));

                                        for col = 1:length(sensitivity_cols)
                                            if ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames)
                                                col_idx = find(strcmp(app.base.project.session.spot.bkgd_corrected_cps.Properties.VariableNames, sensitivity_cols{col}));

                                                internal_standard_name = app.base.project.session.spot.internal_standard{bracket_idx,cal_idx}{unk};
                                                internal_standard_intensity = app.base.project.session.spot.bkgd_corrected_cps.(char(internal_standard_name))(unk);
                                                internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk};

                                                % actual calculation
                                                mass_fractions_matrix(col_idx) = (app.base.project.session.spot.bkgd_corrected_cps.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                    (internal_standard_mass_fraction / ...
                                                    app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));

                                                % limits of detection
                                                LOD_matrix(col_idx) = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction);
                                            end
                                        end

                                        % Restore original value
                                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_idx,cal_idx}{unk} = original_is_mass_fraction;

                                        % save the data
                                        current_matrix_composition{unk,:} = mass_fractions_matrix;
                                        current_matrix_limit_of_detection{unk,:} = LOD_matrix;

                                        % Check if values changed
                                        if any(abs(mass_fractions_matrix - old_values) > 1e-10)
                                            changes_made = true;
                                        end
                                    end
                                end
                            else
                                current_matrix_composition{unk,:} = table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:));
                                current_matrix_limit_of_detection{unk,:} = table2array(app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,cal_idx}(unk,:));
                            end
                        end
                        % if there are NaN numbers they get replaced by 0.
                        % This can be if there is an internal standard
                        % input but the intervals were deleted.
                        current_matrix_composition = fillmissing(current_matrix_composition, 'constant', 0);
                        current_matrix_limit_of_detection = fillmissing(current_matrix_limit_of_detection, 'constant', 0);

                        app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx} = current_matrix_composition;
                        % Store the matrix_limits_of_detection table for current CAL in the cell array
                        app.base.project.session.spot.matrix_limits_of_detection{bracket_idx,cal_idx} = current_matrix_limit_of_detection;
                    end
                end

                % If no changes were made in this pass, we can stop
                if ~changes_made && pass > 1
                    break;
                end
            end
        end

        % for the inclusions
        function inclusion_quantification_failed = calculate_inclusion_mass_fractions(app)

            %%%%% this function calculates the mass fractions in several
            %%%%% passes through the dataset. it makes one pass per primary
            %%%%% reference material. this is done to make a sequential
            %%%%% data reduction possible, using the values calculated with
            %%%%% one CAL as an internal standard for another CAL

            inclusion_quantification_failed = false;

            % identify CALs and unique CAL names
            cal_indices   = strcmp(app.base.project.session.spot.type, "CAL");
            cal_materials = app.base.project.session.spot.referencematerial(cal_indices);
            unique_CALs   = unique(cal_materials);

            % all unknown spots
            all_UNK = find(~strcmp(app.base.project.session.spot.type, "CAL"));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % first pass: calculate mass fractions for all unknowns
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            for cal_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)

                    current_inclusion_composition = array2table( ...
                        single(zeros(length(app.base.project.session.spot.signal_starts), ...
                        numel(app.base.project.session.spot.analytes))), ...
                        'VariableNames', ...
                        app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames);

                    current_mixing_ratio = single(zeros(length(app.base.project.session.spot.signal_starts), 1));

                    current_mix_composition = array2table( ...
                        single(zeros(length(app.base.project.session.spot.signal_starts), ...
                        numel(app.base.project.session.spot.analytes))), ...
                        'VariableNames', ...
                        app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames);

                    for i = 1:numel(all_UNK)
                        unk = all_UNK(i);

                        % various sanity checks for first internal standard
                        if isempty(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                ~isequal(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk})
                            continue
                        end

                        if isempty(app.base.project.session.spot.internal_standard_oxide_wt_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            continue
                        end

                        if ischar(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from') && ...
                                ~isequal(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk}, 'Total Oxides')
                            % IS1 will be resolved later in dynamic passes
                            continue
                        end

                        % checks for second internal standard (Two IS case)
                        if isempty(app.base.project.session.spot.second_internal_standard_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                            continue
                        end

                        if isempty(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                            continue
                        end

                        % if second IS mass fraction is of type 'from <CAL>',
                        % we also skip here and handle it in the dynamic passes
                        if ischar(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from') && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                            continue
                        end

                        % no signal in mix cps
                        if all(app.base.project.session.spot.bkgd_corrected_cps_mix{unk, :} == 0)
                            continue
                        end

                        % Initialize arrays
                        mass_fractions_inclusion = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));
                        mixing_ratio             = zeros(numel(app.base.project.session.spot.signal_starts), 1);
                        mass_fractions_mix       = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));

                        % Get column names from sensitivity table
                        sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames;

                        internal_standard = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                        if isequal(app.base.project.session.spot.unmixing_via{unk}, 'None')

                            continue

                        elseif isequal(internal_standard, 'Total Oxides') && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Matrix-only tracer')

                            [internal_standard_intensity, internal_standard_mass_fraction, internal_standard_name] = ...
                                total_oxide_calculation_inclusion_MOT(app, bracket_idx, cal_idx, unk);

                        else
                                % normal case
                                internal_standard_name         = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                                internal_standard_intensity    = app.base.project.session.spot.bkgd_corrected_cps_incl.(char(internal_standard_name))(unk);
                                internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};
                        end

                        % Calculate mass fractions for analytes in sensitivity table
                        for col = 1:length(sensitivity_cols)

                            col_idx = find(strcmp(app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames, ...
                                sensitivity_cols{col}));

                            % MOT case
                            if isequal(app.base.project.session.spot.unmixing_via{unk}, 'Matrix-only tracer') && ...
                                    ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames)

                                mass_fractions_inclusion(col_idx) = ...
                                    (app.base.project.session.spot.bkgd_corrected_cps_incl.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                    (internal_standard_mass_fraction / ...
                                    app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));

                                % for MOT limits of detection
                                % first, check if the MOT is in the CAL,
                                % otherwise the mix cannot be quantified
                                host_only_tracer = app.base.project.session.spot.matrix_only_tracer{unk};
                                if any(ismember(app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames, ...
                                        host_only_tracer))

                                    % check if the host from a different
                                    % spot was taken for MOT unmixing
                                    if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                        if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                            message = {['The quantification for spot #' num2str(unk) ...
                                                ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                ' failed. Make sure that the host for this spot is quantified.']};
                                            uialert(app.UIFigure, message, 'Warning');

                                            inclusion_quantification_failed = true;

                                            return

                                        else

                                            host_from_spot_index = unk;

                                        end

                                    else

                                        % Get the matrix name for the given unk
                                        matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                        % Find the index in spot.name
                                        host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                        if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                            message = {['The quantification for spot #' num2str(unk) ...
                                                ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                            uialert(app.UIFigure, message, 'Warning');

                                            inclusion_quantification_failed = true;

                                            return
                                        end

                                    end

                                    % mixing ratio
                                    if isequal(col, 1)
                                        mixing_ratio(unk) = calculate_mixing_ratio_x_via_MOT( ...
                                            app, bracket_idx, cal_idx, unk, host_from_spot_index, ...
                                            internal_standard_name, internal_standard_mass_fraction, host_only_tracer);
                                    end

                                    % mass fractions of the mix
                                    mass_fractions_mix(col_idx) = calculate_mix_mass_fractions_via_MOT( ...
                                        app, bracket_idx, cal_idx, unk, host_from_spot_index, col_idx, ...
                                        mixing_ratio, mass_fractions_inclusion);

                                else
                                    % here it just continues without
                                    % the quantifying the mix because
                                    % the MOT is not in the CAL
                                    continue
                                end

                                % Two internal standards case
                            elseif isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards') && ...
                                    ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames) && ...
                                    ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_mix.Properties.VariableNames)

                                % check if the host from a different
                                % spot was taken for MOT unmixing
                                if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                        message = {['The quantification for spot #' num2str(unk) ...
                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                            ' failed. Make sure that the host for this spot is quantified.']};
                                        uialert(app.UIFigure, message, 'Warning');

                                        inclusion_quantification_failed = true;

                                        return

                                    else

                                        host_from_spot_index = unk;

                                    end

                                else

                                    % Get the matrix name for the given unk
                                    matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                    % Find the index in spot.name
                                    host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                        message = {['The quantification for spot #' num2str(unk) ...
                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                            ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                        uialert(app.UIFigure, message, 'Warning');

                                        inclusion_quantification_failed = true;

                                        return
                                    end

                                end

                                % mixing ratio
                                if isequal(col, 1)
                                    mixing_ratio(unk) = calculate_mixing_ratio_via_two_IS( ...
                                        app, bracket_idx, cal_idx, unk, host_from_spot_index, ...
                                        internal_standard_name, internal_standard_mass_fraction);
                                end

                                % mass fractions
                                [mass_fractions_inclusion(col_idx), mass_fractions_mix(col_idx)] = ...
                                    calculate_mass_fractions_via_two_IS(app, bracket_idx, cal_idx, unk, host_from_spot_index, col, col_idx, mixing_ratio);

                            end
                        end

                        % Update current tables for this unk
                        current_inclusion_composition{unk,:} = mass_fractions_inclusion;
                        current_mixing_ratio(unk)            = mixing_ratio(unk);
                        current_mix_composition{unk,:}       = mass_fractions_mix;
                    end

                    % store in app
                    app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx} = current_inclusion_composition;
                    app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx}          = current_mixing_ratio;
                    app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}       = current_mix_composition;

                    % handle empty sensitivities
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx} = zeros(0,0);
                    end
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx} = zeros(0,0);
                    end
                    if isempty(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}) || ...
                            isequal(app.base.project.session.spot.sensitivities{bracket_idx,cal_idx}, zeros(0,0))
                        app.base.project.session.spot.mix_composition{bracket_idx,cal_idx} = zeros(0,0);
                    end

                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % dynamic passes for sequential data reduction (IS "from" CAL)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            max_passes = length(unique_CALs);
            for pass_incl = 1:max_passes

                changes_made_incl = false; % track if any values updated in this pass

                for cal_idx = 1:size(app.base.project.session.spot.sensitivities, 2)
                    for bracket_idx = 1:size(app.base.project.session.spot.sensitivities, 1)

                        current_inclusion_composition = array2table( ...
                            single(zeros(length(app.base.project.session.spot.signal_starts), ...
                            numel(app.base.project.session.spot.analytes))), ...
                            'VariableNames', ...
                            app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames);

                        current_mixing_ratio = single(zeros(length(app.base.project.session.spot.signal_starts), 1));

                        current_mix_composition = array2table( ...
                            single(zeros(length(app.base.project.session.spot.signal_starts), ...
                            numel(app.base.project.session.spot.analytes))), ...
                            'VariableNames', ...
                            app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames);

                        for i = 1:numel(all_UNK)
                            unk = all_UNK(i);

                            % Skip if no internal standard is defined
                            if isempty(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk})
                                continue
                            end

                            %%%% here it starts for the IS1 and IS2 %%%%
                            % first internal standard element
                            is_element = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};

                            % second internal standard element
                            second_is_element = [];
                            if isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                                second_is_element = app.base.project.session.spot.second_internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                            end

                            % 'from' flags
                            first_is_from = ischar(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                startsWith(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from') && ...
                                ~isequal(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk}, 'Total Oxides');

                            second_is_from = false;
                            if ~isempty(second_is_element)
                                second_is_from = ischar(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                    startsWith(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from');
                            end

                            % --------------------------------------------------
                            % CASE 1: IS1 is 'from <CAL>' (IS2 may or may not be)
                            % --------------------------------------------------
                            if first_is_from

                                % source CAL for IS1
                                source_cal_1   = extractAfter(app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from ');
                                source_cal_idx_1 = find(strcmp(unique_CALs, source_cal_1));

                                calculated_is_value_1 = 0;
                                if ~isempty(source_cal_idx_1) && ...
                                        ~isempty(app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_1})
                                    calculated_is_value_1 = app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_1}{unk, is_element};
                                end

                                % optional: source CAL for IS2 (if 'from' used)
                                calculated_is_value_2 = [];
                                if second_is_from
                                    source_cal_2    = extractAfter(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from ');
                                    source_cal_idx_2 = find(strcmp(unique_CALs, source_cal_2));

                                    if ~isempty(source_cal_idx_2) && ...
                                            ~isempty(app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_2})
                                        calculated_is_value_2 = app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_2}{unk, second_is_element};
                                    else
                                        calculated_is_value_2 = 0;
                                    end
                                end

                                % if required values are not available (>0) keep old values
                                if isequal(calculated_is_value_1, 0) || ...
                                        (second_is_from && (isempty(calculated_is_value_2) || isequal(calculated_is_value_2, 0)))

                                    current_inclusion_composition{unk,:} = ...
                                        table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx}(unk,:));
                                    current_mixing_ratio(unk) = ...
                                        app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx}(unk);
                                    current_mix_composition{unk,:} = ...
                                        table2array(app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}(unk,:));

                                elseif calculated_is_value_1 > 0 && ...
                                        (~second_is_from || calculated_is_value_2 > 0)

                                    % store original values
                                    original_is_mass_fraction_1 = ...
                                        app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};
                                    old_values = table2array( ...
                                        app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx}(unk,:));

                                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                        calculated_is_value_1;

                                    if second_is_from
                                        original_is_mass_fraction_2 = ...
                                            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};
                                        app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                            calculated_is_value_2;
                                    end

                                    % recalculate mass fractions with updated IS values
                                    sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames;
                                    mass_fractions_inclusion = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));
                                    mixing_ratio             = zeros(numel(app.base.project.session.spot.signal_starts), 1);
                                    mass_fractions_mix       = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));

                                    internal_standard_name         = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                                    internal_standard_intensity    = app.base.project.session.spot.bkgd_corrected_cps_incl.(char(internal_standard_name))(unk);
                                    internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};

                                    for col = 1:length(sensitivity_cols)

                                        col_idx = find(strcmp(app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames, ...
                                            sensitivity_cols{col}));

                                        if isequal(app.base.project.session.spot.unmixing_via{unk}, 'Matrix-only tracer') && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames)

                                            mass_fractions_inclusion(col_idx) = ...
                                                (app.base.project.session.spot.bkgd_corrected_cps_incl.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                (internal_standard_mass_fraction / ...
                                                app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));

                                            host_only_tracer = app.base.project.session.spot.matrix_only_tracer{unk};
                                            if any(ismember(app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames, ...
                                                    host_only_tracer))

                                                % check if the host from a different
                                                % spot was taken for MOT unmixing
                                                if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                                        message = {['The quantification for spot #' num2str(unk) ...
                                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                            ' failed. Make sure that the host for this spot is quantified.']};
                                                        uialert(app.UIFigure, message, 'Warning');

                                                        inclusion_quantification_failed = true;

                                                        return

                                                    else

                                                        host_from_spot_index = unk;

                                                    end

                                                else

                                                    % Get the matrix name for the given unk
                                                    matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                                    % Find the index in spot.name
                                                    host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                                        message = {['The quantification for spot #' num2str(unk) ...
                                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                            ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                                        uialert(app.UIFigure, message, 'Warning');

                                                        inclusion_quantification_failed = true;

                                                        return
                                                    end

                                                end
                                                % mixing ratio
                                                if isequal(col, 1)
                                                    mixing_ratio(unk) = calculate_mixing_ratio_x_via_MOT( ...
                                                        app, bracket_idx, cal_idx, unk, host_from_spot_index, ...
                                                        internal_standard_name, internal_standard_mass_fraction, host_only_tracer);
                                                end

                                                % mass fractions of the mix
                                                mass_fractions_mix(col_idx) = calculate_mix_mass_fractions_via_MOT( ...
                                                    app, bracket_idx, cal_idx, unk, host_from_spot_index, col_idx, ...
                                                    mixing_ratio, mass_fractions_inclusion);


                                            else
                                                % here it just continues without
                                                % the quantifying the mix because
                                                % the MOT is not in the CAL
                                                continue
                                            end

                                        elseif isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards') && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames) && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_mix.Properties.VariableNames)


                                            % check if the host from a different
                                            % spot was taken for MOT unmixing
                                            if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                                if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                                    message = {['The quantification for spot #' num2str(unk) ...
                                                        ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                        ' failed. Make sure that the host for this spot is quantified.']};
                                                    uialert(app.UIFigure, message, 'Warning');

                                                    inclusion_quantification_failed = true;

                                                    return

                                                else

                                                    host_from_spot_index = unk;

                                                end

                                            else

                                                % Get the matrix name for the given unk
                                                matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                                % Find the index in spot.name
                                                host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                                if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                                    message = {['The quantification for spot #' num2str(unk) ...
                                                        ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                        ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                                    uialert(app.UIFigure, message, 'Warning');

                                                    inclusion_quantification_failed = true;

                                                    return
                                                end

                                            end


                                            % mixing ratio
                                            if isequal(col, 1)
                                                mixing_ratio(unk) = calculate_mixing_ratio_via_two_IS( ...
                                                    app, bracket_idx, cal_idx, unk,host_from_spot_index, ...
                                                    internal_standard_name, internal_standard_mass_fraction);
                                            end

                                            % mass fractions
                                            [mass_fractions_inclusion(col_idx), mass_fractions_mix(col_idx)] = ...
                                                calculate_mass_fractions_via_two_IS(app, bracket_idx, cal_idx, unk, host_from_spot_index, col, col_idx, mixing_ratio);


                                        end
                                    end

                                    % restore original IS values
                                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                        original_is_mass_fraction_1;
                                    if second_is_from
                                        app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                            original_is_mass_fraction_2;
                                    end

                                    current_inclusion_composition{unk,:} = mass_fractions_inclusion;
                                    current_mixing_ratio(unk)            = mixing_ratio(unk);
                                    current_mix_composition{unk,:}       = mass_fractions_mix;

                                    if any(abs(mass_fractions_inclusion - old_values) > 1e-10)
                                        changes_made_incl = true;
                                    end
                                end

                                % --------------------------------------------------
                                % CASE 2: IS1 numeric, IS2 is 'from <CAL>'
                                % --------------------------------------------------
                            elseif second_is_from

                                source_cal_2    = extractAfter(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}, 'from ');
                                source_cal_idx_2 = find(strcmp(unique_CALs, source_cal_2));

                                calculated_is_value_2 = 0;
                                if ~isempty(source_cal_idx_2) && ...
                                        ~isempty(app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_2})
                                    calculated_is_value_2 = app.base.project.session.spot.inclusion_composition{bracket_idx,source_cal_idx_2}{unk, second_is_element};
                                end

                                if isequal(calculated_is_value_2, 0)

                                    current_inclusion_composition{unk,:} = ...
                                        table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx}(unk,:));
                                    current_mixing_ratio(unk) = ...
                                        app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx}(unk);
                                    current_mix_composition{unk,:} = ...
                                        table2array(app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}(unk,:));

                                elseif calculated_is_value_2 > 0

                                    % store original second IS
                                    original_is_mass_fraction_2 = ...
                                        app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};
                                    old_values = table2array( ...
                                        app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx}(unk,:));

                                    app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                        calculated_is_value_2;

                                    % recalculate with updated second IS
                                    sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames;
                                    mass_fractions_inclusion = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));
                                    mixing_ratio             = zeros(numel(app.base.project.session.spot.signal_starts), 1);
                                    mass_fractions_mix       = zeros(1, width(app.base.project.session.spot.bkgd_corrected_cps_incl));

                                    internal_standard_name         = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                                    internal_standard_intensity    = app.base.project.session.spot.bkgd_corrected_cps_incl.(char(internal_standard_name))(unk);
                                    internal_standard_mass_fraction = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};

                                    for col = 1:length(sensitivity_cols)

                                        col_idx = find(strcmp(app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames, ...
                                            sensitivity_cols{col}));

                                        if isequal(app.base.project.session.spot.unmixing_via{unk}, 'Matrix-only tracer') && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames)

                                            mass_fractions_inclusion(col_idx) = ...
                                                (app.base.project.session.spot.bkgd_corrected_cps_incl.(sensitivity_cols{col})(unk) / internal_standard_intensity) * ...
                                                (internal_standard_mass_fraction / ...
                                                app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk));

                                            host_only_tracer = app.base.project.session.spot.matrix_only_tracer{unk};
                                            if any(ismember(app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames, ...
                                                    host_only_tracer))
                                                % check if the host from a different
                                                % spot was taken for MOT unmixing
                                                if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                                        message = {['The quantification for spot #' num2str(unk) ...
                                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                            ' failed. Make sure that the host for this spot is quantified.']};
                                                        uialert(app.UIFigure, message, 'Warning');

                                                        inclusion_quantification_failed = true;

                                                        return

                                                    else

                                                        host_from_spot_index = unk;

                                                    end

                                                else

                                                    % Get the matrix name for the given unk
                                                    matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                                    % Find the index in spot.name
                                                    host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                                    if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                                        message = {['The quantification for spot #' num2str(unk) ...
                                                            ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                            ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                                        uialert(app.UIFigure, message, 'Warning');

                                                        inclusion_quantification_failed = true;

                                                        return
                                                    end

                                                end

                                                % mixing ratio
                                                if isequal(col, 1)
                                                    mixing_ratio(unk) = calculate_mixing_ratio_x_via_MOT( ...
                                                        app, bracket_idx, cal_idx, unk, host_from_spot_index, ...
                                                        internal_standard_name, internal_standard_mass_fraction, host_only_tracer);
                                                end

                                                % mass fractions of the mix
                                                mass_fractions_mix(col_idx) = calculate_mix_mass_fractions_via_MOT( ...
                                                    app, bracket_idx, cal_idx, unk, host_from_spot_index, col_idx, ...
                                                    mixing_ratio, mass_fractions_inclusion);

                                            else
                                                continue
                                            end

                                        elseif isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards') && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames) && ...
                                                ismember(sensitivity_cols{col}, app.base.project.session.spot.bkgd_corrected_cps_mix.Properties.VariableNames)

                                            % check if the host from a different
                                            % spot was taken for MOT unmixing
                                            if isequal(app.base.project.session.spot.matrix_from_spot(unk), app.base.project.session.spot.name(unk))

                                                if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(unk,:))), 0)

                                                    message = {['The quantification for spot #' num2str(unk) ...
                                                        ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                        ' failed. Make sure that the host for this spot is quantified.']};
                                                    uialert(app.UIFigure, message, 'Warning');

                                                    inclusion_quantification_failed = true;

                                                    return

                                                else

                                                    host_from_spot_index = unk;

                                                end

                                            else

                                                % Get the matrix name for the given unk
                                                matrix_name = app.base.project.session.spot.matrix_from_spot{unk};

                                                % Find the index in spot.name
                                                host_from_spot_index = find(strcmp(app.base.project.session.spot.name, matrix_name));

                                                if isequal(sum(table2array(app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}(host_from_spot_index,:))), 0)

                                                    message = {['The quantification for spot #' num2str(unk) ...
                                                        ' for calibrator ' char(unique_CALs(cal_idx)) ...
                                                        ' failed. Make sure that the host ' num2str(host_from_spot_index) ' for this spot is quantified.']};
                                                    uialert(app.UIFigure, message, 'Warning');

                                                    inclusion_quantification_failed = true;

                                                    return
                                                end

                                            end


                                            % mixing ratio
                                            if isequal(col, 1)
                                                mixing_ratio(unk) = calculate_mixing_ratio_via_two_IS( ...
                                                    app, bracket_idx, cal_idx, unk,host_from_spot_index, ...
                                                    internal_standard_name, internal_standard_mass_fraction);
                                            end

                                            % mass fractions
                                            [mass_fractions_inclusion(col_idx), mass_fractions_mix(col_idx)] = ...
                                                calculate_mass_fractions_via_two_IS(app, bracket_idx, cal_idx, unk, host_from_spot_index, col, col_idx, mixing_ratio);

                                        end
                                    end

                                    % restore original second IS
                                    app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk} = ...
                                        original_is_mass_fraction_2;

                                    current_inclusion_composition{unk,:} = mass_fractions_inclusion;
                                    current_mixing_ratio(unk)            = mixing_ratio(unk);
                                    current_mix_composition{unk,:}       = mass_fractions_mix;

                                    if any(abs(mass_fractions_inclusion - old_values) > 1e-10)
                                        changes_made_incl = true;
                                    end
                                end

                                % --------------------------------------------------
                                % CASE 3: no 'from' for either IS – just keep values
                                % --------------------------------------------------
                            else
                                current_inclusion_composition{unk,:} = ...
                                    table2array(app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx}(unk,:));
                                current_mixing_ratio(unk) = ...
                                    app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx}(unk);
                                current_mix_composition{unk,:} = ...
                                    table2array(app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}(unk,:));
                            end
                        end

                        app.base.project.session.spot.inclusion_composition{bracket_idx,cal_idx} = current_inclusion_composition;
                        app.base.project.session.spot.mixing_ratio{bracket_idx,cal_idx}          = current_mixing_ratio;
                        app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}       = current_mix_composition;
                    end
                end

                % stop early if nothing changed in a pass after the first
                if ~changes_made_incl && pass_incl > 1
                    break;
                end
            end
        end

        %%%%%%%%%%%%%%%% Total Oxides %%%%%%%%%%%%%%%%%%%%%

        function get_oxides_from_file(app)

            if isfield(app.base.project.session, 'oxides')
                app.base.project.session = rmfield(app.base.project.session, 'oxides');
            end

            try

                oxide_file = dir(fullfile(app.base.project.session.config.total_oxides_path, 'Oxides.xlsx'));

                % Get the full path of the file
                filePath = fullfile(app.base.project.session.config.total_oxides_path, oxide_file.name);

                [raw] = readtable(filePath);

            catch ME
                
                DisplayError(app, ME,'Error reading oxides from Oxides.xlsx. Close it if it is open in another program. Make sure the Total Oxide folder was not moved or renamed.')
                message={'Error reading oxides from Oxides.xlsx. Did you rename or move the folder? Close the Oxides.xlsx if it is open in another program. If you got the base/project/session from someone else, adjust the total oxides folder path.'};
                uialert(app.UIFigure,message,' Error');
                
            end

            % Extract the data
            element_names = raw(:,1); % First col (elements)
            oxide_names = raw(:,2);   % Second col (oxides)
            conversion_factors = raw(:,3);  % Third col (values)
            preferred_isotope = raw(:,4);
            considered_major = raw(:, 5);

            oxide_factors = containers.Map(element_names.Variables, conversion_factors.Variables);

            % Store the map in the app
            app.base.project.session.oxides.conversion_factors = oxide_factors;

            % Store oxide names
            app.base.project.session.oxides.oxide_names = table2cell(oxide_names);

            % Store element names (already extracted in the loop above)
            app.base.project.session.oxides.element_names = table2cell(element_names);

            % Store preferred_isotopes
            app.base.project.session.oxides.preferred_isotopes = table2cell(preferred_isotope);

            % This is just for visualization in the results module and has no impact on the quantification - All
            % oxides that are in the .xlsx file will be considered for quantification.
            app.base.project.session.oxides.considered_major = table2cell(considered_major);

        end

        function [intstd_elements_intensity , internal_standard_mass_fraction, intstd_elements_name] = total_oxide_calculation(app, bracket_idx, cal_idx, unk)

            % this function is called in calculate_matrix_mass_fractions
            % it outputs the intensity and mass fraction of the major element
            % with the highest net-count rate (like in the old SILLS). The rest
            % is calculated in the calculate_matrix_mass_fractions function

            % Get column names from sensitivity table
            elements_to_total = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.Properties.VariableNames;

            % Get oxide information from session
            element_names = app.base.project.session.oxides.element_names;
            preferred_isotopes = app.base.project.session.oxides.preferred_isotopes;

            % First: Filter to only keep elements that have oxides defined
            temp_elements = {};
            for i = 1:length(elements_to_total)
                current = elements_to_total{i};
                element_name = current(isstrprop(current, 'alpha'));

                % Check if this element has an oxide defined
                if ismember(element_name, element_names)
                    temp_elements{end+1} = current;
                end
            end

            % Second: Filter to keep only one preferred isotope per element
            filtered_elements = {};

            for i = 1:length(element_names)
                element_name = element_names{i};

                % Find all measured isotopes for this element
                measured_isotopes = temp_elements( ...
                    cellfun(@(x) isequal(x(isstrprop(x, 'alpha')), element_name), temp_elements));

                if ~isempty(measured_isotopes)
                    % Get preferred isotope for this element
                    preferred = preferred_isotopes{i};

                    if ~isempty(preferred)
                        % If preferred isotope is measured, use it
                        idx = find(strcmp(measured_isotopes, preferred), 1);
                        if ~isempty(idx)
                            filtered_elements{end+1} = preferred;
                        else
                            % Preferred isotope not measured, use first measured
                            filtered_elements{end+1} = measured_isotopes{1};
                        end
                    else
                        % No preferred isotope, use first measured
                        filtered_elements{end+1} = measured_isotopes{1};
                    end
                end
            end

            elements_to_total = filtered_elements(:)';


            %%%%%%%%%
            % Step 1: Calculate preliminary normalized mass fractions
            % the IS is taken from the
            % calculate_sensitivity_relative_to_the_internal_standard
            % function where the IS for the total mass calcualtion is
            % defined as the analyte with the highest net cps.

            sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk, elements_to_total));
            intensity = table2array(app.base.project.session.spot.bkgd_corrected_cps(unk, elements_to_total));

            preliminary_mass_fraction = intensity ./ sensitivity;

            % Att make sure to not fuck up the indices here
            intstd_element_index = table2array(app.internal_standard_indices_total_oxides{bracket_idx, cal_idx}(unk,:));

            normalized_mass_fraction = preliminary_mass_fraction ./ preliminary_mass_fraction(intstd_element_index);

            % up to this point, this was limited by which elements are in a
            % given CAL as seen in the sensitivity

            % Step 2: Handle Fe2+/Fe3+ ratio like old SILLS
            %%%%%%%%%%% For Julia!!! %%%%%%%%%%%%

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                fe_idx = find(contains(elements_to_total, 'Fe'));

                fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, cal_idx}{unk};

                if ~isempty(fe_ratio) && ~isequal(fe_ratio, 1)
                    if any(fe_idx)
                        fe_total = normalized_mass_fraction(fe_idx);
                        % Calculate Fe2O3 and FeO using the constants from old script
                        % 0.699480/0.777314 is the ratio of Fe in Fe2O3 vs FeO
                        fe_constant = 0.699480/0.777314;

                        % Calculate fractions using the same formula as old script
                        fe2o3_fraction = fe_total * (1-(1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio))));
                        feo_fraction = fe_total * (1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio)));

                        % Update Fe calculations
                        normalized_mass_fraction(fe_idx) = feo_fraction;
                        % Add Fe2O3 contribution
                        normalized_mass_fraction = [normalized_mass_fraction fe2o3_fraction];
                        elements_to_total = [elements_to_total {'Fe2O3'}];
                    end
                end
            end

            % Step 3: Convert to oxides
            oxide_factors = app.base.project.session.oxides.conversion_factors;
            oxide_wt_percent = zeros(size(normalized_mass_fraction));

            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element) && isKey(oxide_factors, base_element{1}) && ~strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * oxide_factors(base_element{1});
                elseif strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * 1.4297;
                end
            end

            % Step 4: Normalize to input total oxide wt%
            if ~isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_idx, cal_idx}{unk})
                std_value = app.base.project.session.spot.internal_standard_oxide_wt{bracket_idx, cal_idx}{unk};
                if ~isempty(std_value)
                    oxide_wt_percent = oxide_wt_percent * (std_value / sum(oxide_wt_percent));
                end
            end

            % so this oxide_wt_percent is now the correct value for a given
            % cal_idx, bracket_idx and unk

            % Step 5: Convert major oxide weight percentages back to µg/g
            mass_fractions_ugg = zeros(size(oxide_wt_percent));
            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element)
                    if contains(element, 'Fe') && ~strcmp(element, 'Fe2O3')
                        % Store Fe2+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors('Fe')) * 10000;
                        %major_elements_names{j} = 'Fe2';

                    elseif strcmp(element, 'Fe2O3')
                        % Store Fe3+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / 1.4297) * 10000;
                        elements_to_total{j} = 'Fe3';

                    elseif isKey(oxide_factors, base_element{1})
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors(base_element{1})) * 10000;
                    end
                end
            end

            % Initialize mass_fractions table with Fe2+/Fe3+ columns
            % major_mass_fractions = array2table(zeros(size(app.base.project.session.spot.bkgd_corrected_cps,1), length([major_elements_names, {'Fe2', 'Fe3'}])), ...
            %     'VariableNames', [major_elements_names, {'Fe2', 'Fe3'}]);

            mass_fractions = array2table(zeros(size(app.base.project.session.spot.bkgd_corrected_cps,1), length(elements_to_total)), ...
                'VariableNames', elements_to_total);

            mass_fractions{unk, elements_to_total} = mass_fractions_ugg;

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                % Find the column that contains 'Fe' in the header
                fe_col = find(contains(mass_fractions.Properties.VariableNames, 'Fe') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe2') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe3'));

                mass_fractions.Fe2 = table2array(mass_fractions(:, fe_col));

                % Sum Fe2 and Fe3 to ge the total Fe
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions{:, fe_col} = mass_fractions.Fe2 + mass_fractions.Fe3;
                end

                %%%%%%%% store the Fe2 and Fe3
                if ~isfield(app.base.project.session.spot, 'Fe_2_3')
                    app.base.project.session.spot.Fe_2_3 = cell(size(app.base.project.session.spot.sensitivities));
                end

                if isempty(app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx})
                    num_rows = size(app.base.project.session.spot.bkgd_corrected_cps, 1);
                    app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx} = array2table(zeros(num_rows, 2), ...
                        'VariableNames', {'Fe2', 'Fe3'});
                end

                % Store Fe2 and Fe3 in app properties, resetting to 0 if missing
                if ismember('Fe2', mass_fractions.Properties.VariableNames)
                    app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx}.Fe2(unk) = ...
                        mass_fractions.Fe2(unk);
                else
                    app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx}.Fe2(unk) = 0;
                end

                if ismember('Fe3', mass_fractions.Properties.VariableNames)
                    app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx}.Fe3(unk) = ...
                        mass_fractions.Fe3(unk);
                else
                    % ensure Fe3 is explicitly reset if not present this run
                    app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx}.Fe3(unk) = 0;
                end

                % Convert table columns to single precision
                temp_table = app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx};
                temp_table.Fe2 = single(temp_table.Fe2);
                temp_table.Fe3 = single(temp_table.Fe3);
                app.base.project.session.spot.Fe_2_3{bracket_idx, cal_idx} = temp_table;

                % Remove Fe2 and Fe3 columns from major_mass_fractions
                mass_fractions.Fe2 = [];
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions.Fe3 = [];
                end

            end

            % for function output
            intstd_elements_name = elements_to_total(intstd_element_index);
            intstd_elements_intensity = app.base.project.session.spot.bkgd_corrected_cps.(intstd_elements_name{1})(unk);

            internal_standard_mass_fraction = mass_fractions.(intstd_elements_name{1})(unk);

        end

        function [intstd_elements_intensity , internal_standard_mass_fraction, intstd_elements_name] = total_oxide_calculation_inclusion_MOT(app, bracket_idx, cal_idx, unk)


            % Get column names from sensitivity table
            elements_to_total = app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}.Properties.VariableNames;

            % Get oxide information from session
            element_names = app.base.project.session.oxides.element_names;
            preferred_isotopes = app.base.project.session.oxides.preferred_isotopes;

            % First: Filter to only keep elements that have oxides defined
            temp_elements = {};
            for i = 1:length(elements_to_total)
                current = elements_to_total{i};
                element_name = current(isstrprop(current, 'alpha'));

                % Check if this element has an oxide defined
                if ismember(element_name, element_names)
                    temp_elements{end+1} = current;
                end
            end

            % Second: Filter to keep only one preferred isotope per element
            filtered_elements = {};

            for i = 1:length(element_names)
                element_name = element_names{i};

                % Find all measured isotopes for this element
                measured_isotopes = temp_elements( ...
                    cellfun(@(x) isequal(x(isstrprop(x, 'alpha')), element_name), temp_elements));

                if ~isempty(measured_isotopes)
                    % Get preferred isotope for this element
                    preferred = preferred_isotopes{i};

                    if ~isempty(preferred)
                        % If preferred isotope is measured, use it
                        idx = find(strcmp(measured_isotopes, preferred), 1);
                        if ~isempty(idx)
                            filtered_elements{end+1} = preferred;
                        else
                            % Preferred isotope not measured, use first measured
                            filtered_elements{end+1} = measured_isotopes{1};
                        end
                    else
                        % No preferred isotope, use first measured
                        filtered_elements{end+1} = measured_isotopes{1};
                    end
                end
            end

            elements_to_total = filtered_elements(:)';


            %%%%%%%%%
            % Step 1: Calculate preliminary normalized mass fractions
            % the IS is taken from the
            % calculate_sensitivity_relative_to_the_internal_standard
            % function where the IS for the total mass calcualtion is
            % defined as the analyte with the highest net cps.

            sensitivity = table2array(app.base.project.session.spot.sensitivities{bracket_idx, cal_idx}(unk, elements_to_total));
            intensity = table2array(app.base.project.session.spot.bkgd_corrected_cps_incl(unk, elements_to_total));
            %%%%%%% Att, if the intensity of some elements is 0 it affects
            %%%%%%% the normalization, I'm not sure yet if I have to get
            %%%%%%% rid of them or if this is the right way. I think its
            %%%%%%% the right way though.

            preliminary_mass_fraction = intensity ./ sensitivity;

            %% Att make sure to not fuck up the indices here
            intstd_element_index = table2array(app.internal_standard_indices_total_oxides_inclusion{bracket_idx, cal_idx}(unk,:));

            normalized_mass_fraction = preliminary_mass_fraction ./ preliminary_mass_fraction(intstd_element_index);

            % up to this point, this was limited by which elements are in a
            % given CAL as seen in the sensitivity

            % Step 2: Handle Fe2+/Fe3+ ratio like old SILLS
            %%%%%%%%%%% For Julia!!! %%%%%%%%%%%%

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                fe_idx = find(contains(elements_to_total, 'Fe'));

                fe_ratio = app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{bracket_idx, cal_idx}{unk};

                if ~isempty(fe_ratio) && ~isequal(fe_ratio, 1)
                    if any(fe_idx)
                        fe_total = normalized_mass_fraction(fe_idx);
                        % Calculate Fe2O3 and FeO using the constants from old script
                        % 0.699480/0.777314 is the ratio of Fe in Fe2O3 vs FeO
                        fe_constant = 0.699480/0.777314;

                        % Calculate fractions using the same formula as old script
                        fe2o3_fraction = fe_total * (1-(1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio))));
                        feo_fraction = fe_total * (1/(1+(fe_constant)*((1-fe_ratio)/fe_ratio)));

                        % Update Fe calculations
                        normalized_mass_fraction(fe_idx) = feo_fraction;
                        % Add Fe2O3 contribution
                        normalized_mass_fraction = [normalized_mass_fraction fe2o3_fraction];
                        elements_to_total = [elements_to_total {'Fe2O3'}];
                    end
                end
            end

            % Step 3: Convert to oxides
            oxide_factors = app.base.project.session.oxides.conversion_factors;
            oxide_wt_percent = zeros(size(normalized_mass_fraction));

            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element) && isKey(oxide_factors, base_element{1}) && ~strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * oxide_factors(base_element{1});
                elseif strcmp(element, 'Fe2O3')
                    oxide_wt_percent(j) = normalized_mass_fraction(j) * 1.4297;
                end
            end

            % Step 4: Normalize to input total oxide wt%
            if ~isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{bracket_idx, cal_idx}{unk})
                std_value = app.base.project.session.spot.internal_standard_oxide_wt_inclusion{bracket_idx, cal_idx}{unk};
                if ~isempty(std_value)
                    oxide_wt_percent = oxide_wt_percent * (std_value / sum(oxide_wt_percent));
                end
            end

            % so this oxide_wt_percent is now the correct value for a given
            % cal_idx, bracket_idx and unk

            % Step 5: Convert major oxide weight percentages back to µg/g
            mass_fractions_ugg = zeros(size(oxide_wt_percent));
            for j = 1:length(elements_to_total)
                element = elements_to_total{j};
                base_element = regexp(element, '^[A-Za-z]+', 'match');
                if ~isempty(base_element)
                    if contains(element, 'Fe') && ~strcmp(element, 'Fe2O3')
                        % Store Fe2+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors('Fe')) * 10000;
                        %major_elements_names{j} = 'Fe2';

                    elseif strcmp(element, 'Fe2O3')
                        % Store Fe3+ value
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / 1.4297) * 10000;
                        elements_to_total{j} = 'Fe3';

                    elseif isKey(oxide_factors, base_element{1})
                        mass_fractions_ugg(j) = (oxide_wt_percent(j) / oxide_factors(base_element{1})) * 10000;
                    end
                end
            end

            mass_fractions = array2table(zeros(size(app.base.project.session.spot.bkgd_corrected_cps_incl,1), length(elements_to_total)), ...
                'VariableNames', elements_to_total);

            mass_fractions{unk, elements_to_total} = mass_fractions_ugg;

            if any(contains(app.base.project.session.oxides.oxide_names, 'FeO')) && any(contains(elements_to_total, 'Fe'))

                % Find the column that contains 'Fe' in the header
                fe_col = find(contains(mass_fractions.Properties.VariableNames, 'Fe') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe2') & ...
                    ~strcmp(mass_fractions.Properties.VariableNames, 'Fe3'));

                mass_fractions.Fe2 = table2array(mass_fractions(:, fe_col));

                % Sum Fe2 and Fe3 to ge the total Fe
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions{:, fe_col} = mass_fractions.Fe2 + mass_fractions.Fe3;
                end

                %%%%%%%% store the Fe2 and Fe3
                if ~isfield(app.base.project.session.spot, 'Fe_2_3_incl')
                    app.base.project.session.spot.Fe_2_3_incl = cell(size(app.base.project.session.spot.sensitivities));
                end

                if isempty(app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx})
                    num_rows = size(app.base.project.session.spot.bkgd_corrected_cps_incl, 1);
                    app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx} = array2table(zeros(num_rows, 2), ...
                        'VariableNames', {'Fe2', 'Fe3'});
                end

                % Store Fe2 and Fe3 in app properties without overwriting previous values
                if any(ismember(mass_fractions.Properties.VariableNames, 'Fe2'))
                    app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx}.Fe2(unk) = mass_fractions.Fe2(unk);
                end
                if any(ismember(mass_fractions.Properties.VariableNames, 'Fe3'))
                    app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx}.Fe3(unk) = mass_fractions.Fe3(unk);
                end

                % Convert table columns to single precision
                temp_table = app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx};
                temp_table.Fe2 = single(temp_table.Fe2);
                temp_table.Fe3 = single(temp_table.Fe3);
                app.base.project.session.spot.Fe_2_3_incl{bracket_idx, cal_idx} = temp_table;

                % Remove Fe2 and Fe3 columns from major_mass_fractions
                mass_fractions.Fe2 = [];
                if any(contains(mass_fractions.Properties.VariableNames, 'Fe3'))
                    mass_fractions.Fe3 = [];
                end

            end

            % for function output
            intstd_elements_name = char(elements_to_total(intstd_element_index));
            intstd_elements_intensity = app.base.project.session.spot.bkgd_corrected_cps_incl.(intstd_elements_name)(unk);

            internal_standard_mass_fraction = mass_fractions.(intstd_elements_name)(unk);

        end

        %%%%%%%%%%%%%%%% Mixing ratio and mix/inclusion mass fraction calculations %%%%%%%%%%%%%%%%%%%%%

        function mixing_ratio = calculate_mixing_ratio_x_via_MOT(app, bracket_idx, cal_idx, unk, host_from_spot_index, internal_standard_name, internal_standard_mass_fraction, host_only_tracer)

            relative_sensitivity = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.(host_only_tracer)(unk);

            Cishost = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}.(char(internal_standard_name))(host_from_spot_index);
            Cthost = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}.(host_only_tracer)(host_from_spot_index);
            Cisinc = internal_standard_mass_fraction;

            Iismix = app.base.project.session.spot.bkgd_corrected_cps_mix.(char(internal_standard_name))(unk);
            Itmix = app.base.project.session.spot.bkgd_corrected_cps_mix.(host_only_tracer)(unk);

            %solve for the concentration ratio 'cr':
            cr = (Itmix/Iismix)/relative_sensitivity;

            %solve for x
            mixing_ratio = (Cthost - cr*Cishost)/(Cthost - cr*Cishost + cr*Cisinc);

        end

        function mass_fractions_mix = calculate_mix_mass_fractions_via_MOT(app, bracket_idx, cal_idx, unk, host_from_spot_index, col_idx, mixing_ratio, mass_fractions_inclusion)
                                    
            matrix_mass_fraction = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}{host_from_spot_index, col_idx};

            inclusion_mass_fraction = mass_fractions_inclusion(col_idx);

            x = mixing_ratio(unk);

            mass_fractions_mix = inclusion_mass_fraction * x + (1 - x) * matrix_mass_fraction;

        end
        
        function mixing_ratio = calculate_mixing_ratio_via_two_IS(app, bracket_idx, cal_idx, unk, host_from_spot_index, internal_standard_name, internal_standard_mass_fraction)

            second_internal_standard_name = app.base.project.session.spot.second_internal_standard_inclusion{bracket_idx,cal_idx}{unk};
            second_internal_standard_mass_fraction = app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};

            intensity_mix_is1 = app.base.project.session.spot.bkgd_corrected_cps_mix.(internal_standard_name)(unk);
            intensity_mix_is2 = app.base.project.session.spot.bkgd_corrected_cps_mix.(second_internal_standard_name)(unk);

            mass_fraction_host_is1 = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}.(internal_standard_name)(host_from_spot_index);
            mass_fraction_host_is2 = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}.(second_internal_standard_name)(host_from_spot_index);

            sensitivity_of_is1_relative_to_is2 = app.sensitivity_relative_to_second_internal_standard_inclusion{bracket_idx,cal_idx}.(char(internal_standard_name))(unk);

            a_mix_is1_is2 = (intensity_mix_is1 / intensity_mix_is2) * (1 / sensitivity_of_is1_relative_to_is2);

            mixing_ratio = (mass_fraction_host_is1 - a_mix_is1_is2 * mass_fraction_host_is2) / ...
                (mass_fraction_host_is1 - internal_standard_mass_fraction - a_mix_is1_is2 * mass_fraction_host_is2 + a_mix_is1_is2 * second_internal_standard_mass_fraction);

        end

        function [mass_fraction_incl_i, mass_fraction_mix_i] = calculate_mass_fractions_via_two_IS(app, bracket_idx, cal_idx, unk, host_from_spot_index, col, col_idx, mixing_ratio)

            second_internal_standard_name = app.base.project.session.spot.second_internal_standard_inclusion{bracket_idx,cal_idx}{unk};
            second_internal_standard_mass_fraction = app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk};

            intensity_mix_is2 = app.base.project.session.spot.bkgd_corrected_cps_mix.(second_internal_standard_name)(unk);
            mass_fraction_host_is2 = app.base.project.session.spot.matrix_composition{bracket_idx, cal_idx}.(second_internal_standard_name)(host_from_spot_index);

            % i equals col equals the element that is calculated
            intensity_mix_i = app.base.project.session.spot.bkgd_corrected_cps_mix{unk, col_idx};
            sensitivity_of_i_relative_to_is2 = app.sensitivity_relative_to_second_internal_standard_inclusion{bracket_idx,cal_idx}{unk, col};
            mass_fraction_host_i = app.base.project.session.spot.matrix_composition{bracket_idx,cal_idx}{host_from_spot_index, col_idx}; 

            % calculations
            mass_fraction_mix_is2 = (1 - mixing_ratio(unk)) * mass_fraction_host_is2 + mixing_ratio(unk) * second_internal_standard_mass_fraction;

            mass_fraction_mix_i = (intensity_mix_i / intensity_mix_is2) * (mass_fraction_mix_is2 / sensitivity_of_i_relative_to_is2);

            mass_fraction_incl_i = (1 / mixing_ratio(unk)) * mass_fraction_mix_i - ((1 - mixing_ratio(unk)) / mixing_ratio(unk)) * mass_fraction_host_i;

        end

        %%%%%%%%%%%%%%%% Limits of Detection %%%%%%%%%%%%%%%%%%%%%

        function LOD_matrix = calculate_limits_of_detection_matrix(app, bracket_idx, cal_idx, unk, sensitivity_cols, col, internal_standard_name, internal_standard_mass_fraction)
            % According to Pettke 2012

            background_start = app.base.project.session.spot.background_starts;
            background_end = app.base.project.session.spot.background_ends;

            % calculate the mean for the matrix
            matrix_1_start = app.base.project.session.spot.matrix_1_starts;
            matrix_1_end = app.base.project.session.spot.matrix_1_ends;

            matrix_2_start = app.base.project.session.spot.matrix_2_starts;
            matrix_2_end = app.base.project.session.spot.matrix_2_ends;

            matrix_3_start = app.base.project.session.spot.matrix_3_starts;
            matrix_3_end = app.base.project.session.spot.matrix_3_ends;

            % case of full measurement input
            if isequal(app.base.project.session.config.datatype, 'Continuous')
                signal = app.base.project.session.spot.signal_data;
            end

            % case of single_spot input
            if isequal(app.base.project.session.config.datatype, 'Single')
                signal = app.base.project.session.spot.signal_data{unk};
            end

            % mean_background_cps = table2array(mean(signal(background_start{unk}:background_end{unk}, 2:end), 1));
            mean_background_cps = mean(signal.(sensitivity_cols{col})(background_start{unk}:background_end{unk}));

            number_of_sweeps_bg = numel(background_start{unk}:background_end{unk});

            number_of_sweeps_matrix = numel(matrix_1_start{unk}:matrix_1_end{unk}) + numel(matrix_2_start{unk}:matrix_2_end{unk}) + numel(matrix_3_start{unk}:matrix_3_end{unk});

            % i dont know why this is done like that but the old SILLS also
            % done like this. Also in Pettke
            % 2012 it states "sensitivity of element in cps/µg/g as derived from the external standard multiplied by the relative sensitivity factor"

            sensitivity = app.sensitivity_relative_to_internal_standard{bracket_idx,cal_idx}.(sensitivity_cols{col})(unk) .* (app.base.project.session.spot.bkgd_corrected_cps.(char(internal_standard_name))(unk)/internal_standard_mass_fraction);

            % dwelltime in seconds
            dwelltime = app.base.project.session.spot.dwelltimes.(sensitivity_cols{col});

            LOD_matrix = (3.29 * (mean_background_cps * dwelltime * number_of_sweeps_matrix * (1 + number_of_sweeps_matrix / number_of_sweeps_bg)).^0.5 + 2.71)...
                / (number_of_sweeps_matrix * dwelltime * sensitivity);

        end

        function user_stopped_lod_calc = calculate_all_inclusion_limits_of_detection(app)

            user_stopped_lod_calc = false;

            if ~isequal(app.base.project.session.config.reductiontype, 'Inclusion')
                return
            end

            all_UNK = find(~strcmp(app.base.project.session.spot.type, "CAL"));
            nRows = length(app.base.project.session.spot.signal_starts);
            varNames = app.base.project.session.spot.bkgd_corrected_cps_incl.Properties.VariableNames;

            for cal_idx = 1:size(app.base.project.session.spot.sensitivities,2)
                for bracket_idx = 1:size(app.base.project.session.spot.sensitivities,1)

                    sensitivities = app.base.project.session.spot.sensitivities{bracket_idx,cal_idx};
                    
                    if isempty(sensitivities) || isequal(sensitivities, zeros(0,0))
                        app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,cal_idx} = zeros(0,0);
                        continue
                    end

                    mix_composition = app.base.project.session.spot.mix_composition{bracket_idx,cal_idx};
                    if all(mix_composition{:,:} == 0)
                        % ...existing warning logic...
                        app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,cal_idx} = ...
                            array2table(single(zeros(nRows, numel(varNames))), 'VariableNames', varNames);
                        continue
                    end

                    current_inclusion_limit_of_detection = array2table(single(zeros(nRows, numel(varNames))), 'VariableNames', varNames);

                    for i = 1:numel(all_UNK)
                        unk = all_UNK(i);

                        % Check for required internal standards, skip if missing
                        if isempty(app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk})
                            continue
                        end
                        if isempty(app.base.project.session.spot.second_internal_standard_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                            continue
                        end
                        if isempty(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_idx,cal_idx}{unk}) && ...
                                isequal(app.base.project.session.spot.unmixing_via{unk}, 'Two internal standards')
                            continue
                        end

                        % Check for matrix-only tracer issues
                        host_only_tracer = app.base.project.session.spot.matrix_only_tracer{unk};
                        if isequal(app.base.project.session.spot.unmixing_via{unk}, 'Matrix-only tracer') && ...
                                ~any(ismember(app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames, host_only_tracer))

                            unique_CALs = unique(app.base.project.session.spot.referencematerial);
                            unique_CALs = unique_CALs(~strcmp(unique_CALs, 'None'));

                            msg = {['The limits of detection for spot #' num2str(unk) ' for calibrator ' char(unique_CALs(cal_idx)) ' could not be calculated. Make sure that the matrix-only tracer element ' host_only_tracer ' has a known mass fraction (i.e. the element is defined in the RM file) for this CAL.']};

                            fig = app.UIFigure;
                            title = "ICP-Base";
                            selection_lods = uiconfirm(fig,msg,title, ...
                                "Options",["I want to continue with missing limits of detection","Cancel"], ...
                                "DefaultOption",1,"CancelOption",2);


                            switch selection_lods
                                case 'I want to continue with missing limits of detection'
                                    continue
                                case 'Cancel'
                                    user_stopped_lod_calc = true;
                                    return
                            end

                        end

                        if all(app.base.project.session.spot.mix_composition{bracket_idx, cal_idx}{unk, :} == 0)
                            continue
                        end

                        % --- Vectorized LOD calculation for all analytes ---
                        sensitivity_cols = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.Properties.VariableNames;
                        nAnalytes = numel(sensitivity_cols);

                        % Get signal data
                        if isequal(app.base.project.session.config.datatype, 'Continuous')
                            signal = app.base.project.session.spot.signal_data;
                        else
                            signal = app.base.project.session.spot.signal_data{unk};
                        end

                        background_start = app.base.project.session.spot.background_starts{unk};
                        background_end = app.base.project.session.spot.background_ends{unk};
                        inclusion_start = app.base.project.session.spot.inclusion_1_starts{unk};
                        inclusion_end = app.base.project.session.spot.inclusion_1_ends{unk};

                        number_of_sweeps_bg = numel(background_start:background_end);
                        number_of_sweeps_inclusion = numel(inclusion_start:inclusion_end);

                        % Internal standard name
                        internal_standard_name = app.base.project.session.spot.internal_standard_inclusion{bracket_idx,cal_idx}{unk};
                        if isequal(internal_standard_name, 'Total Oxides')
                            internal_standard_name = varNames{app.internal_standard_indices_total_oxides_inclusion{bracket_idx,cal_idx}{unk,:}};
                        end

                        % Prepare arrays for vectorized calculation
                        mean_background_cps = zeros(1, nAnalytes);
                        sensitivity = zeros(1, nAnalytes);
                        dwelltime = zeros(1, nAnalytes);
                        internal_standard_mass_fraction_mix = app.base.project.session.spot.mix_composition{bracket_idx,cal_idx}.(internal_standard_name)(unk);

                        for col = 1:nAnalytes
                            analyte = sensitivity_cols{col};
                            if ismember(analyte, varNames)
                                mean_background_cps(col) = mean(signal.(analyte)(background_start:background_end));
                                dwelltime(col) = app.base.project.session.spot.dwelltimes.(analyte);
                                sensitivity(col) = app.sensitivity_relative_to_internal_standard_inclusion{bracket_idx,cal_idx}.(analyte)(unk) * ...
                                    (app.base.project.session.spot.bkgd_corrected_cps_mix.(internal_standard_name)(unk) / internal_standard_mass_fraction_mix);
                            end
                        end

                        % LOD formula (vectorized)
                        lod_row = (3.29 * sqrt(mean_background_cps .* dwelltime .* number_of_sweeps_inclusion .* ...
                            (1 + number_of_sweeps_inclusion ./ number_of_sweeps_bg)) + 2.71) ./ ...
                            (number_of_sweeps_inclusion .* dwelltime .* sensitivity);

                        % Assign to correct columns in table
                        for col = 1:nAnalytes
                            analyte = sensitivity_cols{col};
                            if ismember(analyte, varNames)
                                col_idx = find(strcmp(varNames, analyte), 1, 'first');
                                current_inclusion_limit_of_detection{unk, col_idx} = single(lod_row(col));
                            end
                        end
                    end

                    app.base.project.session.spot.inclusion_limits_of_detection{bracket_idx,cal_idx} = current_inclusion_limit_of_detection;
                end
            end
        end


    end


    methods (Access = public)

        %%%%%%%%%%%%%%%%%%%%%%%%%% API %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [settings] = assign_internal_standard(app, settings)
            try
                test_state = true; % is needed later as a failsafe so that no broken data gets into the data structure

                % if settings is not sent or something wrong is put in
                if not(exist('settings', 'var')) || not(isstruct(settings))
                    settings = struct();
                end

                % if the internal_standard is not input
                if not(isfield(settings,'internal_standard')) || isempty(settings.internal_standard)
                    % now return the settings with the options for type:
                    settings.internal_standard.Options={"All Analyte Names or Total Oxides"};
                    disp('The internal standard must be provided in settings.internal_standard');
                    test_state = false;
                end

                % if the internal_standard_mass_fraction is not input
                if not(isfield(settings,'internal_standard_mass_fraction')) || isempty(settings.internal_standard_mass_fraction)
                    settings.internal_standard_mass_fraction.Options={"Mass fraction in µg/g or wt% total oxides"};
                    test_state = false;
                    disp('Internal standard mass fractions must be provided in settings.internal_standard_mass_fraction');
                end

                % if the internal_standard is not input
                if not(isfield(settings,'spotnumber_for_internal_standard')) || isempty(settings.spotnumber_for_internal_standard)
                    settings.spotnumber_for_internal_standard.Options={"Array of spotnumbers"};
                    disp('Spotnumbers for the internal standard must be provided in settings.spotnumber_for_internal_standard');
                    return
                end

                if not(isfield(settings,'internal_standard_for_primary_reference_material')) || isempty(settings.internal_standard_for_primary_reference_material)
                    settings.internal_standard_for_primary_reference_material.Options=string(unique(app.base.project.session.spot.referencematerial));
                    test_state = false;
                    disp('Calibrator name for internal standard mass fractions must be provided in settings.internal_standard_for_primary_reference_material');
                end


                if not(isfield(settings,'internal_standard_for_bracket_id')) || isempty(settings.internal_standard_for_bracket_id)
                    settings.internal_standard_for_bracket_id.Options=string(unique(settings.bracket_id));
                    test_state = false;
                    disp('Bracket_id for internal standard mass fractions must be provided in settings.internal_standard_for_bracket_id');
                end

                % if not(isfield(settings,'internal_standard_oxide_wt')) || isempty(settings.internal_standard_oxide_wt)
                %     settings.internal_standard_oxide_wt.Options = 1:100;
                %     test_state = false;
                %     disp('wt% total oxide must be provided in settings.internal_standard_oxide_wt');
                % end
                % 
                % if not(isfield(settings,'Fe_ratio_for_total_oxide')) || isempty(settings.Fe_ratio_for_total_oxide)
                %     settings.Fe_ratio_for_total_oxide.Options = 0:0.01:1;
                %     test_state = false;
                %     disp('FeO/(FeO + Fe2O3) ratio must be provided in settings.Fe_ratio_for_total_oxide');
                % end

                internal_standard_for_primary_reference_material = settings.internal_standard_for_primary_reference_material;
                spotnumber_for_internal_standard = settings.spotnumber_for_internal_standard;
                internal_standard = settings.internal_standard;
                internal_standard_mass_fraction = settings.internal_standard_mass_fraction;
                bracket_ids = settings.internal_standard_for_bracket_id;

                % I will implement this total oxide shit if it is really
                % needed
                %oxide_wt = settings.internal_standard_oxide_wt;
                %fe_ratio = settings.Fe_ratio_for_total_oxide;

                % if test_state = false, push the data from the cache to the
                % data structure
                if test_state == false
                    return
                else

                    % Get unique CALs
                    unique_CALs = unique(app.base.project.session.spot.referencematerial);
                    unique_CALs = unique_CALs(~strcmp(unique_CALs, 'None'));
                    unique_brackets = unique(bracket_ids);

                    % Process each entry
                    for i = 1:length(internal_standard_for_primary_reference_material)
                        % Find corresponding CAL index
                        cal_idx = find(strcmp(unique_CALs, internal_standard_for_primary_reference_material(i)), 1);
                        if isempty(cal_idx)
                            continue;
                        end

                        % Get bracket ID for this entry
                        current_bracket = bracket_ids(i);

                        % Find spot index within the bracket
                        spot_idx = spotnumber_for_internal_standard(i);

                        % Store data in appropriate location
                        app.base.project.session.spot.internal_standard{current_bracket, cal_idx}{spot_idx} = internal_standard(i);
                        app.base.project.session.spot.internal_standard_mass_fraction{current_bracket, cal_idx}{spot_idx} = internal_standard_mass_fraction(i);


                    end
                end

                if isfield(settings, 'quantification_module_GUI_update_state') && settings.quantification_module_GUI_update_state == true
                    update_quantification_module_GUI(app)
                end

            catch ME
                DisplayError(app, ME,'assign_internal_standard')

                message = "assign_internal_standard failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");
                disp("assign_internal_standard failed")
            end

        end

        %%%%%%%%%%%%%% Quantification Wrapper %%%%%%%%%%%%%

        function quantification_failed = quantification_wrapper(app)

            quantification_failed = false;

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Quantification', ...
                'Message', 'Calculating...', 'Cancelable', 'off', 'Indeterminate','on');

            % load in the Oxide.xlsx
            get_oxides_from_file(app)

            %%%%%%%%%% Matrix/Host
            calculate_sensitivity_relative_to_the_internal_standard(app)
            calculate_matrix_mass_fractions(app)

            %%%%%%%%%% Inclusion
            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                calculate_background_corrected_mix_intensities(app)
                calculate_sensitivity_relative_to_the_internal_standard_incl(app)
                calculate_sensitivity_relative_to_sec_internal_standard_incl(app)

                inclusion_quantification_failed = calculate_inclusion_mass_fractions(app);

                if inclusion_quantification_failed == true
                    quantification_failed = true;
                    delete(progress)
                    return
                end

                lods_failed = calculate_all_inclusion_limits_of_detection(app);

                if lods_failed
                    quantification_failed = true;
                end
            end

            delete(progress)

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function update_MSF_plot(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers
            ref_mat = app.base.project.session.spot.referencematerial(:);

            if numel(unique(setdiff(ref_mat, "None"))) == 1
                message={'At least two reference materials (CAL or QCRM) are needed for calculation of the MSF.'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end

            % Generate initial table with spot numbers and names
            all_sensitivities = table(spotNumbers, spotnames,ref_mat, 'VariableNames', {'#', 'Spotname', 'Reference Material'});

            initial_sensitivities = zeros(numel(app.base.project.session.spot.type), numel(app.base.project.session.spot.analytes));
            all_RM = [];

            % let all_RM be all spots which spot.type = all_RM
            for i = 1:numel(app.base.project.session.spot.type)
                if strcmp(app.base.project.session.spot.type(i), "CAL") || strcmp(app.base.project.session.spot.type(i), "QCRM")
                    all_RM = [all_RM,i];
                end
            end

            for i = 1:numel(all_RM)

                bg_corrected_cps_matrix = app.base.project.session.spot.bkgd_corrected_cps(all_RM(i) , :);

                %%%%%%%% Calculate the sensitivity %%%%%%%%%

                % get the name of the corresponding referencematerial that has been
                % chosen
                referencematerial = app.base.project.session.spot.referencematerial{all_RM(i)};

                % find the index of the corresponding referencematerial
                idx = find(strcmp(app.base.project.session.referencematerials.names, referencematerial));

                if ~isempty(idx)
                    % get the table for the corresponding referencematerial
                    RM_table = app.base.project.session.referencematerials.data{idx};

                    % get the column headers (elements) of the table
                    CAL_elements = RM_table.Properties.VariableNames;

                    % Remove numbers from analytes
                    cleaned_analytes = cellfun(@(x) regexprep(x, '\d', ''), app.base.project.session.spot.analytes, 'UniformOutput', false);

                    for j = 1:numel(cleaned_analytes)
                        if ismember(cleaned_analytes{j}, CAL_elements)
                            % Store directly in the matrix instead of cell array
                            initial_sensitivities(all_RM(i), j) = bg_corrected_cps_matrix.(j) / RM_table.(cleaned_analytes{j});
                        end
                    end
                end
            end

            initial_sensitivities = array2table(initial_sensitivities, "VariableNames", app.base.project.session.spot.analytes);

            all_sensitivities = [all_sensitivities, initial_sensitivities];

            %%%%%%%%%%%%%

            % Extract unique reference materials
            referenceMaterials = unique(all_sensitivities.("Reference Material"));

            % Initialize a new table to store results
            processed_sensitivities = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = all_sensitivities(strcmp(all_sensitivities.("Reference Material"), refMaterial), :);

                % Calculate min, max, and mean for each element (numeric columns only)
                numericData = rows{:, 4:end}; % Assuming element data starts from the 4th column
                minValues = min(numericData, [], 1);
                maxValues = max(numericData, [], 1);
                meanValues = mean(numericData, 1, 'omitnan');

                % Create a temporary table for the current reference material
                tempTable = table(minValues', maxValues', meanValues', ...
                    'VariableNames', {'Min', 'Max', 'Mean'});
                tempTable.Element = rows.Properties.VariableNames(4:end)'; % Add element names
                tempTable.ReferenceMaterial = repmat({refMaterial}, size(tempTable, 1), 1);

                % Append to the results table
                processed_sensitivities = [processed_sensitivities; tempTable];
            end

            % Reorder columns for better readability
            processed_sensitivities = processed_sensitivities(:, {'ReferenceMaterial', 'Element', 'Min', 'Max', 'Mean'});

            %%%%%%%%%%%%%
            % Identify rows where all numeric columns are zero
            emptyRows = all(processed_sensitivities{:, 3:end} == 0, 2);

            % Remove those rows
            processed_sensitivities = processed_sensitivities(~emptyRows, :);

            denominators = app.DenominatorListBox.Value;
            % Initialize an empty cell array to store the ratios
            ratios = {};
            elements = unique(processed_sensitivities.Element);
            % Generate ratios with the chosen denominators
            for i = 1:length(elements)
                for j = 1:length(denominators)
                    if ~strcmp(elements{i}, denominators{j}) % Avoid self-ratios
                        ratios{end+1} = [elements{i} '/' denominators{j}];
                    end
                end
            end

            app.RatioListBox.Items = ratios;
            app.RatioListBox.ItemsData = app.RatioListBox.Items;

            %%%%%%%%%%%%

            sensitivityType = app.SensitivitybasedonDropDown.Value;

            % Get the selected ratios from the RatioListBox
            selected_ratios = app.RatioListBox.Value;

            if isempty(selected_ratios)
                return
            end

            % Initialize a new table to store the calculated ratios
            calculated_ratios = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = processed_sensitivities(strcmp(processed_sensitivities.ReferenceMaterial, refMaterial), :);

                % Loop through each selected ratio
                for j = 1:length(selected_ratios)
                    % Split the ratio into numerator and denominator
                    elements = split(selected_ratios{j}, '/');
                    numerator = elements{1};
                    denominator = elements{2};

                    % Check if both elements exist in the current reference material
                    if any(strcmp(rows.Element, numerator)) && any(strcmp(rows.Element, denominator))
                        % Get the mean values for the numerator and denominator
                        numeratorValue = rows.(sensitivityType)(strcmp(rows.Element, numerator));
                        denominatorValue = rows.(sensitivityType)(strcmp(rows.Element, denominator));

                        % Calculate the ratio
                        ratioValue = numeratorValue / denominatorValue;

                        % Append the result to the calculated_ratios table
                        calculated_ratios = [calculated_ratios; table({refMaterial}, {selected_ratios{j}}, ratioValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'Value'})];
                    end
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%

            compareToMaterial = app.ComparetoDropDown.Value;

            % Filter rows for the reference material to compare to
            referenceRows = calculated_ratios(strcmp(calculated_ratios.ReferenceMaterial, compareToMaterial), :);

            % Extract the list of ratios available in the reference material
            availableRatios = referenceRows.Ratio;

            % Filter the calculated_ratios table to keep only rows with these ratios
            filtered_ratios = calculated_ratios(ismember(calculated_ratios.Ratio, availableRatios), :);

            if isempty(filtered_ratios)
                return
            end

            %%%%%%
            % Filter rows for the chosen reference material
            referenceRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, compareToMaterial), :);

            % Initialize a new table to store the normalized ratios
            app.MSF_ratios = table();

            % Loop through each reference material in the filtered_ratios table
            uniqueMaterials = unique(filtered_ratios.ReferenceMaterial);

            for i = 1:length(uniqueMaterials)
                currentMaterial = uniqueMaterials{i};

                % Skip the chosen reference material itself
                if strcmp(currentMaterial, compareToMaterial)
                    continue;
                end

                % Filter rows for the current reference material
                currentRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, currentMaterial), :);

                % Initialize a temporary table to store the ratios for this material
                tempTable = table();

                % Loop through each ratio in the current material
                for j = 1:height(currentRows)
                    currentRatio = currentRows.Ratio{j};

                    % Find the corresponding ratio in the chosen reference material
                    refRow = referenceRows(strcmp(referenceRows.Ratio, currentRatio), :);

                    if ~isempty(refRow)
                        % Calculate the normalized ratio
                        normalizedValue = currentRows.Value(j) / refRow.Value;

                        % Append the result to the temporary table
                        tempTable = [tempTable; table({currentMaterial}, {currentRatio}, normalizedValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'NormalizedValue'})];
                    end
                end

                % Append the temporary table to the app.MSF_ratios table
                app.MSF_ratios = [app.MSF_ratios; tempTable];
            end

            app.MSF_ratios = addvars(app.MSF_ratios, ...
                repmat({char(compareToMaterial)}, height(app.MSF_ratios), 1), ...
                'NewVariableNames','ComparedTo', ...
                'After','NormalizedValue');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isempty(app.MSF_ratios)
                return
            end

            % Get the unique ratios and reference materials
            uniqueRatios = unique(app.MSF_ratios.Ratio);
            uniqueMaterials = unique(app.MSF_ratios.ReferenceMaterial);

            % Prepare the figure
            cla(app.MSFAxes); % Clear the axes
            hold(app.MSFAxes, 'on'); % Hold on to plot multiple points

            % Define a colormap for different ratios
            colors = lines(length(uniqueRatios)); % Generate distinct colors for each ratio

            % Loop through each ratio and plot the points
            for i = 1:length(uniqueRatios)
                currentRatio = uniqueRatios{i};

                % Filter rows for the current ratio
                ratioRows = app.MSF_ratios(strcmp(app.MSF_ratios.Ratio, currentRatio), :);

                % Get the x and y values
                xValues = find(ismember(uniqueMaterials, ratioRows.ReferenceMaterial)); % Map materials to x-axis indices
                yValues = ratioRows.NormalizedValue;

                % Plot the points for the current ratio
                scatter(app.MSFAxes, xValues, yValues, 60, 'filled', 'MarkerFaceColor', colors(i, :), ...
                    'DisplayName', currentRatio, 'MarkerEdgeColor', 'black'); % Add legend entry
            end

            % Plot the horizontal dashed line at y = 1 (exclude from legend)
            yline(app.MSFAxes, 1, '--', 'LineWidth', 1.5, 'Color', 'k', 'HandleVisibility', 'off');

            % Set x-axis labels to the reference materials
            set(app.MSFAxes, 'XTick', 1:length(uniqueMaterials), 'XTickLabel', uniqueMaterials, 'TickLabelInterpreter', 'none');

            % Add space between the first/last x-axis entries and the plot borders
            xlim(app.MSFAxes, [0.5, length(uniqueMaterials) + 0.5]);

            % Add labels
            xlabel(app.MSFAxes, 'Reference Materials', 'Interpreter', 'none');
            ylabel(app.MSFAxes, compareToMaterial, 'Interpreter', 'none'); % Use the chosen reference material as the y-axis label

            % Show or delete the legend based on the checkbox value
            if app.ShowLegendCheckBox.Value
                legend(app.MSFAxes, 'show', 'Location', 'northeast', 'Interpreter', 'none'); % Show the legend
            else
                legend(app.MSFAxes, 'off'); % Delete the legend
            end

            % Add a grid
            grid(app.MSFAxes, 'on');

            hold(app.MSFAxes, 'off');


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)

            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.quantification = true;
                app.editstatus = true; % always set to true so that the quantification triggers at the end
                %% Att this might be bullshit and has to be revisited


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base, GUI stuff

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            if ~isfield(app.base.project.session.spot, 'internal_standard') || ...
                    isempty(app.base.project.session.spot.internal_standard)
                app.base.project.session.spot.internal_standard = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'internal_standard_mass_fraction') || ...
                    isempty(app.base.project.session.spot.internal_standard_mass_fraction)
                app.base.project.session.spot.internal_standard_mass_fraction = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'internal_standard_oxide_wt') || ...
                    isempty(app.base.project.session.spot.internal_standard_oxide_wt)
                app.base.project.session.spot.internal_standard_oxide_wt = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide') || ...
                    isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide)
                app.base.project.session.spot.Fe_ratio_for_total_oxide = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end
            
            if ~isfield(app.base.project.session.spot, 'matrix_composition')
                % Initialize cell array for storing matrix_composition tables for each CAL
                app.base.project.session.spot.matrix_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if ~isfield(app.base.project.session.spot, 'matrix_limits_of_detection')
                % Initialize cell array for storing matrix_composition tables for each CAL
                app.base.project.session.spot.matrix_limits_of_detection = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            end

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')

                if ~isfield(app.base.project.session.spot, 'internal_standard_inclusion') || ...
                        isempty(app.base.project.session.spot.internal_standard_inclusion)
                    app.base.project.session.spot.internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'internal_standard_mass_fraction_inclusion') || ...
                        isempty(app.base.project.session.spot.internal_standard_mass_fraction_inclusion)
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'second_internal_standard_inclusion') || ...
                        isempty(app.base.project.session.spot.second_internal_standard_inclusion)
                    app.base.project.session.spot.second_internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'second_internal_standard_mass_fraction_inclusion') || ...
                        isempty(app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion)
                    app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'internal_standard_oxide_wt_inclusion') || ...
                        isempty(app.base.project.session.spot.internal_standard_oxide_wt_inclusion)
                    app.base.project.session.spot.internal_standard_oxide_wt_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'Fe_ratio_for_total_oxide_inclusion') || ...
                        isempty(app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion)
                    app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                        size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'inclusion_composition')
                    % Initialize cell array for storing matrix_composition tables for each CAL
                    app.base.project.session.spot.inclusion_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

                if ~isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                    % Initialize cell array for storing matrix_composition tables for each CAL
                    app.base.project.session.spot.inclusion_limits_of_detection = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
                end

            end

            % read in the Oxide.xlsx
            if ~isfield(app.base.project.session.config, 'total_oxides_path')
                app.base.project.session.config.total_oxides_path = fullfile(app.sourceapp.installpath, 'Total_Oxide_Calculations');
            end

            app.selected_table = 'MatrixTable'; 

            % this was moved to the quantification wrapper so that the
            % oxides are loaded in whenever "Proceed to Results Module" is
            % clicked
            %get_oxides_from_file(app)

            % Change "PRM" to "CAL" & "SRM" to "QCRM"-Backwards compatibility
            app.base.project.session.spot.type(app.base.project.session.spot.type == "PRM") = "CAL";
            app.base.project.session.spot.type(app.base.project.session.spot.type == "SRM") = "QCRM";
            if isfield(app.base.project.session.spot, 'prm_sensitivities')
                % Copy data to the new field
                [app.base.project.session.spot.cal_sensitivities] = app.base.project.session.spot.prm_sensitivities;
                % Remove the old field
                app.base.project.session.spot = rmfield(app.base.project.session.spot, 'prm_sensitivities');
            end


            update_progress_buttons(app)
            update_quantification_module_GUI(app)

            %%% Relative Sensitivity Factor
            refmat = unique(app.base.project.session.spot.referencematerial);
            app.ComparetoDropDown.Items = refmat(refmat ~= "None");
            app.ComparetoDropDown.ItemsData = app.ComparetoDropDown.Items;
            app.DenominatorListBox.Items = app.base.project.session.spot.analytes;
            app.DenominatorListBox.ItemsData = app.DenominatorListBox.Items;


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            app.base.project.session.spot.internal_standard = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_mass_fraction = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.Fe_ratio_for_total_oxide = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_oxide_wt = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.matrix_composition = cell(size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));

            app.base.project.session.spot.second_internal_standard_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            app.base.project.session.spot.internal_standard_oxide_wt_inclusion = repmat({cell(size(app.base.project.session.spot.signal_starts,1),1)}, ...
                    size(app.base.project.session.spot.sensitivities,1), size(app.base.project.session.spot.sensitivities,2));
            

            update_quantification_module_GUI(app)

        end

        % Callback function: MatrixTable
        function MatrixTableSelectionChanged(app, event)
            % Update selected_spot based on spotnumber instead of cell index
            if ~isempty(app.MatrixTable.Selection)
                selectedRows = unique(app.MatrixTable.Selection(:, 1));
                app.selected_spot = app.MatrixTable.Data{selectedRows, '#'}; % Use spotnumber from the table
                app.selected_table = 'MatrixTable';

                update_quantification_module_GUI(app);
            end
        end

        % Cell edit callback: MatrixTable
        function MatrixTableCellEdit(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            indices = event.Indices;
            newData = event.NewData;

            % Validate newData
            if ischar(newData) || isstring(newData)
                if ~startsWith(lower(newData), 'from')
                    % Try to convert to number
                    try
                        newData = str2double(newData);
                        if isnan(newData)
                            newData = [];
                        end
                    catch
                        newData = [];
                    end
                end
            elseif ~isnumeric(newData)
                newData = [];
            elseif isnan(newData)
                newData = [];
            elseif isequal(newData, 0)
                newData = [];
            elseif newData < 0
                newData = [];
                % Check if it's the Fe ratio column and validate range for numeric inputs
            elseif strcmp(app.MatrixTable.ColumnName{indices(2)}, 'FeO/(FeO + Fe2O3)') && (newData < 0 || newData > 1)
                newData = [];
            elseif strcmp(app.MatrixTable.ColumnName{indices(2)}, '% Total Oxides') && (newData < 0 || newData > 100)
                newData = [];
            end


            for spot_idx = app.selected_spot'
                switch app.MatrixTable.ColumnName{indices(2)}
                    case '[µg/g]'
                        app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                    case '% Total Oxides'
                        app.base.project.session.spot.internal_standard_oxide_wt{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                    case 'FeO/(FeO + Fe2O3)'
                        app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                end
            end


            update_quantification_module_GUI(app)
        end

        % Value changed function: AnalyteListBox
        function AnalyteListBoxValueChanged(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            newValues = repmat({app.AnalyteListBox.Value}, length(app.selected_spot), 1);

            if isequal(newValues, {'=================='})
                return
            end

            if strcmp(app.selected_table, 'InclusionTable') && contains(app.selected_column, '(I.S.1)')
                app.base.project.session.spot.internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;

                % Handle changes between "Total Oxides" and other elements
                if any(contains(newValues, 'Total Oxides'))
                    % Set % Total Oxides and FeO cells to default values
                    app.base.project.session.spot.internal_standard_oxide_wt_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {100};
                    app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {1};
                    % Empty µg/g field
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};

                else
                    % Empty % Total Oxides and FeO cells
                    app.base.project.session.spot.internal_standard_oxide_wt_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};
                    app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};
                end

            elseif strcmp(app.selected_table, 'InclusionTable') && contains(app.selected_column, '(I.S.2)')
                
                 if any(~ismember(app.selected_spot, app.two_IS_rows))
                     app.fromCALListBox.Value = '==================';
                     return
                 else
                    app.base.project.session.spot.second_internal_standard_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                 end

            elseif strcmp(app.selected_table, 'MatrixTable')
                app.base.project.session.spot.internal_standard{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;

                % Handle changes between "Total Oxides" and other elements
                if any(contains(newValues, 'Total Oxides'))
                    % Set % Total Oxides and FeO cells to default values
                    app.base.project.session.spot.internal_standard_oxide_wt{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {100};
                    app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {1};
                    % Empty µg/g field
                    app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};

                else
                    % Empty % Total Oxides and FeO cells
                    app.base.project.session.spot.internal_standard_oxide_wt{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};
                    app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = {[]};

                    % Iterate through each selected spot
                    for i = 1:length(app.selected_spot)
                        % Check if the current spot type is "QCRM"
                        if strcmp(app.base.project.session.spot.type(app.selected_spot(i)), "QCRM")
                            % Get the current QCRM name
                            current_qcrm = app.base.project.session.spot.referencematerial(app.selected_spot(i));

                            % Find the index using ismember for better array handling
                            qcrm_idx = find(ismember(app.base.project.session.referencematerials.names, current_qcrm));

                            if ~isempty(qcrm_idx)
                                % Get QCRM data for the current reference material
                                qcrm_data = app.base.project.session.referencematerials.data{qcrm_idx};

                                % Clean the IS values (remove numbers)
                                cleaned_IS = cellfun(@(x) regexprep(x, '\d', ''), newValues, 'UniformOutput', false);

                                if ismember(cleaned_IS{1}, qcrm_data.Properties.VariableNames)
                                    % Update the internal standard mass fraction for the current spot
                                    app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot(i)) = {qcrm_data.(cleaned_IS{1})};
                                else
                                    app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot(i)) = {[]};
                                end
                            else
                                % Handle the case where the QCRM index is not found
                                app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot(i)) = {[]};
                            end
                        else
                            % Handle the case where the spot type is not "QCRM"
                            app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot(i)) = {[]};
                        end
                    end

                end

            end

            app.AnalyteListBox.Value = '==================';

            update_quantification_module_GUI(app)

        end

        % Value changed function: fromCALListBox
        function fromCALListBoxValueChanged(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


            newValues = repmat({app.fromCALListBox.Value}, length(app.selected_spot), 1);

             if isequal(newValues, {'=================='})
                return
             end

             if strcmp(app.selected_table, 'InclusionTable') && contains(app.selected_column, '(I.S.1)')
                app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
             elseif strcmp(app.selected_table, 'InclusionTable') && contains(app.selected_column, '(I.S.2)')

                 if any(~ismember(app.selected_spot, app.two_IS_rows))
                     app.fromCALListBox.Value = '==================';
                     return
                 else
                     app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                 end

             elseif strcmp(app.selected_table, 'MatrixTable')
                app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
            end


            app.fromCALListBox.Value = '==================';

            update_quantification_module_GUI(app)
        end

        % Value changed function: SpotnamecontainsEditField
        function SpotnamecontainsEditFieldValueChanged(app, event)
 

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            ChooseButtonPushed(app, event);

            % Validate newData
            if ischar(app.StringCompareInputEditField.Value) || isstring(app.StringCompareInputEditField.Value)
                if ~startsWith(lower(app.StringCompareInputEditField.Value), 'from')
                    % Try to convert to number
                    try
                        app.StringCompareInputEditField.Value = str2double(app.StringCompareInputEditField.Value);
                        if isnan(app.StringCompareInputEditField.Value)
                            app.StringCompareInputEditField.Valueata = [];
                        end
                    catch
                        app.StringCompareInputEditField.Value = [];
                    end
                end
            elseif ~isnumeric(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif isnan(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif app.StringCompareInputEditField.Value < 0 
                app.StringCompareInputEditField.Value = [];
                % Check if it's the Fe ratio column and validate range for numeric inputs
            elseif strcmp(app.StringCompareDropDown.Value, 'FeO/(FeO + Fe2O3)') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 1)
                return
            elseif strcmp(app.StringCompareDropDown.Value, '% Total Oxides') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 100)
                return
            end
            
            % Check if we have a valid mass fraction input
            if ~isempty(app.StringCompareInputEditField.Value) && ~isnan(app.StringCompareInputEditField.Value)

                newValues = repmat({app.StringCompareInputEditField.Value}, length(app.selected_spot), 1);

                if strcmp(app.selected_table, 'MatrixTable')

                    switch app.StringCompareDropDown.Value
                        case 'µg/g'
                            app.base.project.session.spot.internal_standard_mass_fraction{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                        case '% Total Oxides'
                            app.base.project.session.spot.internal_standard_oxide_wt{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                        case 'FeO/(FeO + Fe2O3)'
                            app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                    end

                elseif strcmp(app.selected_table, 'InclusionTable')
                    % Handle only the 'µg/g' case for InclusionTable
                    switch app.StringCompareDropDown.Value
                        case 'µg/g (I.S.1)'
                            app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                         case 'µg/g (I.S.2)'
                            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                        case '% Total Oxides'
                            app.base.project.session.spot.internal_standard_oxide_wt_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                        case 'FeO/(FeO + Fe2O3)'
                            app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{app.BracketidDropDown.Value, app.selected_CAL}(app.selected_spot) = newValues;
                    end
                end

            end

            % Update the GUI
            update_quantification_module_GUI(app);
        end

        % Button pushed function: ProceedtoResultsModuleButton
        function ProceedtoResultsModuleButtonPushed(app, event)


            if app.editstatus == true || all(all(cellfun(@isempty, app.base.project.session.spot.matrix_composition)))

                %try

                    quantification_failed = quantification_wrapper(app);

                    if quantification_failed == true
                        return
                    end

            end

            try
                app.base.project.session.config.module_status.quantification = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Yes","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        delete(app)

                    case 'Cancel'
                        return
                end
                
            catch ME
                DisplayError(app, ME,'Proceeding to Results Module failed')
                message = "Proceeding to Results Module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end


        end

        % Menu selected function: SaveSessiontoBaseandExitMenu
        function SaveSessiontoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.quantification = true;

                % uiconfirm ask user if finished
                fig = app.UIFigure;
                msg = "Do you want to save the data?";
                title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Save", "Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                switch selection
                    case 'Save'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: CalibratorDropDown
        function CalibratorDropDownValueChanged(app, event)
            app.selected_CAL = app.CalibratorDropDown.Value;
            update_quantification_module_GUI(app)
        end

        % Value changed function: BracketidDropDown
        function BracketidDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketidDropDown.Value;
            update_quantification_module_GUI(app)
        end

        % Cell edit callback: InclusionTable
        function InclusionTableCellEdit(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;
            
            indices = event.Indices;
            newData = event.NewData;

            % Validate newData
            if ischar(newData) || isstring(newData)
                if ~startsWith(lower(newData), 'from')
                    % Try to convert to number
                    try
                        newData = str2double(newData);
                        if isnan(newData)
                            newData = [];
                        end
                    catch
                        newData = [];
                    end
                end
            elseif ~isnumeric(newData)
                newData = [];
            elseif isnan(newData)
                newData = [];
            elseif isequal(newData, 0)
                newData = [];      
            end


            for spot_idx = app.selected_spot'

                switch app.InclusionTable.ColumnName{indices(2)}
                    case '[µg/g] (I.S.1)'
                        app.base.project.session.spot.internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                    case '% Total Oxides'
                        app.base.project.session.spot.internal_standard_oxide_wt_inclusion{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                    case 'FeO/(FeO + Fe2O3)'
                        app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                    case '[µg/g] (I.S.2)'
                        if ~ismember(spot_idx, app.two_IS_rows)
                            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = [];
                        else
                            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{app.BracketidDropDown.Value, app.selected_CAL}{spot_idx} = newData;
                        end
                end

            end

            update_quantification_module_GUI(app)

        end

        % Callback function: InclusionTable
        function InclusionTableSelectionChanged(app, event)
            % Update selected_spot based on spotnumber instead of cell index
            if ~isempty(app.InclusionTable.Selection)
                selectedRows = unique(app.InclusionTable.Selection(:, 1));
                app.selected_spot = app.InclusionTable.Data{selectedRows, '#'}; % Use spotnumber from the table
                app.selected_table = 'InclusionTable';

                app.selected_column = app.InclusionTable.ColumnName{unique(event.Selection(:,2))};

                update_quantification_module_GUI(app);
            end
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, ResultsButton
        function ImportButtonPushed(app, event)
            try

                app.base.project.session.config.module_status.quantification = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to jump modules?";
                    title = "ICP-Base";
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Yes","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                DisplayError(app, ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Button pushed function: ShowDwelltimesButton
        function ShowDwelltimesButtonPushed(app, event)
            
            % Create a new figure window
            fig = uifigure('Name', 'Dwell Times', 'Position', [100 100 1200 100]);

            % Create a uitable
            uit = uitable(fig);
            uit.Position = [20 20 1160 70];

            % Get the dwell times data
            dwellData = app.base.project.session.spot.dwelltimes;

            % Set the data and column names
            uit.Data = table2array(dwellData);
            uit.ColumnName = dwellData.Properties.VariableNames;

            % Make table editable
            uit.ColumnEditable = true(1, width(dwellData));

            % Add cell edit callback to validate and update values
            uit.CellEditCallback = @(src, event) updateDwellTime(app, src, event);

            % Adjust column widths
            uit.ColumnWidth = repmat({50}, 1, width(dwellData));


        end

        % Value changed function: StringCompareInputEditField
        function StringCompareInputEditFieldValueChanged(app, event)

        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)

           selected_string = app.SpotnamecontainsEditField.Value;

           % Determine the logical operator (&& or ||)
           if contains(selected_string, '&&')
               operator = 'AND';
               substrings = strsplit(selected_string, '&&');
               % Trim any whitespace around the substrings
               substrings = strtrim(substrings);
           elseif contains(selected_string, '||')
               operator = 'OR';
               substrings = strsplit(selected_string, '||');
               % Trim any whitespace around the substrings
               substrings = strtrim(substrings);
           else
               % this is the original code
               % Initialize an empty array to store the indices of the selected spots
 
               preliminary_indices = [];

               if strcmp(app.selected_table, 'MatrixTable')
                   table = app.MatrixTable;
               elseif strcmp(app.selected_table, 'InclusionTable')
                   table = app.InclusionTable;
               end

               % Iterate through the spot names
               for i = 1:length(table.Data.Spotname)
                   % Check if the spot name contains the selected string
                   if contains(table.Data.Spotname{i}, selected_string)
                       preliminary_indices(end +1) = i; %#ok
                   end
               end

               selected_spot_indices = table.Data{preliminary_indices, '#'};

               % Store the selected spot indices in the app property
               app.selected_spot = selected_spot_indices;

               return
           end

           % Initialize an empty array to store the indices of the selected spots
           selected_spot_indices = [];

           % Iterate through the spot names
           for i = 1:length(app.base.project.session.spot.name)
               % Check the condition based on the operator
               if strcmp(operator, 'AND')
                   % Check if the spot name contains all the substrings
                   condition = all(cellfun(@(s) contains(app.base.project.session.spot.name{i}, s), substrings));
               elseif strcmp(operator, 'OR')
                   % Check if the spot name contains at least one of the substrings
                   condition = any(cellfun(@(s) contains(app.base.project.session.spot.name{i}, s), substrings));
               end

               % Check the condition
               if condition
                   % If it does, add the index to the selected_spot_indices array
                   selected_spot_indices(end+1) = i; %#ok
               end
           end

           % Store the selected spot indices in the app property
           app.selected_spot = selected_spot_indices;
        end

        % Value changed function: HostInclusionDropDown
        function HostInclusionDropDownValueChanged(app, event)
            app.selected_host_inclusion = app.HostInclusionDropDown.Value;

            % Get the current table (MatrixTable or InclusionTable) based on the selected table
            if strcmp(app.selected_host_inclusion, 'Host')
                app.selected_table = 'MatrixTable';
            elseif strcmp(app.selected_host_inclusion, 'Inclusion')
                app.selected_table = 'InclusionTable';
            end

            update_quantification_module_GUI(app)
            update_inclusion_table_style(app)
        end

        % Button pushed function: ApplytoallCalibratorsButton
        function ApplytoallCalibratorsButtonPushed(app, event)
            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            % Get the current table (MatrixTable or InclusionTable) based on the selected table
            if strcmp(app.selected_table, 'MatrixTable')
                currentTable = app.MatrixTable.Data;

                % Extract spot numbers from the current table
                spotNumbers = currentTable{:, 1}; % Assuming the first column contains spot numbers

                % Get the current bracket_id and selected_cal
                currentBracketId = app.BracketidDropDown.Value;
                currentCAL = app.selected_CAL;

                % Retrieve internal standards and mass fractions (or total oxides and FeO)
                internalStandards = app.base.project.session.spot.internal_standard{currentBracketId, currentCAL}(spotNumbers);
                massFractions = app.base.project.session.spot.internal_standard_mass_fraction{currentBracketId, currentCAL}(spotNumbers);
                totalOxides = app.base.project.session.spot.internal_standard_oxide_wt{currentBracketId, currentCAL}(spotNumbers);
                feRatios = app.base.project.session.spot.Fe_ratio_for_total_oxide{currentBracketId, currentCAL}(spotNumbers);

                % Get all CALs for the same bracket_id
                allCALs = 1:size(app.base.project.session.spot.internal_standard, 2); % Assuming columns correspond to CALs
                allCALs(allCALs == currentCAL) = []; % Exclude the current CAL

                % Copy the data to all other CALs in the same bracket_id
                for cal = allCALs

                    % Find zero and nonzero rows based on sensitivities
                    sens_data = table2array(app.base.project.session.spot.sensitivities{currentBracketId, cal}(spotNumbers,:));
                    non_zero_rows = sum(sens_data, 2) ~= 0;
                    % Remove zero_rows from the table
                    valid_spotnumbers = spotNumbers(non_zero_rows);

                    possible_elements = app.base.project.session.spot.sensitivities{currentBracketId, cal}(spotNumbers,:).Properties.VariableNames;
                    possible_elements = [possible_elements, {'Total Oxides'}];
                    % Filter internal standards based on intersection with possible elements
                    filtered_internalStandards = internalStandards(non_zero_rows);
                    filtered_internalStandards(cellfun(@isempty, filtered_internalStandards)) = {''};

                    % Now you can safely use ismember
                    is_in_possible_elements = ismember(filtered_internalStandards, possible_elements);

                    % Set invalid internal standards to empty
                    internalStandards(~is_in_possible_elements) = {''};
                    massFractions(~is_in_possible_elements) = {[]};
                    totalOxides(~is_in_possible_elements) = {[]};
                    feRatios(~is_in_possible_elements) = {[]};


                    app.base.project.session.spot.internal_standard{currentBracketId, cal}(valid_spotnumbers) = internalStandards(non_zero_rows);
                    app.base.project.session.spot.internal_standard_mass_fraction{currentBracketId, cal}(valid_spotnumbers) = massFractions(non_zero_rows);
                    app.base.project.session.spot.internal_standard_oxide_wt{currentBracketId, cal}(valid_spotnumbers) = totalOxides(non_zero_rows);
                    app.base.project.session.spot.Fe_ratio_for_total_oxide{currentBracketId, cal}(valid_spotnumbers) = feRatios(non_zero_rows);
                end

            elseif strcmp(app.selected_table, 'InclusionTable')
                currentTable = app.InclusionTable.Data;

                % Extract spot numbers from the current table
                spotNumbers = currentTable{:, 1}; % Assuming the first column contains spot numbers

                % Get the current bracket_id and selected_cal
                currentBracketId = app.BracketidDropDown.Value;
                currentCAL = app.selected_CAL;

                % Retrieve internal standards and mass fractions (or total oxides and FeO)
                internalStandards = app.base.project.session.spot.internal_standard_inclusion{currentBracketId, currentCAL}(spotNumbers);
                massFractions = app.base.project.session.spot.internal_standard_mass_fraction_inclusion{currentBracketId, currentCAL}(spotNumbers);
                totalOxides = app.base.project.session.spot.internal_standard_oxide_wt_inclusion{currentBracketId, currentCAL}(spotNumbers);
                feRatios = app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{currentBracketId, currentCAL}(spotNumbers);

                second_internalStandards = app.base.project.session.spot.second_internal_standard_inclusion{currentBracketId, currentCAL}(spotNumbers);
                second_massFractions = app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{currentBracketId, currentCAL}(spotNumbers);

                % Get all CALs for the same bracket_id
                allCALs = 1:size(app.base.project.session.spot.internal_standard_inclusion, 2); % Assuming columns correspond to CALs
                allCALs(allCALs == currentCAL) = []; % Exclude the current CAL

                % Copy the data to all other CALs in the same bracket_id
                for cal = allCALs

                    % Find zero and nonzero rows based on sensitivities
                    sens_data = table2array(app.base.project.session.spot.sensitivities{currentBracketId, cal}(spotNumbers,:));
                    non_zero_rows = sum(sens_data, 2) ~= 0;
                    % Remove zero_rows from the table
                    valid_spotnumbers = spotNumbers(non_zero_rows);

                    possible_elements = app.base.project.session.spot.sensitivities{currentBracketId, cal}(spotNumbers,:).Properties.VariableNames;
                    possible_elements = [possible_elements, {'Total Oxides'}];
                    % Filter internal standards based on intersection with possible elements
                    filtered_internalStandards = internalStandards(non_zero_rows);
                    filtered_internalStandards(cellfun(@isempty, filtered_internalStandards)) = {''};

                    % Now you can safely use ismember
                    is_in_possible_elements = ismember(filtered_internalStandards, possible_elements);

                    % Set invalid internal standards to empty
                    internalStandards(~is_in_possible_elements) = {''};
                    massFractions(~is_in_possible_elements) = {[]};
                    totalOxides(~is_in_possible_elements) = {[]};
                    feRatios(~is_in_possible_elements) = {[]};
                    second_internalStandards(~is_in_possible_elements) = {''};
                    second_massFractions(~is_in_possible_elements) = {[]};

                    app.base.project.session.spot.internal_standard_inclusion{currentBracketId, cal}(valid_spotnumbers) = internalStandards(non_zero_rows);
                    app.base.project.session.spot.internal_standard_mass_fraction_inclusion{currentBracketId, cal}(valid_spotnumbers) = massFractions(non_zero_rows);
                    app.base.project.session.spot.internal_standard_oxide_wt_inclusion{currentBracketId, cal}(valid_spotnumbers) = totalOxides(non_zero_rows);
                    app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{currentBracketId, cal}(valid_spotnumbers) = feRatios(non_zero_rows);
                    app.base.project.session.spot.second_internal_standard_inclusion{currentBracketId, cal}(valid_spotnumbers) = second_internalStandards(non_zero_rows);
                    app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{currentBracketId, cal}(valid_spotnumbers) = second_massFractions(non_zero_rows);
                end

            else
                return
            end

            update_quantification_module_GUI(app)
        end

        % Value changed function: ComparetoDropDown
        function ComparetoDropDownValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: SensitivitybasedonDropDown
        function SensitivitybasedonDropDownValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: DenominatorListBox
        function DenominatorListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: RatioListBox
        function RatioListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            
            if isempty(app.MSF_ratios)
                message={'No Data to be exported'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end


            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            writetable(app.MSF_ratios, outputpath, 'Sheet', 'MSF ratios', 'WriteRowNames', false);

        end

        % Button pushed function: ExportTableButton_2
        function ExportTableButton_2Pushed(app, event)

            fig = app.UIFigure;
            msg = {['The currently active table will be exported. Make sure that the table already contains the correct internal standard analytes. ' ...
                'If you want to import it back again, make sure to not alter the first three columns of the table. Only fill in the values for your internal standards or total oxides. Under no circumstances delete a row or change the name of the sheet. If you do not want to quantify a spot, just leave the internal standard empty.']};
            title = "ICP-Base";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["I understand and if this does not work it is my own fault","Cancel"], ...
                "DefaultOption",1,"CancelOption",2);

            switch selection
                case "I understand and if this does not work it is my own fault"

                    [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
                    outputpath = fullfile(pathname, filename);
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return; % User canceled file selection
                    end

                    if isequal(app.selected_host_inclusion, 'Host')

                        writetable(app.MatrixTable.Data, outputpath, 'Sheet', ['Matrix_', num2str(app.selected_CAL), '_', app.CalibratorDropDown.Items{app.selected_CAL}, '_ID_' ,app.BracketidDropDown.Items{app.selected_bracket_id}]);

                    elseif isequal(app.selected_host_inclusion, 'Inclusion')

                        writetable(app.InclusionTable.Data, outputpath, 'Sheet', ['Inclusion_',num2str(app.selected_CAL), '_', app.CalibratorDropDown.Items{app.selected_CAL}, '_ID_' ,app.BracketidDropDown.Items{app.selected_bracket_id}]);

                    end

                case 'Cancel'
                    return
            end


        end

        % Button pushed function: ImportTableButton
        function ImportTableButtonPushed(app, event)


            fig = app.UIFigure;
            msg = {'Be aware that you can (or rather should) only import tables that were also exported from ICP-Base. You cannot just import a table of any format.'};
            title = "ICP-Base";
            selection = uiconfirm(fig,msg,title, ...
                "Options",["I understand and if this does not work it is my own fault","Cancel"], ...
                "DefaultOption",1,"CancelOption",2);

            switch selection
                case "I understand and if this does not work it is my own fault"


                    [filename, pathname] = uigetfile('*.xlsx', 'Save Excel file');
                    inputpath = fullfile(pathname, filename);
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return; % User canceled file selection
                    end

                    % Read the sheet names
                    sheetNames = readtable(inputpath, 'VariableNamingRule', 'preserve', 'Sheet', 1);

                    % Get the actual sheet names using sheetnames function
                    sheets = sheetnames(inputpath);
                    activeSheet = sheets(1); % Get the first sheet name

                    % Parse the sheet name to extract variables
                    % Expected format: 'Matrix_CAL_Name_ID_BracketId' or 'Inclusion_CAL_Name_ID_BracketId'
                    parts = split(activeSheet, '_');

                    % Extract host/inclusion type
                    host_inclusion = parts{1}; % 'Matrix' or 'Inclusion'

                    % Extract CAL index (2nd part after split)
                    CAL = str2double(parts{2});

                    % Extract bracket ID (last part)
                    bracket_id = str2double(parts{end});

                    % Now read the data
                    data = readtable(inputpath, 'Sheet', activeSheet);

                    %Assign to the appropriate table based on host/inclusion
                    if isequal(host_inclusion, 'Matrix')

                        %%%%% check for invalid entries %%%%%

                        % Update GUI to load the correct table
                        app.CalibratorDropDown.Value = CAL;
                        app.BracketidDropDown.Value = bracket_id;
                        app.HostInclusionDropDown.Value = 'Host';
                        update_matrix_table(app)

                        % Compare first two columns (spot numbers and spotnames)
                        current_spot_numbers = app.MatrixTable.Data{:, 1};
                        current_spotnames = app.MatrixTable.Data{:, 2};
                        imported_spot_numbers = data{:, 1};
                        imported_spotnames = data{:, 2};

                        % Check if they match
                        if ~isequal(current_spot_numbers, imported_spot_numbers) || ~isequal(current_spotnames, imported_spotnames)
                            uialert(app.UIFigure, ...
                                'The spot numbers or spot names in the imported file do not match the variables in the data structure. Please ensure the file has not been altered.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end


                        % Check for conflicting data: column 4 should not have values if column 5 or 6 have values
                        col4 = data{:, 4};
                        col5 = data{:, 5};
                        col6 = data{:, 6};

                        has_col4 = ~isnan(col4);
                        has_col5 = ~isnan(col5);
                        has_col6 = ~isnan(col6);

                        % Check if any row has both mass fraction (col 4) and total oxides (col 5 or 6)
                        if any(has_col4 & (has_col5 | has_col6))
                            uialert(app.UIFigure, ...
                                'Invalid data: A spot cannot have both a mass fraction value and total oxide values. Please use either mass fraction OR total oxides, not both.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        % Validate total oxides range (0 < value <= 100)
                        if any(has_col5 & (col5 <= 0 | col5 > 100))
                            uialert(app.UIFigure, ...
                                'Invalid data: Total Oxides values must be greater than 0 and less than or equal to 100.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        % Validate Fe ratio range (0 <= value <= 1)
                        if any(has_col6 & (col6 < 0 | col6 > 1))
                            uialert(app.UIFigure, ...
                                'Invalid data: FeO/(FeO + Fe2O3) values must be between 0 and 1 (inclusive).', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        %%% lets go %%%

                        % Extract spot numbers (column 1) and values from columns 3, 4, 5, 6
                        spot_numbers = data{:, 1};
                        internal_standard = data{:, 3};  % Column 3: Analyte
                        internal_standard_values = data{:, 4};  % Column 4: x__g_g_
                        total_oxides = data{:, 5};  % Column 5: x_TotalOxides
                        fe_ratio = data{:, 6};  % Column 6: FeO__FeO_Fe2O3_


                        % Process internal_standard (column 3) - cell array of strings
                        app.base.project.session.spot.internal_standard{bracket_id, CAL}(spot_numbers) = internal_standard;

                        % Process internal_standard_mass_fraction (column 4)
                        values_cell = num2cell(internal_standard_values);
                        nan_mask = isnan(internal_standard_values);
                        values_cell(nan_mask) = {[]};
                        app.base.project.session.spot.internal_standard_mass_fraction{bracket_id, CAL}(spot_numbers) = values_cell;

                        % Process internal_standard_oxide_wt (column 5)
                        oxide_cell = num2cell(total_oxides);
                        nan_mask = isnan(total_oxides);
                        oxide_cell(nan_mask) = {[]};
                        app.base.project.session.spot.internal_standard_oxide_wt{bracket_id, CAL}(spot_numbers) = oxide_cell;

                        % Process Fe_ratio_for_total_oxide (column 6)
                        fe_cell = num2cell(fe_ratio);
                        nan_mask = isnan(fe_ratio);
                        fe_cell(nan_mask) = {[]};
                        app.base.project.session.spot.Fe_ratio_for_total_oxide{bracket_id, CAL}(spot_numbers) = fe_cell;

                    elseif isequal(host_inclusion, 'Inclusion')

                        %%%%% check for invalid entries %%%%%

                        % Update GUI to load the correct table
                        app.CalibratorDropDown.Value = CAL;
                        app.BracketidDropDown.Value = bracket_id;
                        app.HostInclusionDropDown.Value = 'Inclusion';
                        update_inclusion_table(app)

                        % Compare first two columns (spot numbers and spotnames)
                        current_spot_numbers = app.InclusionTable.Data{:, 1};
                        current_spotnames = app.InclusionTable.Data{:, 2};
                        imported_spot_numbers = data{:, 1};
                        imported_spotnames = data{:, 2};

                        % Check if they match
                        if ~isequal(current_spot_numbers, imported_spot_numbers) || ~isequal(current_spotnames, imported_spotnames)
                            uialert(app.UIFigure, ...
                                'The spot numbers or spot names in the imported file do not match the variables in the data structure. Please ensure the file has not been altered.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end


                        % Check for conflicting data: column 4 should not have values if column 5 or 6 have values
                        col4 = data{:, 4};
                        col5 = data{:, 5};
                        col6 = data{:, 6};

                        has_col4 = ~isnan(col4);
                        has_col5 = ~isnan(col5);
                        has_col6 = ~isnan(col6);

                        % Check if any row has both mass fraction (col 4) and total oxides (col 5 or 6)
                        if any(has_col4 & (has_col5 | has_col6))
                            uialert(app.UIFigure, ...
                                'Invalid data: A spot cannot have both a mass fraction value and total oxide values. Please use either mass fraction OR total oxides, not both.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        % Validate total oxides range (0 < value <= 100)
                        if any(has_col5 & (col5 <= 0 | col5 > 100))
                            uialert(app.UIFigure, ...
                                'Invalid data: Total Oxides values must be greater than 0 and less than or equal to 100.', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        % Validate Fe ratio range (0 <= value <= 1)
                        if any(has_col6 & (col6 < 0 | col6 > 1))
                            uialert(app.UIFigure, ...
                                'Invalid data: FeO/(FeO + Fe2O3) values must be between 0 and 1 (inclusive).', ...
                                'Import Error', 'Icon', 'error');
                            return;
                        end

                        %%% lets go %%%

                        % Extract spot numbers (column 1) and values from columns 3, 4, 5, 6
                        spot_numbers = data{:, 1};
                        internal_standard = data{:, 3};  % Column 3: Analyte
                        internal_standard_values = data{:, 4};  % Column 4: x__g_g_
                        total_oxides = data{:, 5};  % Column 5: x_TotalOxides
                        fe_ratio = data{:, 6};  % Column 6: FeO__FeO_Fe2O3_

                        % Process internal_standard (column 3) - cell array of strings
                        app.base.project.session.spot.internal_standard_inclusion{bracket_id, CAL}(spot_numbers) = internal_standard;

                        % Process internal_standard_mass_fraction (column 4)
                        values_cell = num2cell(internal_standard_values);
                        nan_mask = isnan(internal_standard_values);
                        values_cell(nan_mask) = {[]};
                        app.base.project.session.spot.internal_standard_mass_fraction_inclusion{bracket_id, CAL}(spot_numbers) = values_cell;

                        % Process internal_standard_oxide_wt (column 5)
                        oxide_cell = num2cell(total_oxides);
                        nan_mask = isnan(total_oxides);
                        oxide_cell(nan_mask) = {[]};
                        app.base.project.session.spot.internal_standard_oxide_wt_inclusion{bracket_id, CAL}(spot_numbers) = oxide_cell;

                        % Process Fe_ratio_for_total_oxide (column 6)
                        fe_cell = num2cell(fe_ratio);
                        nan_mask = isnan(fe_ratio);
                        fe_cell(nan_mask) = {[]};
                        app.base.project.session.spot.Fe_ratio_for_total_oxide_inclusion{bracket_id, CAL}(spot_numbers) = fe_cell;

                        % process second internal standard.
                        second_internal_standard = data{:, 7};  % Column 7: Analyte
                        second_internal_standard_values = data{:, 8};  % Column 8: x__g_g_

                        % Get the unmixing method for these spots
                        unmixing_methods = app.base.project.session.spot.unmixing_via(spot_numbers);

                        % Find indices where unmixing method is 'Two internal standards'
                        two_std_mask = strcmp(unmixing_methods, 'Two internal standards');

                        % Only process spots that use two internal standards
                        spots_with_two_std = spot_numbers(two_std_mask);

                        if ~isempty(spots_with_two_std)
                            % Convert internal_standard to cell array if it's numeric (handles NaN case)
                            if isnumeric(second_internal_standard)
                                second_internal_standard_cell = cell(size(second_internal_standard));
                                for i = 1:length(second_internal_standard)
                                    if isnan(second_internal_standard(i))
                                        second_internal_standard_cell{i} = '';
                                    else
                                        second_internal_standard_cell{i} = num2str(second_internal_standard(i));
                                    end
                                end
                                second_internal_standard = second_internal_standard_cell;
                            end

                            % Process second_internal_standard (column 7) - only for 'Two internal standards' spots
                            app.base.project.session.spot.second_internal_standard_inclusion{bracket_id, CAL}(spots_with_two_std) = second_internal_standard(two_std_mask);

                            % Process second_internal_standard_mass_fraction (column 8)
                            values_to_assign = second_internal_standard_values(two_std_mask);
                            values_cell = num2cell(values_to_assign);
                            nan_mask = isnan(values_to_assign);
                            values_cell(nan_mask) = {[]};
                            app.base.project.session.spot.second_internal_standard_mass_fraction_inclusion{bracket_id, CAL}(spots_with_two_std) = values_cell;
                        end
                    end

                    update_quantification_module_GUI(app)

                case 'Cancel'
                    return
            end
        end

        % Menu selected function: ChangeTotalOxidesFolderMenu
        function ChangeTotalOxidesFolderMenuSelected(app, event)
            % Open folder selection dialog
            selectedFolder = uigetdir('', 'Select Total Oxides Folder');

            % Check if user selected a folder (not cancelled)
            if selectedFolder == 0
                return
            end

            % Store the selected folder path (adjust property name as needed)
            app.base.project.session.config.total_oxides_path = selectedFolder;

            % Call your existing function
            get_oxides_from_file(app)

            update_quantification_module_GUI(app)
        end

        % Menu selected function: ShowOxidesthatwillbeusedMenu
        function ShowOxidesthatwillbeusedMenuSelected(app, event)

            changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;

            message = "If you want to change the oxides that should be used for quantification and you use a compiled version you have to change the Total Oxide Folder. The compiled version has the original Oxides.xlsx (found in ProgramFiles/ICP_Base/application/Total_Oxide_Calculations) locked in. Even if you change the values in ProgramFiles/ICP_Base/application/Total_Oxide_Calculations/Oxides.xlsx it will not actually change it in the software. You can copy this original Total_Oxide_Calculations folder, store it somewhere that is not the ProgramFiles and then you can change anything you want.";
            uialert(app.UIFigure,message,"Warning", ...
                "Icon","warning");

            get_oxides_from_file(app)

            % Create a new figure for the table.
            fig = uifigure('Name', 'Oxides to be used for quantification -  You can change them in the oxides folder', 'Position', [200, 200, 800, 600]);

            % Create a uitable in the new figure.
            uit = uitable(fig);

            % Extract the data from the app's properties.
            oxides_data = app.base.project.session.oxides;
            element_names = oxides_data.element_names;
            oxide_names = oxides_data.oxide_names;

            % The conversion_factors is a Map, so we extract the values.
            % The order of values corresponds to the order of keys, which should match the element_names.
            conv_factors_map = oxides_data.conversion_factors;
            conv_factors_values = values(conv_factors_map, element_names);

            preferred_isotopes = oxides_data.preferred_isotopes;
            considered_major = oxides_data.considered_major;

            % Create a MATLAB table to hold the data.
            tabledata = table(element_names, oxide_names, conv_factors_values, preferred_isotopes, considered_major, ...
                'VariableNames', {'Element', 'Oxide', 'ConversionFactor', 'PreferredIsotope', 'IsMajor'});

            % Assign the data to the uitable.
            uit.Data = tabledata;

            % Set the position and size of the table to fill the figure.
            uit.Position = [20 20 760 560];

            % Make the table non-editable.
            uit.ColumnEditable = false;

            % Center the text in all columns.
            style = uistyle('HorizontalAlignment', 'center');
            addStyle(uit, style);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SaveSessiontoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SaveSessiontoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSessiontoBaseandExitMenuSelected, true);
            app.SaveSessiontoBaseandExitMenu.Text = 'Save Session to Base and Exit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ChangeTotalOxidesFolderMenu = uimenu(app.SettingsMenu);
            app.ChangeTotalOxidesFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTotalOxidesFolderMenuSelected, true);
            app.ChangeTotalOxidesFolderMenu.Text = 'Change Total Oxides Folder';
            app.ShowOxidesthatwillbeusedMenu = uimenu(app.SettingsMenu);
            app.ShowOxidesthatwillbeusedMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowOxidesthatwillbeusedMenuSelected, true);
            app.ShowOxidesthatwillbeusedMenu.Text = 'Show Oxides that will be used';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.InternalStandardizationTab = uitab(app.TabGroup);
            app.InternalStandardizationTab.Title = 'Internal Standardization';
            app.ApplytoallCalibratorsButton = uibutton(app.InternalStandardizationTab, 'push');
            app.ApplytoallCalibratorsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoallCalibratorsButtonPushed, true);
            app.ApplytoallCalibratorsButton.Tooltip = {'I hate this button'};
            app.ApplytoallCalibratorsButton.Text = 'Apply to all Calibrators';
            app.ResetButton = uibutton(app.InternalStandardizationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShowDwelltimesButton = uibutton(app.InternalStandardizationTab, 'push');
            app.ShowDwelltimesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDwelltimesButtonPushed, true);
            app.ShowDwelltimesButton.Text = 'Show Dwelltimes';
            app.Panel = uipanel(app.InternalStandardizationTab);
            app.GridLayout = uigridlayout(app.Panel);
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StringCompareInputEditField = uieditfield(app.GridLayout, 'numeric');
            app.StringCompareInputEditField.Limits = [0 Inf];
            app.StringCompareInputEditField.ValueChangedFcn = createCallbackFcn(app, @StringCompareInputEditFieldValueChanged, true);
            app.StringCompareInputEditField.Value = 42;
            app.StringCompareDropDown = uidropdown(app.GridLayout);
            app.StringCompareDropDown.Items = {''};
            app.StringCompareDropDown.Value = '';
            app.ChooseButton = uibutton(app.GridLayout, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.ContainsLabel = uilabel(app.GridLayout);
            app.ContainsLabel.Text = 'Spotname contains:';
            app.SpotnamecontainsEditField = uieditfield(app.GridLayout, 'text');
            app.SpotnamecontainsEditField.ValueChangedFcn = createCallbackFcn(app, @SpotnamecontainsEditFieldValueChanged, true);
            app.AnalyteListBox = uilistbox(app.InternalStandardizationTab);
            app.AnalyteListBox.Items = {};
            app.AnalyteListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyteListBoxValueChanged, true);
            app.AnalyteListBox.Visible = 'off';
            app.AnalyteListBox.Value = {};
            app.fromCALListBox = uilistbox(app.InternalStandardizationTab);
            app.fromCALListBox.Items = {};
            app.fromCALListBox.ValueChangedFcn = createCallbackFcn(app, @fromCALListBoxValueChanged, true);
            app.fromCALListBox.Visible = 'off';
            app.fromCALListBox.Value = {};
            app.MatrixTable = uitable(app.InternalStandardizationTab);
            app.MatrixTable.ColumnName = '';
            app.MatrixTable.RowName = {};
            app.MatrixTable.ColumnEditable = [false false false true true true];
            app.MatrixTable.CellEditCallback = createCallbackFcn(app, @MatrixTableCellEdit, true);
            app.MatrixTable.SelectionChangedFcn = createCallbackFcn(app, @MatrixTableSelectionChanged, true);
            app.InclusionTable = uitable(app.InternalStandardizationTab);
            app.InclusionTable.ColumnName = '';
            app.InclusionTable.RowName = {};
            app.InclusionTable.ColumnEditable = [false false false true true true false true];
            app.InclusionTable.CellEditCallback = createCallbackFcn(app, @InclusionTableCellEdit, true);
            app.InclusionTable.SelectionChangedFcn = createCallbackFcn(app, @InclusionTableSelectionChanged, true);
            app.InclusionTable.Visible = 'off';
            app.HostInclusionDropDown = uidropdown(app.InternalStandardizationTab);
            app.HostInclusionDropDown.Items = {};
            app.HostInclusionDropDown.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDownValueChanged, true);
            app.HostInclusionDropDown.Value = {};
            app.BracketidDropDownLabel = uilabel(app.InternalStandardizationTab);
            app.BracketidDropDownLabel.Text = 'Bracket id';
            app.BracketidDropDown = uidropdown(app.InternalStandardizationTab);
            app.BracketidDropDown.Items = {};
            app.BracketidDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketidDropDownValueChanged, true);
            app.BracketidDropDown.Value = {};
            app.CalibratorDropDownLabel = uilabel(app.InternalStandardizationTab);
            app.CalibratorDropDownLabel.Text = 'Calibrator';
            app.CalibratorDropDown = uidropdown(app.InternalStandardizationTab);
            app.CalibratorDropDown.Items = {};
            app.CalibratorDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibratorDropDownValueChanged, true);
            app.CalibratorDropDown.Value = {};
            app.ProceedtoResultsModuleButton = uibutton(app.InternalStandardizationTab, 'push');
            app.ProceedtoResultsModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoResultsModuleButtonPushed, true);
            app.ProceedtoResultsModuleButton.Text = 'Proceed to Results Module';
            app.ExportTableButton_2 = uibutton(app.InternalStandardizationTab, 'push');
            app.ExportTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButton_2Pushed, true);
            app.ExportTableButton_2.Text = 'Export Table';
            app.ImportTableButton = uibutton(app.InternalStandardizationTab, 'push');
            app.ImportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTableButtonPushed, true);
            app.ImportTableButton.Text = 'Import Table';
            app.MatrixSensitivityFactorMSFTab = uitab(app.TabGroup);
            app.MatrixSensitivityFactorMSFTab.Title = 'Matrix Sensitivity Factor (MSF)';
            app.MSFAxes = uiaxes(app.MatrixSensitivityFactorMSFTab);
            app.RatioListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.RatioListBox.Items = {};
            app.RatioListBox.Multiselect = 'on';
            app.RatioListBox.ValueChangedFcn = createCallbackFcn(app, @RatioListBoxValueChanged, true);
            app.RatioListBox.Value = {};
            app.DenominatorListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.DenominatorListBox.Items = {};
            app.DenominatorListBox.Multiselect = 'on';
            app.DenominatorListBox.ValueChangedFcn = createCallbackFcn(app, @DenominatorListBoxValueChanged, true);
            app.DenominatorListBox.Value = {};
            app.ShowLegendCheckBox = uicheckbox(app.MatrixSensitivityFactorMSFTab);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.SensitivitybasedonDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDownLabel.Text = 'Sensitivity based on:';
            app.SensitivitybasedonDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDown.Items = {'Mean', 'Min', 'Max'};
            app.SensitivitybasedonDropDown.ValueChangedFcn = createCallbackFcn(app, @SensitivitybasedonDropDownValueChanged, true);
            app.SensitivitybasedonDropDown.Value = 'Mean';
            app.ComparetoDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDownLabel.Text = 'Compare to:';
            app.ComparetoDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDown.Items = {'', ''};
            app.ComparetoDropDown.ValueChangedFcn = createCallbackFcn(app, @ComparetoDropDownValueChanged, true);
            app.ComparetoDropDown.Value = '';
            app.ExportTableButton = uibutton(app.MatrixSensitivityFactorMSFTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.ProgressPanel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.ProgressPanel);
            app.IntervalButton = uibutton(app.GridLayout3, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.GridLayout3, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.GridLayout3, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.GridLayout3, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.GridLayout3, 'push');
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.GridLayout3, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ResultsButton.Text = 'Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quantification_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end