classdef Results_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        SavetoBaseandExitMenu           matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        AdjustSRMAxisFontsizeMenu       matlab.ui.container.Menu
        AdjustSRMAxisMarkersizeMenu     matlab.ui.container.Menu
        ChangeMetadataFolderMenu        matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ResultsTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ShowCommentsCheckBox            matlab.ui.control.CheckBox
        EnddataprocessingButton         matlab.ui.control.Button
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        BracketIdDropDown               matlab.ui.control.DropDown
        BracketIdDropDownLabel          matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDownLabel  matlab.ui.control.Label
        CreateOutputTableButton         matlab.ui.control.Button
        HostInclusionDropDown           matlab.ui.control.DropDown
        ResultsTable                    matlab.ui.control.Table
        AddMetadataTab                  matlab.ui.container.Tab
        ResetButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        MetadataInputEditField          matlab.ui.control.EditField
        SpotnamecontainsEditField       matlab.ui.control.EditField
        SpotnamecontainsEditFieldLabel  matlab.ui.control.Label
        StringCompareDropDown           matlab.ui.control.DropDown
        ApplyButton                     matlab.ui.control.Button
        GetmetadatafromabbreviationsheetsButton  matlab.ui.control.Button
        MetadataTable                   matlab.ui.control.Table
        QualityControlTab               matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SecondaryReferenceMaterialTab   matlab.ui.container.Tab
        LogscaleCheckBox_2              matlab.ui.control.CheckBox
        SecondaryReferenceMaterialDropDown  matlab.ui.control.DropDown
        SecondaryReferenceMaterialDropDownLabel  matlab.ui.control.Label
        BracketIdDropDown_2             matlab.ui.control.DropDown
        BracketIdDropDown_2Label        matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown_2  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDown_2Label  matlab.ui.control.Label
        GotoCorrectionModuleButton      matlab.ui.control.Button
        SRMAxes                         matlab.ui.control.UIAxes
        MatrixSensitivityFactorMSFTab   matlab.ui.container.Tab
        ExportTableButton               matlab.ui.control.Button
        ShowLegendCheckBox              matlab.ui.control.CheckBox
        DenominatorListBox              matlab.ui.control.ListBox
        ComparetoDropDown               matlab.ui.control.DropDown
        ComparetoDropDownLabel          matlab.ui.control.Label
        RatioListBox                    matlab.ui.control.ListBox
        SensitivitybasedonDropDown      matlab.ui.control.DropDown
        SensitivitybasedonDropDownLabel  matlab.ui.control.Label
        MSFAxes                         matlab.ui.control.UIAxes
        SessionDriftTab                 matlab.ui.container.Tab
        LogscaleCheckBox                matlab.ui.control.CheckBox
        ElementLegendListBox            matlab.ui.control.ListBox
        SampleDropDown                  matlab.ui.control.DropDown
        SampleDropDownLabel             matlab.ui.control.Label
        BracketIdDropDown_3             matlab.ui.control.DropDown
        BracketIdDropDown_3Label        matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown_3  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDown_3Label  matlab.ui.control.Label
        DriftAxes                       matlab.ui.control.UIAxes
        ProgressPanel                   matlab.ui.container.Panel
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        IntervalButton                  matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        RefreshMenu                     matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        OutputallmatrixmassfractionsggMenu  matlab.ui.container.Menu
        OutputallinclusionmassfractionsggMenu  matlab.ui.container.Menu
        OutputallmatrixLODsggMenu       matlab.ui.container.Menu
        OutputallinclusionLODsggMenu    matlab.ui.container.Menu
    end

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp
        selected_PRM
        selected_bracket_id
        selected_host_inclusion
        selected_SRM
        selected_analytes

        MSF_ratios

        folderPath

        editstatus = false
    end


    methods (Access = public)


        function update_progress_buttons(app)

            status = app.base.project.session.config.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            app.ResultsButton.Enable = "on";
            app.ResultsButton.BackgroundColor = orange;
            app.ResultsButton.FontWeight = 'bold';

        end

        % Custom function to center the figure
        function center_figure(app, hfig)
            try
                screenSize = get(0,'ScreenSize');
                figPos = hfig.Position;
                figWidth = figPos(3);
                figHeight = figPos(4);
                newX = (screenSize(3) - figWidth) / 2;
                newY = (screenSize(4) - figHeight) / 2;
                hfig.Position=[newX, newY, figWidth, figHeight];
                figure(hfig)
            catch
                ME
                disp('CenterFig_fun failed!')
                disp(ME.message)
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_results_module_GUI(app)

            % % Get unique reference materials
            % uniquePRMs = unique(app.base.project.session.spot.referencematerial);
            % validPRMs = uniquePRMs(strcmp(uniquePRMs, 'PRM'));
            %
            % % Populate PRM dropdown
            % app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            % app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

            % Get indices where type is "PRM"
            prm_indices = strcmp(app.base.project.session.spot.type, "PRM");
            % Get reference materials only for PRM spots
            prm_materials = app.base.project.session.spot.referencematerial(prm_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(prm_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validPRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate PRM dropdown
            app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validPRMs(app.PrimaryReferenceMaterialDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketIdDropDown.Items = string(uniqueBracketIds);
            app.BracketIdDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_PRM is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_PRM)
                app.selected_PRM = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketIdDropDown.Value = uniqueBracketIds(1);
            end

            if isequal(app.base.project.session.config.reductiontype, 'Inclusion')
                app.HostInclusionDropDown.Items = ["Host", "Inclusion"];
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            elseif isequal(app.base.project.session.config.reductiontype, 'Matrix')
                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';
                app.HostInclusionDropDown.Items = "Host";
                app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
            end

            if isempty(app.selected_host_inclusion)
                app.selected_host_inclusion = app.HostInclusionDropDown.ItemsData{1};
            end

            app.BracketIdDropDown.Visible = 'on';
            app.BracketIdDropDown.Enable = 'on';
            app.BracketIdDropDownLabel.Visible = 'on';
            app.PrimaryReferenceMaterialDropDown.Visible = 'on';
            app.PrimaryReferenceMaterialDropDown.Enable = 'on';
            app.PrimaryReferenceMaterialDropDownLabel.Visible = 'on';

            if isequal(app.ShowDropDown.Value,'CPS')

                app.HostInclusionDropDown.Visible = 'on';
                app.HostInclusionDropDown.Enable = 'on';
                app.BracketIdDropDown.Visible = 'off';
                app.BracketIdDropDown.Enable = 'off';
                app.BracketIdDropDownLabel.Visible = 'off';
                app.PrimaryReferenceMaterialDropDown.Visible = 'off';
                app.PrimaryReferenceMaterialDropDown.Enable = 'off';
                app.PrimaryReferenceMaterialDropDownLabel.Visible = 'off';

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';

            end


            %%%%%%%%%%%%%%%%%%%%
            update_results_table(app)
            update_progress_buttons(app)

            %%%%%%%%%%%%%%%%%%%%%%%%%% Quality Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%% Secondary Standard Plots
            srm_indices = strcmp(app.base.project.session.spot.type, "SRM");

            % populate the PRM and bracket_id dropdown
            app.PrimaryReferenceMaterialDropDown_2.Items = app.PrimaryReferenceMaterialDropDown.Items;
            app.PrimaryReferenceMaterialDropDown_2.ItemsData = app.PrimaryReferenceMaterialDropDown.ItemsData;
            app.BracketIdDropDown_2.Items = string(uniqueBracketIds);
            app.BracketIdDropDown_2.ItemsData = uniqueBracketIds;

            if any(srm_indices)
                % Get reference materials only for SRM spots
                srm_materials = app.base.project.session.spot.referencematerial(srm_indices);
                % Get unique names, excluding "None" and "Select Reference Material"
                unique_names = unique(srm_materials);
                unique_names = unique_names(~strcmp(unique_names, 'None'));
                validSRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

                % Populate SRM dropdown
                app.SecondaryReferenceMaterialDropDown.Items = validSRMs;
                app.SecondaryReferenceMaterialDropDown.ItemsData = app.SecondaryReferenceMaterialDropDown.Items;

                update_srm_plot(app)
            else
                app.SecondaryReferenceMaterialDropDown.Visible = false;
            end

            %%% Drift of quantified unknowns plot

            % populate the elementlistbox
            app.ElementLegendListBox.Items = ['All'; app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM}.Properties.VariableNames'];
            app.ElementLegendListBox.ItemsData = 0:numel(app.ElementLegendListBox.Items)-1;

            % populate the PRM and bracket_id dropdown
            app.PrimaryReferenceMaterialDropDown_3.Items = app.PrimaryReferenceMaterialDropDown.Items;
            app.PrimaryReferenceMaterialDropDown_3.ItemsData = app.PrimaryReferenceMaterialDropDown.ItemsData;
            app.BracketIdDropDown_3.Items = string(uniqueBracketIds);
            app.BracketIdDropDown_3.ItemsData = uniqueBracketIds;

            unk_indices = ~strcmp(app.base.project.session.spot.type, "PRM");
            if  isfield(app.base.project.session, 'meta') && isfield(app.base.project.session.meta, 'sample')

                unique_samples = unique(app.base.project.session.meta.sample(unk_indices));
                unique_samples = unique_samples(~strcmp(unique_samples, 'Unknown'));

                app.SampleDropDown.Items = unique_samples;
                app.SampleDropDown.ItemsData = app.SampleDropDown.Items;

                %update_drift_plot(app)
            end

            %%% Relative Sensitivity Factor
            refmat = unique(app.base.project.session.spot.referencematerial);
            app.ComparetoDropDown.Items = refmat(refmat ~= "None");
            app.ComparetoDropDown.ItemsData = app.ComparetoDropDown.Items;

            app.DenominatorListBox.Items = app.base.project.session.spot.analytes;
            app.DenominatorListBox.ItemsData = app.DenominatorListBox.Items;


            update_metadata_table(app)
        end

        function update_results_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            % Add comments if checkbox is checked
            if app.ShowCommentsCheckBox.Value
                if isfield(app.base.project.session.spot, 'comment')
                    comments = app.base.project.session.spot.comment;
                    % Convert nested cells to strings, empty cells become empty strings
                    commentStrings = cellfun(@(x) char(x{1}), comments(~cellfun(@isempty, comments)), 'UniformOutput', false);
                    emptyIdx = cellfun(@isempty, comments);
                    comments(~emptyIdx) = commentStrings;
                    comments(emptyIdx) = {''};
                    T = [T, table(comments, 'VariableNames', {'Comments'})];
                else
                    app.ShowCommentsCheckBox.Value = false;
                    message={'No Comments'};
                    uialert(app.UIFigure,message,' Warning');

                end
            end

            if isequal(app.ShowDropDown.Value,'CPS')

                if isequal(app.selected_host_inclusion,'Host')

                    cps = app.base.project.session.spot.bkgd_corrected_cps;

                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    cps = app.base.project.session.spot.bkgd_corrected_cps_incl;

                end

                % Convert cps to array for logical indexing
                cps_array = table2array(cps);

                % Create mask for rows where at least one value is non-zero
                non_zero_row_mask = any(cps_array ~= 0, 2);

                % Filter T and cps to keep only non-zero rows
                T = T(non_zero_row_mask, :);
                cps = cps(non_zero_row_mask, :);

                % Concatenate filtered cps to T
                T = [T, cps];

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions [µg/g]')

                if isequal(app.selected_host_inclusion,'Host')

                    compositions = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};

                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM})

                        lod_table = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                        comp_table = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM};

                        zero_rows = all(lod_table{:,:} == 0, 2); % Find rows where all LODs are zero
                        comp_table{zero_rows, :} = 0;            % Set those composition rows to zero

                        compositions = comp_table;
                        limits_of_detection = lod_table;

                        if all(lod_table{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this PRM.'};
                            uialert(app.UIFigure, message, 'Warning');
                            compositions = table();
                            limits_of_detection = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end

                end

                % Convert tables to arrays for manipulation
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);

                % Create logical mask for values below detection limit
                below_lod = comp_array < lod_array;

                % Create cell array for display values
                display_values = num2cell(comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with original variable names
                display_compositions = array2table(display_values, 'VariableNames', compositions.Properties.VariableNames);

                % Combine with modified composition data
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep only non-zero composition columns, while keeping all metadata columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);               % 1 x nComp
                nMetaCols = width(T) - width(display_compositions);          % dynamic prefix length
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];        % 1 x width(T)

                T = T(:, col_mask);

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions [µg/g] unfiltered')

                if isequal(app.selected_host_inclusion,'Host')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM};

                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM})
                        compositions = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM};

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Combine with composition data
                T = [T, compositions];

                comp_array = table2array(compositions);

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);      % 1 x nComp
                nMetaCols = width(T) - width(compositions);        % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];

                T = T(:, col_mask);


            elseif isequal(app.ShowDropDown.Value,'Majors as Oxides')

                if isequal(app.selected_host_inclusion,'Host')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM})


                        lod_table = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                        comp_table = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM};

                        zero_rows = all(lod_table{:,:} == 0, 2); % Find rows where all LODs are zero
                        comp_table{zero_rows, :} = 0;            % Set those composition rows to zero

                        compositions = comp_table;
                        limits_of_detection = lod_table;

                        if all(lod_table{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this PRM.'};
                            uialert(app.UIFigure, message, 'Warning');
                            compositions = table();
                            limits_of_detection = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Get list of elements to convert to oxides
                element_names = app.base.project.session.oxides.element_names;
                oxide_names = app.base.project.session.oxides.oxide_names;
                considered_major = app.base.project.session.oxides.considered_major;
                oxide_factors = app.base.project.session.oxides.conversion_factors;

                % Get the variable names from compositions table
                var_names = compositions.Properties.VariableNames;
                new_var_names = var_names; % Start with original names, will update for oxides

                % Create new arrays for the oxide values
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);
                oxide_comp_array = comp_array;
                oxide_lod_array = lod_array;

                % Keep track of which columns were converted to oxides
                converted_columns = false(1, length(var_names));

                % For each column in the composition table
                for j = 1:length(var_names)
                    % Extract the base element from the column name (e.g., 'Fe' from 'Fe57')
                    base_element = regexp(var_names{j}, '^[A-Za-z]+', 'match');

                    if ~isempty(base_element)
                        element = base_element{1};

                        % Check if this element is a major element that should be converted to oxide
                        element_idx = find(strcmp(element_names, element));

                        if ~isempty(element_idx) && strcmp(considered_major{element_idx}, 'Yes') && isKey(oxide_factors, element)
                            % Get the conversion factor and oxide name
                            factor = oxide_factors(element);
                            oxide_name = oxide_names{element_idx};

                            % Convert values to oxide
                            oxide_comp_array(:, j) = comp_array(:, j) * factor;
                            oxide_lod_array(:, j) = lod_array(:, j) * factor;

                            % Mark this column as converted
                            converted_columns(j) = true;

                            % Update column name to show oxide
                            isotope_part = regexp(var_names{j}, '\d+', 'match');
                            if ~isempty(isotope_part)
                                new_var_names{j} = [oxide_name,'_', isotope_part{1}];
                            else
                                new_var_names{j} = oxide_name;
                            end
                        end
                    end
                end

                % Convert only oxide columns from µg/g to wt%
                oxide_comp_array(:, converted_columns) = oxide_comp_array(:, converted_columns) / 10000;
                oxide_lod_array(:, converted_columns) = oxide_lod_array(:, converted_columns) / 10000;

                % Create logical mask for values below detection limit
                below_lod = oxide_comp_array < oxide_lod_array;

                % Create cell array for display values
                display_values = num2cell(oxide_comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], oxide_lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with updated variable names for oxides
                display_compositions = array2table(display_values, 'VariableNames', new_var_names);

                % Combine with T as before
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(oxide_comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(oxide_comp_array ~= 0, 1);         % 1 x nComp
                nMetaCols = width(T) - width(display_compositions);         % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];        % 1 x width(T)

                T = T(:, col_mask);

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                sensitivities = app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM};

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(sensitivities) ~= 0, 2);

                % Filter table to keep only non-zero rows
                sensitivities = sensitivities(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, sensitivities];

            elseif isequal(app.ShowDropDown.Value,'PRM Sensitivities')

                prm_sens = app.base.project.session.spot.prm_sensitivities;

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(prm_sens) ~= 0, 2);

                % Filter table to keep only non-zero rows
                prm_sens = prm_sens(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, prm_sens];


            elseif isequal(app.ShowDropDown.Value,'Limits of detection')

                if isequal(app.selected_host_inclusion,'Host')
                    lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if ~isempty(app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM}) && ~isempty(app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM})

                        lods = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};

                        if all(lods{:,:} == 0, 'all')
                            message = {'There are no limits of detection to filter the data with, probably because the matrix-only tracer element was not defined in the RM file for this PRM.'};
                            uialert(app.UIFigure, message, 'Warning');
                            lods = table();
                        end

                    else
                        message={'No inclusion was quantified'};
                        uialert(app.UIFigure,message,' Warning');
                        app.HostInclusionDropDown.Value = app.HostInclusionDropDown.Items(1);
                        return
                    end
                end

                % Convert LOD table to array for processing
                lod_array = table2array(lods);

                % Create mask for non-zero rows (at least one non-zero in the row)
                non_zero_row_mask = any(lod_array ~= 0, 2);

                % Create mask for non-zero columns (at least one non-zero in the column)
                non_zero_col_mask = any(lod_array ~= 0, 1);

                % Filter rows and columns in T and lods
                T = T(non_zero_row_mask, :);
                lods = lods(non_zero_row_mask, non_zero_col_mask);

                % Add filtered LODs to T
                T = [T, lods];


            elseif isequal(app.ShowDropDown.Value,'Fe2+ and Fe3+')

                if ~isfield(app.base.project.session.spot, 'Fe_2_3')
                    message={'There is no Fe2+ and Fe3+ data'};
                    uialert(app.UIFigure,message,' Warning');
                    app.ShowDropDown.Value = app.ShowDropDown.Items(1);
                    update_results_module_GUI(app)
                    return
                end

                if isequal(app.selected_host_inclusion,'Host')

                    compositions = app.base.project.session.spot.Fe_2_3{app.BracketIdDropDown.Value, app.selected_PRM};

                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    if isfield(app.base.project.session.spot, 'Fe_2_3_incl') && ~isempty(app.base.project.session.spot.Fe_2_3_incl)
                        compositions = app.base.project.session.spot.Fe_2_3_incl{app.BracketIdDropDown.Value, app.selected_PRM};
                    else
                        message={'There is no Fe2+ and Fe3+ data'};
                        uialert(app.UIFigure,message,' Warning');
                        app.ShowDropDown.Value = app.ShowDropDown.Items(1);
                        update_results_module_GUI(app)
                        return
                    end
                end

                % Combine with composition data
                T = [T, compositions];

                comp_array = table2array(compositions);

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

                % Keep all metadata columns + only non-zero composition columns
                non_zero_comp_cols = any(comp_array ~= 0, 1);   % 1 x nComp
                nMetaCols = width(T) - width(compositions);     % dynamic metadata count
                col_mask = [true(1, nMetaCols), non_zero_comp_cols];

                T = T(:, col_mask);
            end

            % Update the displayed table
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;


        end

        %%%%%%%%%%%%%%%%%%%%%%%%%% Export %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function export_displayed_table(app, outputpath)
            % Get the currently displayed table
            displayed_table = app.ResultsTable.Data;

            % Get column names
            column_names = app.ResultsTable.ColumnName;

            % Convert to table if it's not already
            if ~istable(displayed_table)
                displayed_table = array2table(displayed_table, 'VariableNames', column_names);
            end

            % Create sheet name based on current selection
            sheet_name = sprintf('%s - ID%d', app.PrimaryReferenceMaterialDropDown.Items{app.selected_PRM}, app.BracketIdDropDown.Value);

            % Write the table to Excel
            writetable(displayed_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        end

        function export_all_matrix_lods(app, outputpath)
            % Get Bracket IDs and PRMs
            bracket_ids = app.BracketIdDropDown.Items;
            prms = app.PrimaryReferenceMaterialDropDown.Items;

            data_to_output = app.base.project.session.spot.matrix_limits_of_detection;
            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over PRMs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(prms)
                    % Get the table
                    tbl = data_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);

                    % Add spot number and spot names to tbl_clean
                    row_idx = find(nonzero_rows);
                    tbl_clean = addvars(tbl_clean, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', prms{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(tbl_clean, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_inclusion_lods(app, outputpath)
            % Get Bracket IDs and PRMs
            bracket_ids = app.BracketIdDropDown.Items;
            prms = app.PrimaryReferenceMaterialDropDown.Items;

            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                data_to_output = app.base.project.session.spot.inclusion_limits_of_detection;
            else
                message={'No Inclusion was quantified'};
                uialert(app.UIFigure,message,' Warning');
                return
            end

            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over PRMs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(prms)
                    % Get the table
                    tbl = data_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);

                    % Add spot number and spot names to tbl_clean
                    row_idx = find(nonzero_rows);
                    tbl_clean = addvars(tbl_clean, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', prms{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(tbl_clean, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_matrix_mass_fractions(app, outputpath)
            % Get Bracket IDs and PRMs
            bracket_ids = app.BracketIdDropDown.Items;
            prms = app.PrimaryReferenceMaterialDropDown.Items;

            data_to_output = app.base.project.session.spot.matrix_composition;
            lod_to_output = app.base.project.session.spot.matrix_limits_of_detection;
            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over PRMs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(prms)
                    % Get the table and LOD table
                    tbl = data_to_output{i, j};
                    lod_tbl = lod_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);
                    lod_clean = lod_tbl(nonzero_rows, nonzero_cols);

                    % Apply LOD formatting
                    comp_array = table2array(tbl_clean);
                    lod_array = table2array(lod_clean);

                    below_lod = comp_array < lod_array;
                    display_values = num2cell(comp_array);
                    display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                    display_tbl = array2table(display_values, 'VariableNames', tbl_clean.Properties.VariableNames);

                    % Add spot number and spot names to display_tbl
                    row_idx = find(nonzero_rows);
                    display_tbl = addvars(display_tbl, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', prms{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(display_tbl, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end

        function export_all_inclusion_mass_fractions(app, outputpath)
            % Get Bracket IDs and PRMs
            bracket_ids = app.BracketIdDropDown.Items;
            prms = app.PrimaryReferenceMaterialDropDown.Items;

            if isfield(app.base.project.session.spot, 'inclusion_limits_of_detection')
                data_to_output = app.base.project.session.spot.inclusion_composition;
                lod_to_output = app.base.project.session.spot.inclusion_limits_of_detection;
            else
                message={'No Inclusion was quantified'};
                uialert(app.UIFigure,message,' Warning');
                return
            end

            spotnames = app.base.project.session.spot.name(:);
            spotNumbers = (1:numel(spotnames))';

            % Loop over PRMs and Bracket IDs
            for i = 1:numel(bracket_ids)
                for j = 1:numel(prms)
                    % Get the table and LOD table
                    tbl = data_to_output{i, j};
                    lod_tbl = lod_to_output{i, j};

                    % Remove rows and columns that are all zeros
                    tbl_numeric = tbl{:,:};
                    nonzero_rows = any(tbl_numeric ~= 0, 2);
                    nonzero_cols = any(tbl_numeric ~= 0, 1);
                    tbl_clean = tbl(nonzero_rows, nonzero_cols);
                    lod_clean = lod_tbl(nonzero_rows, nonzero_cols);

                    % Apply LOD formatting
                    comp_array = table2array(tbl_clean);
                    lod_array = table2array(lod_clean);

                    below_lod = comp_array < lod_array;
                    display_values = num2cell(comp_array);
                    display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                    display_tbl = array2table(display_values, 'VariableNames', tbl_clean.Properties.VariableNames);

                    % Add spot number and spot names to display_tbl
                    row_idx = find(nonzero_rows);
                    display_tbl = addvars(display_tbl, spotNumbers(row_idx), spotnames(row_idx), 'Before', 1, ...
                        'NewVariableNames', {'SpotNumber', 'SpotName'});

                    % Sheet name as in single export
                    sheet_name = sprintf('%s - ID%s', prms{j}, bracket_ids{i});

                    % Write to Excel
                    writetable(display_tbl, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
                end
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% Add Metadata %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function get_material_abbreviation_file(app)
            %  folderPath = "C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\ICP-Base_dev\Extras\SRM Files";
            abb_files = dir(fullfile(app.base.project.session.config.metadata_abbreviations_path, 'Material_Abbreviations.csv'));

            filenames = {};
            allTables = {};


            % Get the full path of the file
            filePath = fullfile(app.base.project.session.config.metadata_abbreviations_path, abb_files.name);

            % Read the contents of the file
            app.base.project.session.config.material_abbreviations = readtable(filePath);

        end

        function assign_material_to_spot(app)

            % Get the spotnames and material abbreviations
            spotnames = app.base.project.session.spot.name;
            abbreviations = app.base.project.session.config.material_abbreviations;

            % Initialize material cell array
            materials = cell(length(spotnames), 1);

            % Loop through each spot
            for i = 1:length(spotnames)
                % Get current spot name
                spot = spotnames{i};

                % Check each column of abbreviations table
                found = false;
                for col = 1:width(abbreviations)
                    abbrevs = abbreviations{:, col};
                    % Remove empty cells
                    abbrevs = abbrevs(~cellfun(@isempty, abbrevs));

                    % Check if any abbreviation is contained in the spot name
                    for j = 1:length(abbrevs)
                        if contains(lower(spot), lower(abbrevs{j}))
                            % Assign the column name as the material
                            materials{i} = abbreviations.Properties.VariableNames{col};
                            found = true;
                            break;
                        end
                    end
                    if found
                        break;
                    end
                end

                % If no match found, assign unknown
                if ~found
                    materials{i} = 'Unknown';
                end
            end

            % Store the materials in the session
            app.base.project.session.meta.material = materials;
        end

        function get_sample_abbreviation_file(app)
            %  folderPath = "C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\ICP-Base_dev\Extras\SRM Files";
            abb_files = dir(fullfile(app.base.project.session.config.metadata_abbreviations_path, 'Sample_Abbreviations.csv'));

            filenames = {};
            allTables = {};


            % Get the full path of the file
            filePath = fullfile(app.base.project.session.config.metadata_abbreviations_path, abb_files.name);

            % Read the contents of the file
            app.base.project.session.config.sample_abbreviations = readtable(filePath);

        end

        function assign_sample_to_spot(app)
            % Get the spotnames and material abbreviations
            spotnames = app.base.project.session.spot.name;
            abbreviations = app.base.project.session.config.sample_abbreviations;

            % Initialize sample cell array
            samples = cell(length(spotnames), 1);

            % Loop through each spot
            for i = 1:length(spotnames)
                % Get current spot name
                spot = spotnames{i};

                % Check each column of abbreviations table
                found = false;
                best_match = '';
                max_match_length = 0;
                best_match_col = '';

                for col = 1:width(abbreviations)
                    abbrevs = abbreviations{:, col};
                    % Remove empty cells
                    abbrevs = abbrevs(~cellfun(@isempty, abbrevs));

                    % Check each abbreviation
                    for j = 1:length(abbrevs)
                        abbrev = abbrevs{j};

                        % Skip empty abbreviations
                        if isempty(abbrev)
                            continue;
                        end

                        % Try to find exact match with word boundaries
                        % This checks for the abbreviation as a standalone part
                        pattern = ['(^|[^a-zA-Z0-9])' regexptranslate('escape', abbrev) '($|[^a-zA-Z0-9])'];
                        if ~isempty(regexp(spot, pattern, 'once'))
                            % If this match is longer than our best match so far, update it
                            if length(abbrev) > max_match_length
                                max_match_length = length(abbrev);
                                best_match = abbrev;
                                best_match_col = abbreviations.Properties.VariableNames{col};
                                found = true;
                            end
                        end
                    end
                end

                % Assign the best match or unknown
                if found
                    samples{i} = best_match_col;
                else
                    samples{i} = 'Unknown';
                end
            end

            % Store the samples in the session
            app.base.project.session.meta.sample = samples;
        end

        function get_location_abbreviation_file(app)
            
            abb_files = dir(fullfile(app.base.project.session.config.metadata_abbreviations_path, 'Location_Abbreviations.csv'));

            filenames = {};
            allTables = {};


            % Get the full path of the file
            filePath = fullfile(app.base.project.session.config.metadata_abbreviations_path, abb_files.name);

            % Read the contents of the file
            app.base.project.session.config.location_abbreviations = readtable(filePath);

        end

        function assign_location_to_spot(app)
            % Get the spotnames and material abbreviations
            spotnames = app.base.project.session.spot.name;
            abbreviations = app.base.project.session.config.location_abbreviations;

            % Initialize sample cell array
            locations = cell(length(spotnames), 1);

            % Loop through each spot
            for i = 1:length(spotnames)
                % Get current spot name
                spot = spotnames{i};

                % Check each column of abbreviations table
                found = false;
                best_match = '';
                max_match_length = 0;
                best_match_col = '';

                for col = 1:width(abbreviations)
                    abbrevs = abbreviations{:, col};
                    % Remove empty cells
                    abbrevs = abbrevs(~cellfun(@isempty, abbrevs));

                    % Check each abbreviation
                    for j = 1:length(abbrevs)
                        abbrev = abbrevs{j};

                        % Skip empty abbreviations
                        if isempty(abbrev)
                            continue;
                        end

                        % Try to find exact match with word boundaries
                        % This checks for the abbreviation as a standalone part
                        pattern = ['(^|[^a-zA-Z0-9])' regexptranslate('escape', abbrev) '($|[^a-zA-Z0-9])'];
                        if ~isempty(regexp(spot, pattern, 'once'))
                            % If this match is longer than our best match so far, update it
                            if length(abbrev) > max_match_length
                                max_match_length = length(abbrev);
                                best_match = abbrev;
                                best_match_col = abbreviations.Properties.VariableNames{col};
                                found = true;
                            end
                        end
                    end
                end

                % Assign the best match or unknown
                if found
                    locations{i} = best_match_col;
                else
                    locations{i} = 'Unknown';
                end
            end

            % Store the samples in the session
            app.base.project.session.meta.location = locations;
        end


        function update_metadata_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers


            if isfield(app.base.project.session, 'meta') && isfield(app.base.project.session.meta, 'material') && isfield(app.base.project.session.meta, 'sample') && isfield(app.base.project.session.meta, 'location')

                material = app.base.project.session.meta.material(:);

                sample = app.base.project.session.meta.sample(:);

                location = app.base.project.session.meta.location(:);

                % Generate initial table with spot numbers and names
                T = table(spotNumbers, spotnames, material, sample, location, 'VariableNames', {'#', 'Spotname', 'Material', 'Sample', 'Location'});

                app.MetadataTable.Data = T;
                app.MetadataTable.ColumnName = T.Properties.VariableNames;
            end


        end

        %%%%%%%%%%%%%%%%%%%%%%%%%% Quality Control %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %         function update_srm_plot(app)
        %
        %             % get the Indices of the chosen SRM from the dropdown
        %             % from spot.referencematerials
        %             refMaterial = app.SecondaryReferenceMaterialDropDown.Value;
        %
        %             if isempty(refMaterial)
        %                 return
        %             end
        %
        %             indices = find(strcmp(app.base.project.session.spot.referencematerial, refMaterial));
        %
        %             % Get the corresponding time points
        %             time_points = app.base.project.session.spot.integer_time_points(indices);
        %
        %
        %             %%%%%%%%%% calculate the ratios %%%%%%%
        %             % of the measured values vs the published values
        %
        %             values = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown_2.Value, app.PrimaryReferenceMaterialDropDown_2.Value}(indices,:);
        %             lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown_2.Value, app.PrimaryReferenceMaterialDropDown_2.Value}(indices,:);
        %
        %             if all(table2array(values) == 0, 'all')
        %                 % if the values contain all zeros means that the PRM was
        %                 % not used
        %                 %message={'Primary Reference Material was not used for quantification'};
        %                 %uialert(app.UIFigure,message,' Warning');
        %                 cla(app.SRMAxes)
        %                 return
        %             end
        %
        %             % Find indices of rows and columns that have at least one non-zero value
        %             nonZeroRows = any(table2array(values) ~= 0, 2);  % check rows
        %             nonZeroCols = any(table2array(values) ~= 0, 1);     % check columns
        %
        %             % Keep only rows and columns that have non-zero values
        %             srm_massfractions = values(nonZeroRows, nonZeroCols);
        %
        %             srm_pub_idx = find(strcmp(app.base.project.session.referencematerials.names, refMaterial));
        %             srm_pub_data = app.base.project.session.referencematerials.data{srm_pub_idx};
        %             srm_pub_unc = app.base.project.session.referencematerials.uncertainty{srm_pub_idx};
        %
        %             % Get base element names by removing numbers from mass_fraction column names
        %             base_elements = regexprep(srm_massfractions.Properties.VariableNames, '\d+', '');
        %
        %             % Find matching elements in pub_data
        %             [~, pub_indices] = ismember(base_elements, srm_pub_data.Properties.VariableNames);
        %
        %             % Get only the columns where we found matches
        %             valid_matches = pub_indices > 0;
        %             mass_cols = srm_massfractions(:, valid_matches);
        %             pref_vals = repmat(table2array(srm_pub_data(:, pub_indices(valid_matches))), size(mass_cols, 1), 1);
        %
        %             % Calculate ratios
        %             ratio_values = table2array(mass_cols) ./ pref_vals;
        %
        %             % Create results table with original isotope names
        %             ratios = array2table(ratio_values, 'VariableNames', srm_massfractions.Properties.VariableNames(valid_matches));
        %
        %             % these ratios are that I would need for the secondary standard
        %             % correction
        %
        %             %%%%%%%%%% plot that shit %%%%%%%%
        %
        %             % Get the lods corresponding to the ratio values
        %             lods_filtered = lods(nonZeroRows, nonZeroCols);
        %             lods_for_ratios = lods_filtered(:, valid_matches);
        %
        %             % Create a logical matrix where true means the value is below LOD
        %             is_below_lod = table2array(mass_cols) < table2array(lods_for_ratios);
        %
        %             if ~isempty(srm_pub_unc)
        %                 pref_vals_for_calc = table2array(srm_pub_data(:, pub_indices(valid_matches)));
        %                 max_vals = table2array(srm_pub_data(:, pub_indices(valid_matches))) + table2array(srm_pub_unc(:, pub_indices(valid_matches)));
        %                 min_vals = table2array(srm_pub_data(:, pub_indices(valid_matches))) - table2array(srm_pub_unc(:, pub_indices(valid_matches)));
        %
        %                 ratio_max_values = max_vals ./ pref_vals_for_calc;
        %                 ratio_min_values = min_vals ./ pref_vals_for_calc;
        %
        %                 ratio_min_values(isnan(ratio_min_values)) = 0.9;
        %                 ratio_max_values(isnan(ratio_max_values)) = 1.1;
        %
        %                 cla(app.SRMAxes)
        %                 hold(app.SRMAxes, 'on');
        %
        %                 % Set y-axis to log scale
        %                 if app.LogscaleCheckBox_2.Value
        %                     app.SRMAxes.YScale = 'log';
        %                 else
        %                     app.SRMAxes.YScale = 'linear';
        %                 end
        %
        %                 %%%%%%%
        %
        %                 xVals = 1:size(ratio_min_values, 2);
        %
        %                 % Build band bounds
        %                 L50 = min(ratio_min_values*0.01, [], 1);
        %                 U50 = max(ratio_max_values*10,   [], 1);
        %
        %                 L20 = min(ratio_min_values*0.8,  [], 1);
        %                 U20 = max(ratio_max_values*1.2,  [], 1);
        %
        %                 L10 = min(ratio_min_values*0.9,  [], 1);
        %                 U10 = max(ratio_max_values*1.1,  [], 1);
        %
        %                 L00 = min(ratio_min_values,      [], 1);
        %                 U00 = max(ratio_max_values,      [], 1);
        %
        %                 % Ensure positivity for log-scale: mask invalid columns with NaN
        %                 mask50 = (L50>0) & (U50>0);
        %                 mask20 = (L20>0) & (U20>0);
        %                 mask10 = (L10>0) & (U10>0);
        %                 mask00 = (L00>0) & (U00>0);
        %
        %                 L50(~mask50) = NaN; U50(~mask50) = NaN;
        %                 L20(~mask20) = NaN; U20(~mask20) = NaN;
        %                 L10(~mask10) = NaN; U10(~mask10) = NaN;
        %                 L00(~mask00) = NaN; U00(~mask00) = NaN;
        %
        %                 % Helper to make a band polyshape from lower/upper arrays
        %                 mkPoly = @(lo,hi) polyshape([xVals, fliplr(xVals)], [lo, fliplr(hi)], 'Simplify', true);
        %
        %                 % Full bands
        %                 p50 = mkPoly(L50, U50);
        %                 p20 = mkPoly(L20, U20);
        %                 p10 = mkPoly(L10, U10);
        %                 p00 = mkPoly(L00, U00);
        %
        %                 % Non-overlapping rings (outer minus inner)
        %                 r50 = subtract(p50, p20);
        %                 r20 = subtract(p20, p10);
        %                 r10 = subtract(p10, p00);
        %                 r00 = p00;
        %
        %                 % Plot rings (no overlap => no alpha stacking)
        %                 plot(app.SRMAxes, r50, 'FaceColor', [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %                 plot(app.SRMAxes, r20, 'FaceColor', [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %                 plot(app.SRMAxes, r10, 'FaceColor', [1 1 0.8],   'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %                 plot(app.SRMAxes, r00, 'FaceColor', [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %
        %                 % Get current y-limits
        %                 ymin = min(min(table2array(ratios)));
        %                 ymax = max(max(table2array(ratios)));
        %
        %                 % Calculate symmetric limits in log space around y=1
        %                 maxDistance = max(abs(log10(ymin)), abs(log10(ymax)));
        %                 app.SRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];
        %
        %                 % Plot horizontal line at y=1
        %                 yline(app.SRMAxes, 1, 'k--', 'LineWidth', 1);
        %
        %                 % Create a colormap based on time points
        %                 cmap = jet(256);
        %                 min_time = min(time_points);
        %                 max_time = max(time_points);
        %
        %                 % Plot data with colors based on time
        %                 for i = 1:height(ratios)
        %                     % Normalize time to map to colormap
        %                     if max_time > min_time
        %                         norm_time = (time_points(i) - min_time) / (max_time - min_time);
        %                     else
        %                         norm_time = 0.5; % All points have the same time
        %                     end
        %                     color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
        %                     point_color = cmap(color_idx, :);
        %
        %                     x_data = 1:width(ratios);
        %                     y_data = table2array(ratios(i,:));
        %
        %                     % Plot line connecting all points
        %                     plot(app.SRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);
        %
        %                     % Separate points based on LOD
        %                     is_below = is_below_lod(i, :);
        %                     is_above = ~is_below;
        %
        %                     % Plot points below LOD in grey
        %                     if any(is_below)
        %                         plot(app.SRMAxes, x_data(is_below), y_data(is_below), 'o', ...
        %                             'MarkerFaceColor', [0.5 0.5 0.5], ...
        %                             'MarkerEdgeColor', [0.0 0.0 0.0]);
        %                     end
        %
        %                     % Plot points above LOD with time-based color
        %                     if any(is_above)
        %                         plot(app.SRMAxes, x_data(is_above), y_data(is_above), 'o', ...
        %                             'MarkerFaceColor', point_color, ...
        %                             'MarkerEdgeColor', [0.0 0.0 0.0]);
        %                     end
        %                 end
        %
        %                 % Add grids with solid lines
        %                 app.SRMAxes.YGrid = 'on';
        %                 app.SRMAxes.XGrid = 'on';
        %                 app.SRMAxes.GridAlpha = 0.15;
        %                 app.SRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
        %                 app.SRMAxes.LineWidth = 0.5;      % Thin lines
        %
        %                 % Customize the plot
        %                 app.SRMAxes.XTick = 1:width(ratios);
        %                 app.SRMAxes.XTickLabel = ratios.Properties.VariableNames;
        %                 app.SRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
        %                 app.SRMAxes.XLim = [0.5, width(ratios) + 0.5];
        %
        %                 hold(app.SRMAxes, 'off');
        %
        %
        %             else
        %                 cla(app.SRMAxes)
        %                 hold(app.SRMAxes, 'on');
        %
        %                 % Set y-axis to log scale
        %                 if app.LogscaleCheckBox_2.Value
        %                     app.SRMAxes.YScale = 'log';
        %                 else
        %                     app.SRMAxes.YScale = 'linear';
        %                 end
        %
        %                 % Add shaded regions around y=1
        %                 xCoord = [0.5 width(ratios)+0.5 width(ratios)+0.5 0.5];
        %
        %                 % Light green region (±10%)
        %                 yCoord = [0.9 0.9 1.1 1.1];
        %                 fill(app.SRMAxes, xCoord, yCoord, [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %
        %                 % Yellow regions (10-20%)
        %                 yCoordLower = [0.8 0.8 0.9 0.9];
        %                 yCoordUpper = [1.1 1.1 1.2 1.2];
        %                 fill(app.SRMAxes, xCoord, yCoordLower, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %                 fill(app.SRMAxes, xCoord, yCoordUpper, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %
        %                 % Light orange regions (20-30%)
        %                 yCoordLower = [0.7 0.7 0.8 0.8];
        %                 yCoordUpper = [1.2 1.2 1.3 1.3];
        %                 fill(app.SRMAxes, xCoord, yCoordLower, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %                 fill(app.SRMAxes, xCoord, yCoordUpper, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
        %
        %                 % Light red regions (>30% and <70%)
        %                 yCoordLower = [0.00001 0.00001 0.7 0.7];
        %                 yCoordUpper = [1.3 1.3 10000 10000];
        %                 fill(app.SRMAxes, xCoord, yCoordLower, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);
        %                 fill(app.SRMAxes, xCoord, yCoordUpper, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);
        %
        %                 % Get current y-limits
        %                 ymin = min(min(table2array(ratios)));
        %                 ymax = max(max(table2array(ratios)));
        %
        %                 % Calculate symmetric limits in log space around y=1
        %                 maxDistance = max(abs(log10(ymin)), abs(log10(ymax)));
        %                 app.SRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];
        %
        %                 % Plot horizontal line at y=1
        %                 yline(app.SRMAxes, 1, 'k--', 'LineWidth', 1);
        %
        %                 % Create a colormap based on time points
        %                 cmap = jet(256);
        %                 min_time = min(time_points);
        %                 max_time = max(time_points);
        %
        %                 % Plot data with colors based on time
        %                 for i = 1:height(ratios)
        %                     % Normalize time to map to colormap
        %                     if max_time > min_time
        %                         norm_time = (time_points(i) - min_time) / (max_time - min_time);
        %                     else
        %                         norm_time = 0.5; % All points have the same time
        %                     end
        %                     color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
        %                     point_color = cmap(color_idx, :);
        %
        %                     x_data = 1:width(ratios);
        %                     y_data = table2array(ratios(i,:));
        %
        %                     % Plot line connecting all points
        %                     plot(app.SRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);
        %
        %                     % Separate points based on LOD
        %                     is_below = is_below_lod(i, :);
        %                     is_above = ~is_below;
        %
        %                     % Plot points below LOD in grey
        %                     if any(is_below)
        %                         plot(app.SRMAxes, x_data(is_below), y_data(is_below), 'o', ...
        %                             'MarkerFaceColor', [0.5 0.5 0.5], ...
        %                             'MarkerEdgeColor', [0.0 0.0 0.0]);
        %                     end
        %
        %                     % Plot points above LOD with time-based color
        %                     if any(is_above)
        %                         plot(app.SRMAxes, x_data(is_above), y_data(is_above), 'o', ...
        %                             'MarkerFaceColor', point_color, ...
        %                             'MarkerEdgeColor', [0.0 0.0 0.0]);
        %                     end
        %                 end
        %
        %                 % Add grids with solid lines
        %                 app.SRMAxes.YGrid = 'on';
        %                 app.SRMAxes.XGrid = 'on';
        %                 app.SRMAxes.GridAlpha = 0.15;
        %                 app.SRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
        %                 app.SRMAxes.LineWidth = 0.5;      % Thin lines
        %
        %                 % Customize the plot
        %                 app.SRMAxes.XTick = 1:width(ratios);
        %                 app.SRMAxes.XTickLabel = ratios.Properties.VariableNames;
        %                 app.SRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
        %                 app.SRMAxes.XLim = [0.5, width(ratios) + 0.5];
        %
        %                 hold(app.SRMAxes, 'off');
        %             end
        %
        %         end

        function update_srm_plot(app)

            % get the Indices of the chosen SRM from the dropdown
            % from spot.referencematerials
            refMaterial = app.SecondaryReferenceMaterialDropDown.Value;

            if isempty(refMaterial)
                return
            end

            indices = find(strcmp(app.base.project.session.spot.referencematerial, refMaterial));

            % Get the corresponding time points
            time_points = app.base.project.session.spot.integer_time_points(indices);


            %%%%%%%%%% calculate the ratios %%%%%%%
            % of the measured values vs the published values

            values = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown_2.Value, app.PrimaryReferenceMaterialDropDown_2.Value}(indices,:);
            lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown_2.Value, app.PrimaryReferenceMaterialDropDown_2.Value}(indices,:);

            if all(table2array(values) == 0, 'all')
                % if the values contain all zeros means that the PRM was
                % not used
                %message={'Primary Reference Material was not used for quantification'};
                %uialert(app.UIFigure,message,' Warning');
                cla(app.SRMAxes)
                return
            end

            % Find indices of rows and columns that have at least one non-zero value
            nonZeroRows = any(table2array(values) ~= 0, 2);  % check rows
            nonZeroCols = any(table2array(values) ~= 0, 1);     % check columns

            % Keep only rows and columns that have non-zero values
            srm_massfractions = values(nonZeroRows, nonZeroCols);

            srm_pub_idx = find(strcmp(app.base.project.session.referencematerials.names, refMaterial));
            srm_pub_data = app.base.project.session.referencematerials.data{srm_pub_idx};
            srm_pub_unc = app.base.project.session.referencematerials.uncertainty{srm_pub_idx};

            % Get base element names by removing numbers from mass_fraction column names
            base_elements = regexprep(srm_massfractions.Properties.VariableNames, '\d+', '');

            % Find matching elements in pub_data
            [~, pub_indices] = ismember(base_elements, srm_pub_data.Properties.VariableNames);

            % Get only the columns where we found matches
            valid_matches = pub_indices > 0;
            mass_cols = srm_massfractions(:, valid_matches);
            pref_vals = repmat(table2array(srm_pub_data(:, pub_indices(valid_matches))), size(mass_cols, 1), 1);

            % Calculate ratios
            ratio_values = table2array(mass_cols) ./ pref_vals;

            % Create results table with original isotope names
            ratios = array2table(ratio_values, 'VariableNames', srm_massfractions.Properties.VariableNames(valid_matches));

            % these ratios are that I would need for the secondary standard
            % correction

            %%%%%%%%%% plot that shit %%%%%%%%

            % Get the lods corresponding to the ratio values
            lods_filtered = lods(nonZeroRows, nonZeroCols);
            lods_for_ratios = lods_filtered(:, valid_matches);

            % Create a logical matrix where true means the value is below LOD
            is_below_lod = table2array(mass_cols) < table2array(lods_for_ratios);

            if ~isempty(srm_pub_unc)
                pref_vals_for_calc = table2array(srm_pub_data(:, pub_indices(valid_matches)));
                max_vals = table2array(srm_pub_data(:, pub_indices(valid_matches))) + table2array(srm_pub_unc(:, pub_indices(valid_matches)));
                min_vals = table2array(srm_pub_data(:, pub_indices(valid_matches))) - table2array(srm_pub_unc(:, pub_indices(valid_matches)));

                ratio_max_values = max_vals ./ pref_vals_for_calc;
                ratio_min_values = min_vals ./ pref_vals_for_calc;

                ratio_min_values(isnan(ratio_min_values)) = 0.9;
                ratio_max_values(isnan(ratio_max_values)) = 1.1;

                cla(app.SRMAxes)
                hold(app.SRMAxes, 'on');

                % Set y-axis to log scale
                if app.LogscaleCheckBox_2.Value
                    app.SRMAxes.YScale = 'log';
                else
                    app.SRMAxes.YScale = 'linear';
                end

                %%%%%%%

                xVals = 1:size(ratio_min_values, 2);

                % Extend x-values for full coverage
                xExtended = [0.5, xVals, max(xVals) + 0.5];

                % Build band bounds
                L50 = min(ratio_min_values*0.01, [], 1);
                U50 = max(ratio_max_values*10,   [], 1);

                L20 = min(ratio_min_values*0.8,  [], 1);
                U20 = max(ratio_max_values*1.2,  [], 1);

                L10 = min(ratio_min_values*0.9,  [], 1);
                U10 = max(ratio_max_values*1.1,  [], 1);

                L00 = min(ratio_min_values,      [], 1);
                U00 = max(ratio_max_values,      [], 1);

                % Extend bounds to edges
                L50_ext = [L50(1), L50, L50(end)];
                U50_ext = [U50(1), U50, U50(end)];
                L20_ext = [L20(1), L20, L20(end)];
                U20_ext = [U20(1), U20, U20(end)];
                L10_ext = [L10(1), L10, L10(end)];
                U10_ext = [U10(1), U10, U10(end)];
                L00_ext = [L00(1), L00, L00(end)];
                U00_ext = [U00(1), U00, U00(end)];

                % Ensure positivity for log-scale
                mask50 = (L50_ext>0) & (U50_ext>0);
                mask20 = (L20_ext>0) & (U20_ext>0);
                mask10 = (L10_ext>0) & (U10_ext>0);
                mask00 = (L00_ext>0) & (U00_ext>0);

                L50_ext(~mask50) = NaN; U50_ext(~mask50) = NaN;
                L20_ext(~mask20) = NaN; U20_ext(~mask20) = NaN;
                L10_ext(~mask10) = NaN; U10_ext(~mask10) = NaN;
                L00_ext(~mask00) = NaN; U00_ext(~mask00) = NaN;

                % Helper to make a band polyshape from lower/upper arrays
                mkPoly = @(lo,hi) polyshape([xExtended, fliplr(xExtended)], [lo, fliplr(hi)], 'Simplify', true);

                % Full bands
                p50 = mkPoly(L50_ext, U50_ext);
                p20 = mkPoly(L20_ext, U20_ext);
                p10 = mkPoly(L10_ext, U10_ext);
                p00 = mkPoly(L00_ext, U00_ext);

                % Non-overlapping rings (outer minus inner)
                r50 = subtract(p50, p20);
                r20 = subtract(p20, p10);
                r10 = subtract(p10, p00);
                r00 = p00;

                % Plot rings (no overlap => no alpha stacking)
                plot(app.SRMAxes, r50, 'FaceColor', [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.SRMAxes, r20, 'FaceColor', [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.SRMAxes, r10, 'FaceColor', [1 1 0.8],   'EdgeColor', 'none', 'FaceAlpha', 0.5);
                plot(app.SRMAxes, r00, 'FaceColor', [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Get current y-limits with padding
                ymin = min(min(table2array(ratios)));
                ymax = max(max(table2array(ratios)));

                % Calculate symmetric limits in log space around y=1 with 10% padding
                maxDistance = max(abs(log10(ymin)), abs(log10(ymax))) * 1.1;
                app.SRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];

                % Plot horizontal line at y=1
                yline(app.SRMAxes, 1, 'k--', 'LineWidth', 1);

                % Create a colormap based on time points
                cmap = jet(256);
                min_time = min(time_points);
                max_time = max(time_points);

                % Plot data with colors based on time
                for i = 1:height(ratios)
                    % Normalize time to map to colormap
                    if max_time > min_time
                        norm_time = (time_points(i) - min_time) / (max_time - min_time);
                    else
                        norm_time = 0.5; % All points have the same time
                    end
                    color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
                    point_color = cmap(color_idx, :);

                    x_data = 1:width(ratios);
                    y_data = table2array(ratios(i,:));

                    % Plot line connecting all points
                    plot(app.SRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);

                    % Separate points based on LOD
                    is_below = is_below_lod(i, :);
                    is_above = ~is_below;

                    % Plot points below LOD in grey
                    if any(is_below)
                        plot(app.SRMAxes, x_data(is_below), y_data(is_below), 'o', ...
                            'MarkerFaceColor', [0.5 0.5 0.5], ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end

                    % Plot points above LOD with time-based color
                    if any(is_above)
                        plot(app.SRMAxes, x_data(is_above), y_data(is_above), 'o', ...
                            'MarkerFaceColor', point_color, ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end
                end

                % Add grids with solid lines
                app.SRMAxes.YGrid = 'on';
                app.SRMAxes.XGrid = 'on';
                app.SRMAxes.GridAlpha = 0.15;
                app.SRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
                app.SRMAxes.LineWidth = 1;      % Thin lines

                % Customize the plot
                app.SRMAxes.XTick = 1:width(ratios);
                app.SRMAxes.XTickLabel = ratios.Properties.VariableNames;
                app.SRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
                app.SRMAxes.XLim = [0.5, width(ratios) + 0.5];

                hold(app.SRMAxes, 'off');


            else
                cla(app.SRMAxes)
                hold(app.SRMAxes, 'on');

                % Set y-axis to log scale
                if app.LogscaleCheckBox_2.Value
                    app.SRMAxes.YScale = 'log';
                else
                    app.SRMAxes.YScale = 'linear';
                end

                % Add shaded regions around y=1
                xCoord = [0.5 width(ratios)+0.5 width(ratios)+0.5 0.5];

                % Light green region (±10%)
                yCoord = [0.9 0.9 1.1 1.1];
                fill(app.SRMAxes, xCoord, yCoord, [0.9 1 0.9], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Yellow regions (10-20%)
                yCoordLower = [0.8 0.8 0.9 0.9];
                yCoordUpper = [1.1 1.1 1.2 1.2];
                fill(app.SRMAxes, xCoord, yCoordLower, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                fill(app.SRMAxes, xCoord, yCoordUpper, [1 1 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Light orange regions (20-30%)
                yCoordLower = [0.7 0.7 0.8 0.8];
                yCoordUpper = [1.2 1.2 1.3 1.3];
                fill(app.SRMAxes, xCoord, yCoordLower, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);
                fill(app.SRMAxes, xCoord, yCoordUpper, [1 0.9 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.5);

                % Light red regions (>30% and <70%)
                yCoordLower = [0.00001 0.00001 0.7 0.7];
                yCoordUpper = [1.3 1.3 10000 10000];
                fill(app.SRMAxes, xCoord, yCoordLower, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                fill(app.SRMAxes, xCoord, yCoordUpper, [1 0.8 0.8], 'EdgeColor', 'none', 'FaceAlpha', 0.4);

                % Get current y-limits with padding
                ymin = min(min(table2array(ratios)));
                ymax = max(max(table2array(ratios)));

                % Calculate symmetric limits in log space around y=1 with 10% padding
                maxDistance = max(abs(log10(ymin)), abs(log10(ymax))) * 1.1;
                app.SRMAxes.YLim = [10^(-maxDistance) 10^maxDistance];

                % Plot horizontal line at y=1
                yline(app.SRMAxes, 1, 'k--', 'LineWidth', 1);

                % Create a colormap based on time points
                cmap = jet(256);
                min_time = min(time_points);
                max_time = max(time_points);

                % Plot data with colors based on time
                for i = 1:height(ratios)
                    % Normalize time to map to colormap
                    if max_time > min_time
                        norm_time = (time_points(i) - min_time) / (max_time - min_time);
                    else
                        norm_time = 0.5; % All points have the same time
                    end
                    color_idx = floor(norm_time * (size(cmap, 1) - 1)) + 1;
                    point_color = cmap(color_idx, :);

                    x_data = 1:width(ratios);
                    y_data = table2array(ratios(i,:));

                    % Plot line connecting all points
                    plot(app.SRMAxes, x_data, y_data, '-', 'Color', [0.7 0.7 0.7]);

                    % Separate points based on LOD
                    is_below = is_below_lod(i, :);
                    is_above = ~is_below;

                    % Plot points below LOD in grey
                    if any(is_below)
                        plot(app.SRMAxes, x_data(is_below), y_data(is_below), 'o', ...
                            'MarkerFaceColor', [0.5 0.5 0.5], ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end

                    % Plot points above LOD with time-based color
                    if any(is_above)
                        plot(app.SRMAxes, x_data(is_above), y_data(is_above), 'o', ...
                            'MarkerFaceColor', point_color, ...
                            'MarkerEdgeColor', [0.0 0.0 0.0]);
                    end
                end

                % Add grids with solid lines
                app.SRMAxes.YGrid = 'on';
                app.SRMAxes.XGrid = 'on';
                app.SRMAxes.GridAlpha = 0.15;
                app.SRMAxes.MinorGridLineStyle = '-';  % Solid lines for minor grid
                app.SRMAxes.LineWidth = 1;      % Thin lines

                % Customize the plot
                app.SRMAxes.XTick = 1:width(ratios);
                app.SRMAxes.XTickLabel = ratios.Properties.VariableNames;
                app.SRMAxes.XTickLabelRotation = 45;  % Rotate labels for better readability
                app.SRMAxes.XLim = [0.5, width(ratios) + 0.5];

                hold(app.SRMAxes, 'off');
            end
        end


        function update_drift_plot(app)

            % if isequal(app.selected_host_inclusion,'Host')
            composition = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown_3.Value, app.selected_PRM};
            limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown_3.Value, app.selected_PRM};
            % elseif isequal(app.selected_host_inclusion,'Inclusion')
            %     compositions = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown_3.Value, app.selected_PRM};
            %     limits_of_detection = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown_3.Value, app.selected_PRM};
            % end

            zero_cols = all(table2array(composition) == 0, 1);
            composition = composition(:, ~zero_cols);

            if isempty(composition)
                cla(app.DriftAxes)
                return
            end

            % Store original variable names before filtering
            all_var_names = composition.Properties.VariableNames;

            % Extract data for selected analytes
            if ~isempty(app.selected_analytes)
                composition = composition(:, app.selected_analytes);
            else
                composition = composition;
            end

            sample_idx = find(strcmp(app.base.project.session.meta.sample, app.SampleDropDown.Value));
            sample_composition = composition(sample_idx,:);

            if all(all(table2array(sample_composition) == 0))
                cla(app.DriftAxes)
                return
            end

            % Remove columns with all zeros
            zero_rows = all(table2array(sample_composition) == 0, 2);
            sample_composition = sample_composition(~zero_rows,:);
            valid_sample_idx = sample_idx(~zero_rows);

            sample_composition = table2array(sample_composition);

            cla(app.DriftAxes)

            hold(app.DriftAxes, 'on');

            scatter(app.DriftAxes, valid_sample_idx, sample_composition, 15, 'o', 'filled')

            % Get the number of analytes (columns in sample_composition)
            num_analytes = size(sample_composition, 2);

            % Create a colormap for different analytes
            colors = lines(num_analytes);

            % Loop through each analyte and connect points with appropriate line style
            for i = 1:num_analytes
                % Get data for current analyte
                y_data = sample_composition(:, i);

                % Sort data points by x value (valid_sample_idx)
                [sorted_x, sort_idx] = sort(valid_sample_idx);
                sorted_y = y_data(sort_idx);

                % Connect points with different line styles based on adjacency
                for j = 1:(length(sorted_x)-1)
                    % Check if points are adjacent
                    if sorted_x(j+1) - sorted_x(j) == 1
                        % Adjacent points - solid line
                        line(app.DriftAxes, [sorted_x(j), sorted_x(j+1)], [sorted_y(j), sorted_y(j+1)], ...
                            'Color', colors(i,:), 'LineWidth', 1);
                    else
                        % Non-adjacent points - dashed line
                        line(app.DriftAxes, [sorted_x(j), sorted_x(j+1)], [sorted_y(j), sorted_y(j+1)], ...
                            'Color', '#D0D0D0', 'LineStyle', '--', 'LineWidth', 1);
                    end
                end
            end

            ref_values = [];

            if ~isempty(app.selected_analytes)
                % Plot SRM reference values as horizontal lines
                % Check if all points are SRMs
                if all(app.base.project.session.spot.type(valid_sample_idx) == "SRM")
                    % Get the reference material name (assuming all are the same)
                    refmat_name = app.base.project.session.spot.referencematerial(valid_sample_idx(1));

                    % Find the index of this reference material in the database
                    refmat_idx = find(strcmp(app.base.project.session.referencematerials.names, refmat_name));

                    if ~isempty(refmat_idx)
                        % Get reference material data
                        refmat_data = app.base.project.session.referencematerials.data{refmat_idx, 1};

                        % Get the actual variable names for selected analytes
                        if ~isempty(app.selected_analytes)
                            analyte_names = all_var_names(app.selected_analytes);
                        else
                            analyte_names = composition.Properties.VariableNames;
                        end

                        % Loop through selected analytes and plot reference lines
                        for i = 1:num_analytes
                            % Get the analyte name
                            analyte_name = analyte_names{i};
                            % Strip the isotope number (e.g., "Cl35" -> "Cl")
                            base_element = regexprep(analyte_name, '\d+', '');

                            % Check if this element exists in the reference material data
                            if ismember(base_element, refmat_data.Properties.VariableNames)
                                % Get the reference value
                                ref_value = refmat_data.(base_element);
                                ref_values = [ref_values, ref_value];

                                % Plot horizontal line with the same color as the data points
                                x_limits = xlim(app.DriftAxes);
                                line(app.DriftAxes, x_limits, [ref_value, ref_value], ...
                                    'Color', 'Black', 'LineStyle', '-.', 'LineWidth', 1.5);

                            end
                        end
                    end
                end
            end

            % Set appropriate axis limits
            % For x-axis: add small padding on both sides
            x_padding = (max(valid_sample_idx) - min(valid_sample_idx)) * 0.05;
            if x_padding == 0  % In case there's only one data point
                x_padding = 1;
            end
            x_min = max(1, min(valid_sample_idx) - x_padding);
            x_max = max(valid_sample_idx) + x_padding;
            xlim(app.DriftAxes, [x_min, x_max]);

            % For y-axis: include both data points and reference lines with minimal padding
            all_y_values = [sample_composition(:); ref_values(:)];
            if ~isempty(all_y_values)
                if app.LogscaleCheckBox.Value
                    % For log scale, need to be careful with padding for small values
                    min_y = min(all_y_values(all_y_values > 0));  % Smallest positive value
                    max_y = max(all_y_values);

                    % Add appropriate padding for log scale
                    y_min = min_y * 0.8;
                    y_max = max_y * 1.2;

                    ylim(app.DriftAxes, [y_min, y_max]);
                else
                    % For linear scale, add small padding on both sides
                    y_range = max(all_y_values) - min(all_y_values);
                    if y_range == 0  % In case all values are the same
                        y_range = max(all_y_values) * 0.1;
                        if y_range == 0  % In case value is zero
                            y_range = 1;
                        end
                    end
                    y_padding = y_range * 0.1;
                    y_min = min(all_y_values) - y_padding;
                    y_max = max(all_y_values) + y_padding;
                    ylim(app.DriftAxes, [y_min, y_max]);
                end
            end

            if app.LogscaleCheckBox.Value
                set(app.DriftAxes, 'YScale', 'log');
            else
                set(app.DriftAxes, 'YScale', 'linear');
            end
            set(app.DriftAxes, 'yminorgrid', 'on')
            set(app.DriftAxes, 'MinorGridLIneStyle', '-')
            set(app.DriftAxes, 'MinorGridAlpha', 0.15)


            hold(app.DriftAxes, 'off');
        end

        function update_MSF_plot(app)

            % preallocate the columns
            spotnames = app.base.project.session.spot.name(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers
            ref_mat = app.base.project.session.spot.referencematerial(:);

            if numel(unique(setdiff(ref_mat, "None"))) == 1
                message={'At least two reference materials (PRM or SRM) are needed for calculation of the MSF.'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end

            % Generate initial table with spot numbers and names
            all_sensitivities = table(spotNumbers, spotnames,ref_mat, 'VariableNames', {'#', 'Spotname', 'Reference Material'});

            initial_sensitivities = zeros(numel(app.base.project.session.spot.type), numel(app.base.project.session.spot.analytes));
            all_RM = [];

            % let all_RM be all spots which spot.type = all_RM
            for i = 1:numel(app.base.project.session.spot.type)
                if strcmp(app.base.project.session.spot.type(i), "PRM") || strcmp(app.base.project.session.spot.type(i), "SRM")
                    all_RM = [all_RM,i];
                end
            end

            for i = 1:numel(all_RM)

                bg_corrected_cps_matrix = app.base.project.session.spot.bkgd_corrected_cps(all_RM(i) , :);

                %%%%%%%% Calculate the sensitivity %%%%%%%%%

                % get the name of the corresponding referencematerial that has been
                % chosen
                referencematerial = app.base.project.session.spot.referencematerial{all_RM(i)};

                % find the index of the corresponding referencematerial
                idx = find(strcmp(app.base.project.session.referencematerials.names, referencematerial));

                if ~isempty(idx)
                    % get the table for the corresponding referencematerial
                    RM_table = app.base.project.session.referencematerials.data{idx};

                    % get the column headers (elements) of the table
                    PRM_elements = RM_table.Properties.VariableNames;

                    % Remove numbers from analytes
                    cleaned_analytes = cellfun(@(x) regexprep(x, '\d', ''), app.base.project.session.spot.analytes, 'UniformOutput', false);

                    for j = 1:numel(cleaned_analytes)
                        if ismember(cleaned_analytes{j}, PRM_elements)
                            % Store directly in the matrix instead of cell array
                            initial_sensitivities(all_RM(i), j) = bg_corrected_cps_matrix.(j) / RM_table.(cleaned_analytes{j});
                        end
                    end
                end
            end

            initial_sensitivities = array2table(initial_sensitivities, "VariableNames", app.base.project.session.spot.analytes);

            all_sensitivities = [all_sensitivities, initial_sensitivities];

            %%%%%%%%%%%%%

            % Extract unique reference materials
            referenceMaterials = unique(all_sensitivities.("Reference Material"));

            % Initialize a new table to store results
            processed_sensitivities = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = all_sensitivities(strcmp(all_sensitivities.("Reference Material"), refMaterial), :);

                % Calculate min, max, and mean for each element (numeric columns only)
                numericData = rows{:, 4:end}; % Assuming element data starts from the 4th column
                minValues = min(numericData, [], 1);
                maxValues = max(numericData, [], 1);
                meanValues = mean(numericData, 1, 'omitnan');

                % Create a temporary table for the current reference material
                tempTable = table(minValues', maxValues', meanValues', ...
                    'VariableNames', {'Min', 'Max', 'Mean'});
                tempTable.Element = rows.Properties.VariableNames(4:end)'; % Add element names
                tempTable.ReferenceMaterial = repmat({refMaterial}, size(tempTable, 1), 1);

                % Append to the results table
                processed_sensitivities = [processed_sensitivities; tempTable];
            end

            % Reorder columns for better readability
            processed_sensitivities = processed_sensitivities(:, {'ReferenceMaterial', 'Element', 'Min', 'Max', 'Mean'});

            %%%%%%%%%%%%%
            % Identify rows where all numeric columns are zero
            emptyRows = all(processed_sensitivities{:, 3:end} == 0, 2);

            % Remove those rows
            processed_sensitivities = processed_sensitivities(~emptyRows, :);

            denominators = app.DenominatorListBox.Value;
            % Initialize an empty cell array to store the ratios
            ratios = {};
            elements = unique(processed_sensitivities.Element);
            % Generate ratios with the chosen denominators
            for i = 1:length(elements)
                for j = 1:length(denominators)
                    if ~strcmp(elements{i}, denominators{j}) % Avoid self-ratios
                        ratios{end+1} = [elements{i} '/' denominators{j}];
                    end
                end
            end

            app.RatioListBox.Items = ratios;
            app.RatioListBox.ItemsData = app.RatioListBox.Items;

            %%%%%%%%%%%%

            sensitivityType = app.SensitivitybasedonDropDown.Value;

            % Get the selected ratios from the RatioListBox
            selected_ratios = app.RatioListBox.Value;

            if isempty(selected_ratios)
                return
            end

            % Initialize a new table to store the calculated ratios
            calculated_ratios = table();

            % Loop through each reference material
            for i = 1:length(referenceMaterials)
                refMaterial = referenceMaterials{i};

                % Filter rows for the current reference material
                rows = processed_sensitivities(strcmp(processed_sensitivities.ReferenceMaterial, refMaterial), :);

                % Loop through each selected ratio
                for j = 1:length(selected_ratios)
                    % Split the ratio into numerator and denominator
                    elements = split(selected_ratios{j}, '/');
                    numerator = elements{1};
                    denominator = elements{2};

                    % Check if both elements exist in the current reference material
                    if any(strcmp(rows.Element, numerator)) && any(strcmp(rows.Element, denominator))
                        % Get the mean values for the numerator and denominator
                        numeratorValue = rows.(sensitivityType)(strcmp(rows.Element, numerator));
                        denominatorValue = rows.(sensitivityType)(strcmp(rows.Element, denominator));

                        % Calculate the ratio
                        ratioValue = numeratorValue / denominatorValue;

                        % Append the result to the calculated_ratios table
                        calculated_ratios = [calculated_ratios; table({refMaterial}, {selected_ratios{j}}, ratioValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'Value'})];
                    end
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%

            compareToMaterial = app.ComparetoDropDown.Value;

            % Filter rows for the reference material to compare to
            referenceRows = calculated_ratios(strcmp(calculated_ratios.ReferenceMaterial, compareToMaterial), :);

            % Extract the list of ratios available in the reference material
            availableRatios = referenceRows.Ratio;

            % Filter the calculated_ratios table to keep only rows with these ratios
            filtered_ratios = calculated_ratios(ismember(calculated_ratios.Ratio, availableRatios), :);

            if isempty(filtered_ratios)
                return
            end

            %%%%%%
            % Filter rows for the chosen reference material
            referenceRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, compareToMaterial), :);

            % Initialize a new table to store the normalized ratios
            app.MSF_ratios = table();

            % Loop through each reference material in the filtered_ratios table
            uniqueMaterials = unique(filtered_ratios.ReferenceMaterial);

            for i = 1:length(uniqueMaterials)
                currentMaterial = uniqueMaterials{i};

                % Skip the chosen reference material itself
                if strcmp(currentMaterial, compareToMaterial)
                    continue;
                end

                % Filter rows for the current reference material
                currentRows = filtered_ratios(strcmp(filtered_ratios.ReferenceMaterial, currentMaterial), :);

                % Initialize a temporary table to store the ratios for this material
                tempTable = table();

                % Loop through each ratio in the current material
                for j = 1:height(currentRows)
                    currentRatio = currentRows.Ratio{j};

                    % Find the corresponding ratio in the chosen reference material
                    refRow = referenceRows(strcmp(referenceRows.Ratio, currentRatio), :);

                    if ~isempty(refRow)
                        % Calculate the normalized ratio
                        normalizedValue = currentRows.Value(j) / refRow.Value;

                        % Append the result to the temporary table
                        tempTable = [tempTable; table({currentMaterial}, {currentRatio}, normalizedValue, ...
                            'VariableNames', {'ReferenceMaterial', 'Ratio', 'NormalizedValue'})];
                    end
                end

                % Append the temporary table to the app.MSF_ratios table
                app.MSF_ratios = [app.MSF_ratios; tempTable];
            end

            app.MSF_ratios = addvars(app.MSF_ratios, ...
                repmat({char(compareToMaterial)}, height(app.MSF_ratios), 1), ...
                'NewVariableNames','ComparedTo', ...
                'After','NormalizedValue');

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if isempty(app.MSF_ratios)
                return
            end

            % Get the unique ratios and reference materials
            uniqueRatios = unique(app.MSF_ratios.Ratio);
            uniqueMaterials = unique(app.MSF_ratios.ReferenceMaterial);

            % Prepare the figure
            cla(app.MSFAxes); % Clear the axes
            hold(app.MSFAxes, 'on'); % Hold on to plot multiple points

            % Define a colormap for different ratios
            colors = lines(length(uniqueRatios)); % Generate distinct colors for each ratio

            % Loop through each ratio and plot the points
            for i = 1:length(uniqueRatios)
                currentRatio = uniqueRatios{i};

                % Filter rows for the current ratio
                ratioRows = app.MSF_ratios(strcmp(app.MSF_ratios.Ratio, currentRatio), :);

                % Get the x and y values
                xValues = find(ismember(uniqueMaterials, ratioRows.ReferenceMaterial)); % Map materials to x-axis indices
                yValues = ratioRows.NormalizedValue;

                % Plot the points for the current ratio
                scatter(app.MSFAxes, xValues, yValues, 60, 'filled', 'MarkerFaceColor', colors(i, :), ...
                    'DisplayName', currentRatio, 'MarkerEdgeColor', 'black'); % Add legend entry
            end

            % Plot the horizontal dashed line at y = 1 (exclude from legend)
            yline(app.MSFAxes, 1, '--', 'LineWidth', 1.5, 'Color', 'k', 'HandleVisibility', 'off');

            % Set x-axis labels to the reference materials
            set(app.MSFAxes, 'XTick', 1:length(uniqueMaterials), 'XTickLabel', uniqueMaterials, 'TickLabelInterpreter', 'none');

            % Add space between the first/last x-axis entries and the plot borders
            xlim(app.MSFAxes, [0.5, length(uniqueMaterials) + 0.5]);

            % Add labels
            xlabel(app.MSFAxes, 'Reference Materials', 'Interpreter', 'none');
            ylabel(app.MSFAxes, compareToMaterial, 'Interpreter', 'none'); % Use the chosen reference material as the y-axis label

            % Show or delete the legend based on the checkbox value
            if app.ShowLegendCheckBox.Value
                legend(app.MSFAxes, 'show', 'Location', 'northeast', 'Interpreter', 'none'); % Show the legend
            else
                legend(app.MSFAxes, 'off'); % Delete the legend
            end

            % Add a grid
            grid(app.MSFAxes, 'on');

            hold(app.MSFAxes, 'off');


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            center_figure(app, app.UIFigure)

            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.results = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the base

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.config.module_status.results = true;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);


            % read in the RM Files
            try

                % read in the metadata abbreviation files
                if ~isfield(app.base.project.session.config, 'metadata_abbreviations_path')
                    app.base.project.session.config.metadata_abbreviations_path =  fullfile(app.sourceapp.installpath,'Add_Metadata');
                end

                if ~isfield(app.base.project.session.meta, 'sample') || isempty(app.base.project.session.meta.sample)
                     app.base.project.session.meta.sample = repmat({'Unknown'}, size(app.base.project.session.spot.name));
                end

                if ~isfield(app.base.project.session.meta, 'material') || isempty(app.base.project.session.meta.material)
                    app.base.project.session.meta.material = repmat({'Unknown'}, size(app.base.project.session.spot.name));
                end
                if ~isfield(app.base.project.session.meta, 'location') || isempty(app.base.project.session.meta.location)
                    app.base.project.session.meta.location = repmat({'Unknown'}, size(app.base.project.session.spot.name));
                end


            catch ME
                DisplayError(ME,'Error reading Metadata files')
                message={'Error reading Metadata files'};
                uialert(app.UIFigure,message,' Error');
            end


            update_results_module_GUI(app)
        end

        % Menu selected function: SavetoBaseandExitMenu
        function SavetoBaseandExitMenuSelected(app, event)

            try

                app.base.project.session.config.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end

        end

        % Value changed function: PrimaryReferenceMaterialDropDown
        function PrimaryReferenceMaterialDropDownValueChanged(app, event)
            app.PrimaryReferenceMaterialDropDown.Value = event.Source.Value;
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;

            app.PrimaryReferenceMaterialDropDown_2.Value = event.Source.Value;
            app.PrimaryReferenceMaterialDropDown_3.Value = event.Source.Value;

            update_results_module_GUI(app)


        end

        % Value changed function: BracketIdDropDown
        function BracketIdDropDownValueChanged(app, event)

            app.BracketIdDropDown.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketIdDropDown.Value;

            app.BracketIdDropDown_2.Value = event.Source.Value;
            app.BracketIdDropDown_3.Value = event.Source.Value;

            update_results_module_GUI(app)

        end

        % Button pushed function: EnddataprocessingButton
        function EnddataprocessingButtonPushed(app, event)

            try

                app.base.project.session.config.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to end the data processing?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Yes","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end


        end

        % Value changed function: HostInclusionDropDown
        function HostInclusionDropDownValueChanged(app, event)
            app.selected_host_inclusion = app.HostInclusionDropDown.Value;

            update_results_module_GUI(app)
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ImportButton, IntervalButton, QuantificationButton
        function QuantificationButtonPushed(app, event)
            try

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to jump modules?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Yes","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                else
                    selection = 'Yes';
                end

                switch selection
                    case 'Yes'
                        settings.ICP_Base_GUI_update_state = true;
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Button pushed function: CreateOutputTableButton
        function CreateOutputTableButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            %outputpath = 'C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\Test Data\Output Test\Gana_Rossa.xlsx';
            %app.base.project.session.outputpath = fullfile(pathname, filename);
            export_displayed_table(app, outputpath)


        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            update_results_module_GUI(app)

        end

        % Button pushed function: GotoCorrectionModuleButton
        function GotoCorrectionModuleButtonPushed(app, event)

            app.base.project.session.config.module_status.results = true;

            Correction_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
            %delete(app)


        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            assign_sample_to_spot(app)
            assign_material_to_spot(app)
            assign_location_to_spot(app)
            
            update_metadata_table(app)
        end

        % Value changed function: SecondaryReferenceMaterialDropDown
        function SecondaryReferenceMaterialDropDownValueChanged(app, event)
            app.selected_SRM = app.SecondaryReferenceMaterialDropDown.Value;

            update_srm_plot(app)
        end

        % Value changed function: PrimaryReferenceMaterialDropDown_2
        function PrimaryReferenceMaterialDropDown_2ValueChanged(app, event)

            app.PrimaryReferenceMaterialDropDown.Value = event.Source.Value;
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;

            app.PrimaryReferenceMaterialDropDown_2.Value = event.Source.Value;

            update_srm_plot(app)
        end

        % Value changed function: BracketIdDropDown_2
        function BracketIdDropDown_2ValueChanged(app, event)
            app.BracketIdDropDown.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketIdDropDown.Value;

            app.BracketIdDropDown_2.Value = event.Source.Value;

            update_srm_plot(app)
        end

        % Value changed function: PrimaryReferenceMaterialDropDown_3
        function PrimaryReferenceMaterialDropDown_3ValueChanged(app, event)


            app.PrimaryReferenceMaterialDropDown.Value = event.Source.Value;
            app.PrimaryReferenceMaterialDropDown_2.Value = event.Source.Value;
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;

            app.PrimaryReferenceMaterialDropDown_3.Value = event.Source.Value;

            update_results_module_GUI(app)
        end

        % Value changed function: BracketIdDropDown_3
        function BracketIdDropDown_3ValueChanged(app, event)
            app.BracketIdDropDown.Value = event.Source.Value;
            app.BracketIdDropDown_2.Value = event.Source.Value;
            app.selected_bracket_id = app.BracketIdDropDown.Value;

            app.BracketIdDropDown_3.Value = event.Source.Value;

            update_drift_plot(app)
        end

        % Value changed function: ElementLegendListBox
        function ElementLegendListBoxValueChanged(app, event)
            chosen_analyte = app.ElementLegendListBox.Value;

            if chosen_analyte == 0  % When 'All' is selected (Value = 0)
                app.selected_analytes = [];
            else
                app.selected_analytes = chosen_analyte;
            end

            update_drift_plot(app)
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            %value = app.SampleDropDown.Value;

            update_drift_plot(app)
        end

        % Value changed function: LogscaleCheckBox
        function LogscaleCheckBoxValueChanged(app, event)
            update_drift_plot(app)

        end

        % Value changed function: ShowCommentsCheckBox
        function ShowCommentsCheckBoxValueChanged(app, event)
            %value = app.ShowCommentsCheckBox.Value;
            update_results_table(app)
        end

        % Callback function
        function CalculateButtonPushed(app, event)

            update_MSF_plot(app)

        end

        % Value changed function: ComparetoDropDown
        function ComparetoDropDownValueChanged(app, event)
            update_MSF_plot(app)

        end

        % Value changed function: SensitivitybasedonDropDown
        function SensitivitybasedonDropDownValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: RatioListBox
        function RatioListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: DenominatorListBox
        function DenominatorListBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Value changed function: ShowLegendCheckBox
        function ShowLegendCheckBoxValueChanged(app, event)
            update_MSF_plot(app)
        end

        % Menu selected function: OutputallmatrixmassfractionsggMenu
        function OutputallmatrixmassfractionsggMenuSelected(app, event)

            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_matrix_mass_fractions(app,outputpath)


        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)


            if isempty(app.MSF_ratios)
                message={'No Data to be exported'};
                uialert(app.UIFigure, message,' Warning','Icon','warning');
                return
            end


            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            writetable(app.MSF_ratios, outputpath, 'Sheet', 'MSF ratios', 'WriteRowNames', false);


        end

        % Value changed function: LogscaleCheckBox_2
        function LogscaleCheckBox_2ValueChanged(app, event)

            update_srm_plot(app)
        end

        % Menu selected function: OutputallinclusionmassfractionsggMenu
        function OutputallinclusionmassfractionsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_inclusion_mass_fractions(app, outputpath);
        end

        % Menu selected function: OutputallmatrixLODsggMenu
        function OutputallmatrixLODsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_matrix_lods(app,outputpath)
        end

        % Menu selected function: OutputallinclusionLODsggMenu
        function OutputallinclusionLODsggMenuSelected(app, event)
            [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            export_all_inclusion_lods(app,outputpath)
        end

        % Menu selected function: ChangeMetadataFolderMenu
        function ChangeMetadataFolderMenuSelected(app, event)
            % Open folder selection dialog
            selectedFolder = uigetdir('', 'Select metadata Folder');

            % Check if user selected a folder (not cancelled)
            if selectedFolder == 0
                return
            end

            % Store the selected folder path (adjust property name as needed)
            app.base.project.session.config.metadata_abbreviations_path = selectedFolder;

            % Call your existing function
            get_material_abbreviation_file(app)
            get_sample_abbreviation_file(app)
            get_location_abbreviation_file(app)

            update_metadata_table(app)
        end

        % Button pushed function: GetmetadatafromabbreviationsheetsButton
        function GetmetadatafromabbreviationsheetsButtonPushed(app, event)
            progress = uiprogressdlg(app.UIFigure, 'Title', 'ICP-Base', ...
                'Message', 'Fetching metadata...', 'Cancelable', 'off', 'Indeterminate','on');


            get_material_abbreviation_file(app)
            get_sample_abbreviation_file(app)
            get_location_abbreviation_file(app)
            
            assign_sample_to_spot(app)
            assign_material_to_spot(app)
            assign_location_to_spot(app)
            
            update_metadata_table(app)

            delete(progress)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

             selected_string = app.SpotnamecontainsEditField.Value;

           % Determine the logical operator (&& or ||)
           if contains(selected_string, '&&')
               operator = 'AND';
               substrings = strsplit(selected_string, '&&');
               % Trim any whitespace around the substrings
               substrings = strtrim(substrings);

               % Initialize an empty array to store the indices of the selected spots
               selected_spot_indices = [];

               % Iterate through the spot names
               for i = 1:length(app.base.project.session.spot.name)
                   % Check the condition based on the operator
                   if strcmp(operator, 'AND')
                       % Check if the spot name contains all the substrings
                       condition = all(cellfun(@(s) contains(app.base.project.session.spot.name{i}, s), substrings));
                   end

                   % Check the condition
                   if condition
                       % If it does, add the index to the selected_spot_indices array
                       selected_spot_indices(end+1) = i; %#ok
                   end
               end


           elseif contains(selected_string, '||')
               operator = 'OR';
               substrings = strsplit(selected_string, '||');
               % Trim any whitespace around the substrings
               substrings = strtrim(substrings);

               % Initialize an empty array to store the indices of the selected spots
               selected_spot_indices = [];

               % Iterate through the spot names
               for i = 1:length(app.base.project.session.spot.name)
                   % Check the condition based on the operator
                   if strcmp(operator, 'OR')
                       % Check if the spot name contains at least one of the substrings
                       condition = any(cellfun(@(s) contains(app.base.project.session.spot.name{i}, s), substrings));
                   end

                   % Check the condition
                   if condition
                       % If it does, add the index to the selected_spot_indices array
                       selected_spot_indices(end+1) = i; %#ok
                   end
               end


           else
               % this is the original code
               % Initialize an empty array to store the indices of the selected spots
 

                selected_spot_indices = [];
               % Iterate through the spot names
               for i = 1:length(app.base.project.session.spot.name)
                   % Check if the spot name contains the selected string
                   if contains(app.base.project.session.spot.name{i}, selected_string)
                       selected_spot_indices(end +1) = i; %#ok
                   end
               end

           end


            switch app.StringCompareDropDown.Value
                case 'Sample'
                    app.base.project.session.meta.sample(selected_spot_indices) = {app.MetadataInputEditField.Value};
                case 'Material'
                    app.base.project.session.meta.material(selected_spot_indices)  = {app.MetadataInputEditField.Value};
                case 'Location'
                    app.base.project.session.meta.location(selected_spot_indices)  = {app.MetadataInputEditField.Value};
            end

            update_metadata_table(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        
            app.base.project.session.meta.sample = repmat({'Unknown'}, size(app.base.project.session.spot.name));
            app.base.project.session.meta.material = repmat({'Unknown'}, size(app.base.project.session.spot.name));
            app.base.project.session.meta.location = repmat({'Unknown'}, size(app.base.project.session.spot.name));

            update_metadata_table(app)
        end

        % Cell edit callback: MetadataTable
        function MetadataTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            selectedRows = unique(app.MetadataTable.Selection(:, 1));

            for spot_idx = selectedRows'

                switch app.MetadataTable.ColumnName{indices(2)}
                    case 'Sample'
                        app.base.project.session.meta.sample(selectedRows) = {event.NewData};
                    case 'Material'
                        app.base.project.session.meta.material(selectedRows)  = {event.NewData};
                    case 'Location'
                        app.base.project.session.meta.location(selectedRows)  = {event.NewData};
                end

            end

            update_metadata_table(app)
        end

        % Menu selected function: AdjustSRMAxisFontsizeMenu
        function AdjustSRMAxisFontsizeMenuSelected(app, event)
            fontsizevalue = inputdlg('Enter Fontsize:','Set Fontsize', 1,{'12'});
            figure(app.UIFigure);

            if ~isempty(fontsizevalue)
                fontsizevalue=unique(str2num(fontsizevalue{1})); %#ok<ST2NM>
            else
                return
            end

            if isnumeric(fontsizevalue) && not(isnan(fontsizevalue)) && not(isinf(fontsizevalue))

                app.SRMAxes.FontSize = fontsizevalue;

            end


        end

        % Menu selected function: AdjustSRMAxisMarkersizeMenu
        function AdjustSRMAxisMarkersizeMenuSelected(app, event)
            markersizevalue = inputdlg('Enter Marker Size:', 'Set Marker Size', 1, {'6'});
            figure(app.UIFigure);

            if ~isempty(markersizevalue)
                markersizevalue = unique(str2num(markersizevalue{1})); %#ok<ST2NM>
            else
                return
            end

            if isnumeric(markersizevalue) && ~isnan(markersizevalue) && ~isinf(markersizevalue) && markersizevalue > 0
                % Find all line objects in the axes that have markers
                lines = findobj(app.SRMAxes, 'Type', 'line', 'Marker', 'o');

                % Set the marker size for all found line objects
                for i = 1:length(lines)
                    lines(i).MarkerSize = markersizevalue;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'icon_48.png';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SavetoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SavetoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBaseandExitMenuSelected, true);
            app.SavetoBaseandExitMenu.Text = 'Save to Base and Exit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.AdjustSRMAxisFontsizeMenu = uimenu(app.SettingsMenu);
            app.AdjustSRMAxisFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustSRMAxisFontsizeMenuSelected, true);
            app.AdjustSRMAxisFontsizeMenu.Text = 'Adjust SRM Axis Fontsize';
            app.AdjustSRMAxisMarkersizeMenu = uimenu(app.SettingsMenu);
            app.AdjustSRMAxisMarkersizeMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustSRMAxisMarkersizeMenuSelected, true);
            app.AdjustSRMAxisMarkersizeMenu.Text = 'Adjust SRM Axis Markersize';
            app.ChangeMetadataFolderMenu = uimenu(app.SettingsMenu);
            app.ChangeMetadataFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeMetadataFolderMenuSelected, true);
            app.ChangeMetadataFolderMenu.Text = 'Change Metadata Folder';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.Text = 'Results';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout2 = uigridlayout(app.ResultsTab);
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.HostInclusionDropDown = uidropdown(app.GridLayout2);
            app.HostInclusionDropDown.Items = {};
            app.HostInclusionDropDown.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDownValueChanged, true);
            app.HostInclusionDropDown.Value = {};
            app.CreateOutputTableButton = uibutton(app.GridLayout2, 'push');
            app.CreateOutputTableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputTableButtonPushed, true);
            app.CreateOutputTableButton.Text = 'Create Output Table';
            app.PrimaryReferenceMaterialDropDownLabel = uilabel(app.GridLayout2);
            app.PrimaryReferenceMaterialDropDownLabel.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown = uidropdown(app.GridLayout2);
            app.PrimaryReferenceMaterialDropDown.Items = {};
            app.PrimaryReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.PrimaryReferenceMaterialDropDown.Value = {};
            app.BracketIdDropDownLabel = uilabel(app.GridLayout2);
            app.BracketIdDropDownLabel.Text = 'Bracket Id';
            app.BracketIdDropDown = uidropdown(app.GridLayout2);
            app.BracketIdDropDown.Items = {};
            app.BracketIdDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketIdDropDown.Value = {};
            app.ShowDropDownLabel = uilabel(app.GridLayout2);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.GridLayout2);
            app.ShowDropDown.Items = {'Mass Fractions [µg/g]', 'Mass Fractions [µg/g] unfiltered', 'Sensitivities', 'PRM Sensitivities', 'CPS', 'Limits of detection', 'Majors as Oxides', 'Fe2+ and Fe3+'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Mass Fractions [µg/g]';
            app.EnddataprocessingButton = uibutton(app.GridLayout2, 'push');
            app.EnddataprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @EnddataprocessingButtonPushed, true);
            app.EnddataprocessingButton.Text = 'End data processing';
            app.ShowCommentsCheckBox = uicheckbox(app.GridLayout2);
            app.ShowCommentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCommentsCheckBoxValueChanged, true);
            app.ShowCommentsCheckBox.Text = 'Show Comments';
            app.AddMetadataTab = uitab(app.TabGroup);
            app.AddMetadataTab.Title = 'Add Metadata';
            app.MetadataTable = uitable(app.AddMetadataTab);
            app.MetadataTable.ColumnName = '';
            app.MetadataTable.RowName = {};
            app.MetadataTable.ColumnEditable = [false false true true true];
            app.MetadataTable.CellEditCallback = createCallbackFcn(app, @MetadataTableCellEdit, true);
            app.GetmetadatafromabbreviationsheetsButton = uibutton(app.AddMetadataTab, 'push');
            app.GetmetadatafromabbreviationsheetsButton.ButtonPushedFcn = createCallbackFcn(app, @GetmetadatafromabbreviationsheetsButtonPushed, true);
            app.GetmetadatafromabbreviationsheetsButton.Text = 'Get metadata from abbreviation sheets';
            app.Panel = uipanel(app.AddMetadataTab);
            app.GridLayout_2 = uigridlayout(app.Panel);
            app.ApplyButton = uibutton(app.GridLayout_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StringCompareDropDown = uidropdown(app.GridLayout_2);
            app.StringCompareDropDown.Items = {'Sample', 'Material', 'Location'};
            app.StringCompareDropDown.Value = 'Sample';
            app.SpotnamecontainsEditFieldLabel = uilabel(app.GridLayout_2);
            app.SpotnamecontainsEditFieldLabel.Text = 'Spotname contains:';
            app.SpotnamecontainsEditField = uieditfield(app.GridLayout_2, 'text');
            app.MetadataInputEditField = uieditfield(app.GridLayout_2, 'text');
            app.ResetButton = uibutton(app.AddMetadataTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.QualityControlTab = uitab(app.TabGroup);
            app.QualityControlTab.Title = 'Quality Control';
            app.TabGroup2 = uitabgroup(app.QualityControlTab);
            app.SecondaryReferenceMaterialTab = uitab(app.TabGroup2);
            app.SecondaryReferenceMaterialTab.Title = 'Secondary Reference Material';
            app.SRMAxes = uiaxes(app.SecondaryReferenceMaterialTab);
            xlabel(app.SRMAxes, 'Analyte')
            ylabel(app.SRMAxes, 'Measured Values/Accepted Values')
            zlabel(app.SRMAxes, 'Z')
            app.SRMAxes.XLim = [0 1];
            app.GotoCorrectionModuleButton = uibutton(app.SecondaryReferenceMaterialTab, 'push');
            app.GotoCorrectionModuleButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCorrectionModuleButtonPushed, true);
            app.GotoCorrectionModuleButton.Enable = 'off';
            app.GotoCorrectionModuleButton.Text = 'Go to Correction Module';
            app.PrimaryReferenceMaterialDropDown_2Label = uilabel(app.SecondaryReferenceMaterialTab);
            app.PrimaryReferenceMaterialDropDown_2Label.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown_2 = uidropdown(app.SecondaryReferenceMaterialTab);
            app.PrimaryReferenceMaterialDropDown_2.Items = {};
            app.PrimaryReferenceMaterialDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDown_2ValueChanged, true);
            app.PrimaryReferenceMaterialDropDown_2.Value = {};
            app.BracketIdDropDown_2Label = uilabel(app.SecondaryReferenceMaterialTab);
            app.BracketIdDropDown_2Label.Text = 'Bracket Id';
            app.BracketIdDropDown_2 = uidropdown(app.SecondaryReferenceMaterialTab);
            app.BracketIdDropDown_2.Items = {};
            app.BracketIdDropDown_2.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDown_2ValueChanged, true);
            app.BracketIdDropDown_2.Value = {};
            app.SecondaryReferenceMaterialDropDownLabel = uilabel(app.SecondaryReferenceMaterialTab);
            app.SecondaryReferenceMaterialDropDownLabel.Text = 'Secondary Reference Material';
            app.SecondaryReferenceMaterialDropDown = uidropdown(app.SecondaryReferenceMaterialTab);
            app.SecondaryReferenceMaterialDropDown.Items = {};
            app.SecondaryReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondaryReferenceMaterialDropDownValueChanged, true);
            app.SecondaryReferenceMaterialDropDown.Value = {};
            app.LogscaleCheckBox_2 = uicheckbox(app.SecondaryReferenceMaterialTab);
            app.LogscaleCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBox_2ValueChanged, true);
            app.LogscaleCheckBox_2.Text = 'Log-scale';
            app.MatrixSensitivityFactorMSFTab = uitab(app.TabGroup2);
            app.MatrixSensitivityFactorMSFTab.Title = 'Matrix Sensitivity Factor (MSF)';
            app.MSFAxes = uiaxes(app.MatrixSensitivityFactorMSFTab);
            xlabel(app.MSFAxes, 'X')
            ylabel(app.MSFAxes, 'Y')
            zlabel(app.MSFAxes, 'Z')
            app.SensitivitybasedonDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDownLabel.Text = 'Sensitivity based on:';
            app.SensitivitybasedonDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.SensitivitybasedonDropDown.Items = {'Mean', 'Min', 'Max'};
            app.SensitivitybasedonDropDown.ValueChangedFcn = createCallbackFcn(app, @SensitivitybasedonDropDownValueChanged, true);
            app.SensitivitybasedonDropDown.Value = 'Mean';
            app.RatioListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.RatioListBox.Items = {};
            app.RatioListBox.Multiselect = 'on';
            app.RatioListBox.ValueChangedFcn = createCallbackFcn(app, @RatioListBoxValueChanged, true);
            app.RatioListBox.Value = {};
            app.ComparetoDropDownLabel = uilabel(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDownLabel.Text = 'Compare to:';
            app.ComparetoDropDown = uidropdown(app.MatrixSensitivityFactorMSFTab);
            app.ComparetoDropDown.Items = {'', ''};
            app.ComparetoDropDown.ValueChangedFcn = createCallbackFcn(app, @ComparetoDropDownValueChanged, true);
            app.ComparetoDropDown.Value = '';
            app.DenominatorListBox = uilistbox(app.MatrixSensitivityFactorMSFTab);
            app.DenominatorListBox.Items = {};
            app.DenominatorListBox.Multiselect = 'on';
            app.DenominatorListBox.ValueChangedFcn = createCallbackFcn(app, @DenominatorListBoxValueChanged, true);
            app.DenominatorListBox.Value = {};
            app.ShowLegendCheckBox = uicheckbox(app.MatrixSensitivityFactorMSFTab);
            app.ShowLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLegendCheckBoxValueChanged, true);
            app.ShowLegendCheckBox.Text = 'Show Legend';
            app.ShowLegendCheckBox.Value = true;
            app.ExportTableButton = uibutton(app.MatrixSensitivityFactorMSFTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.SessionDriftTab = uitab(app.TabGroup2);
            app.SessionDriftTab.Title = 'Session Drift';
            app.DriftAxes = uiaxes(app.SessionDriftTab);
            xlabel(app.DriftAxes, 'Spot')
            ylabel(app.DriftAxes, 'Mass Fraction [µg/g]')
            zlabel(app.DriftAxes, 'Z')
            app.PrimaryReferenceMaterialDropDown_3Label = uilabel(app.SessionDriftTab);
            app.PrimaryReferenceMaterialDropDown_3Label.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown_3 = uidropdown(app.SessionDriftTab);
            app.PrimaryReferenceMaterialDropDown_3.Items = {};
            app.PrimaryReferenceMaterialDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDown_3ValueChanged, true);
            app.PrimaryReferenceMaterialDropDown_3.Value = {};
            app.BracketIdDropDown_3Label = uilabel(app.SessionDriftTab);
            app.BracketIdDropDown_3Label.Text = 'Bracket Id';
            app.BracketIdDropDown_3 = uidropdown(app.SessionDriftTab);
            app.BracketIdDropDown_3.Items = {};
            app.BracketIdDropDown_3.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDown_3ValueChanged, true);
            app.BracketIdDropDown_3.Value = {};
            app.SampleDropDownLabel = uilabel(app.SessionDriftTab);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.SessionDriftTab);
            app.SampleDropDown.Items = {};
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.SampleDropDown.Value = {};
            app.ElementLegendListBox = uilistbox(app.SessionDriftTab);
            app.ElementLegendListBox.Items = {''};
            app.ElementLegendListBox.Multiselect = 'on';
            app.ElementLegendListBox.ValueChangedFcn = createCallbackFcn(app, @ElementLegendListBoxValueChanged, true);
            app.ElementLegendListBox.Value = {''};
            app.LogscaleCheckBox = uicheckbox(app.SessionDriftTab);
            app.LogscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogscaleCheckBoxValueChanged, true);
            app.LogscaleCheckBox.Text = 'Log-scale';
            app.LogscaleCheckBox.Value = true;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.RefreshMenu = uimenu(app.ContextMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.OutputallmatrixmassfractionsggMenu = uimenu(app.ContextMenu2);
            app.OutputallmatrixmassfractionsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallmatrixmassfractionsggMenuSelected, true);
            app.OutputallmatrixmassfractionsggMenu.Text = 'Output all matrix mass fractions [µg/g]';
            app.OutputallinclusionmassfractionsggMenu = uimenu(app.ContextMenu2);
            app.OutputallinclusionmassfractionsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallinclusionmassfractionsggMenuSelected, true);
            app.OutputallinclusionmassfractionsggMenu.Text = 'Output all inclusion mass fractions [µg/g]';
            app.OutputallmatrixLODsggMenu = uimenu(app.ContextMenu2);
            app.OutputallmatrixLODsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallmatrixLODsggMenuSelected, true);
            app.OutputallmatrixLODsggMenu.Text = 'Output all matrix LODs [µg/g]';
            app.OutputallinclusionLODsggMenu = uimenu(app.ContextMenu2);
            app.OutputallinclusionLODsggMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputallinclusionLODsggMenuSelected, true);
            app.OutputallinclusionLODsggMenu.Text = 'Output all inclusion LODs [µg/g]';
            % Assign app.ContextMenu2
            app.CreateOutputTableButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end