classdef Interference_Correction_Submodule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        toallspotscontainingEditField  matlab.ui.control.EditField
        toallspotscontainingEditFieldLabel  matlab.ui.control.Label
        AssignIDEditField              matlab.ui.control.NumericEditField
        AssignIDEditFieldLabel         matlab.ui.control.Label
        ICMIDDropDown                  matlab.ui.control.DropDown
        ICMIDLabel                     matlab.ui.control.Label
        AssignButton                   matlab.ui.control.Button
        RemoveRowButton                matlab.ui.control.Button
        AddRowButton                   matlab.ui.control.Button
        AnalyteListBox                 matlab.ui.control.ListBox
        AssignmentTable                matlab.ui.control.Table
        ICMTable                       matlab.ui.control.Table
        ProceedtoDriftModuleButton     matlab.ui.control.Button
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
    %                     %%%      ICP-Base     %%%                        %
    %                     %%%%%%%%%%%%%%%%%%%%%%%%%                        %
    %                                                                      %
    %  Copyright (C) 2026 Sebastian Stumpf                                 %
    %                                                                      %
    %  This program is free software: you can redistribute it and/or       %
    %  modify it under the terms of the GNU General Public License as      %
    %  published by the Free Software Foundation, either version 3 of      %
    %  the License, or any later version.                                  %
    %                                                                      %
    %  This program is distributed in the hope that it will be useful,     %
    %  but WITHOUT ANY WARRANTY; without even the implied warranty of      %
    %  MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the        %
    %  GNU General Public License for more details.                        %
    %                                                                      %
    %  You should have received a copy of the GNU General Public License   %
    %  along with this program. If not, see <https://www.gnu.org/licenses/>%
    %                                                                      %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = private)
        source
        icm_progress

        icm_bracket_id
        correction_pairs

        selected_assignment_cell

    end

    methods (Access = private)

        function update_icm_table(app)
            sess         = app.source.base.project.session;
            icm_mask     = sess.spot.type == "ICM";
            spot_numbers = num2cell(find(icm_mask));
            spotnames    = cellstr(sess.spot.name(icm_mask));
            ids          = num2cell(app.icm_bracket_id(icm_mask));

            T = cell2table([spot_numbers, spotnames, ids], ...
                'VariableNames', {'#', 'Spotname', 'ID'});

            app.ICMTable.Data           = T;
            app.ICMTable.ColumnName     = {'#', 'Spotname', 'ID'};
            app.ICMTable.ColumnEditable = [false, false, true];
        end

        function update_id_dropdown(app)
            sess     = app.source.base.project.session;
            icm_mask = sess.spot.type == "ICM";
            ids      = unique(app.icm_bracket_id(icm_mask));
            ids      = ids(ids ~= 0);

            app.ICMIDDropDown.Items     = cellstr(string(ids));
            app.ICMIDDropDown.ItemsData = num2cell(ids);
        end

        function update_analyte_listbox(app)
            analytes                 = cellstr(app.source.base.project.session.spot.analytes);
            app.AnalyteListBox.Items = analytes;
        end

        function update_assignment_table(app)
            if isempty(app.ICMIDDropDown.Items)
                app.AssignmentTable.Data           = table(string.empty(0,1), string.empty(0,1), ...
                    'VariableNames', {'Interferer', 'Target'});
                app.AssignmentTable.ColumnName     = {'Interferer', 'Target'};
                app.AssignmentTable.ColumnEditable = [false, false];
                return
            end

            selected_id = app.ICMIDDropDown.Value;
            mask        = app.correction_pairs.ID == selected_id;
            pairs       = app.correction_pairs(mask, {'Interferer', 'Target'});

            app.AssignmentTable.Data           = pairs;
            app.AssignmentTable.ColumnName     = {'Interferer', 'Target'};
            app.AssignmentTable.ColumnEditable = [false, false];
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, icm_progress)
            app.source       = sourceapp;
            app.icm_progress = icm_progress;

            sess     = app.source.base.project.session;
            icm_mask = sess.spot.type == "ICM";

            % Initialize local ICM bracket IDs
            if isfield(sess.spot, 'icm_bracket_id')
                app.icm_bracket_id = sess.spot.icm_bracket_id;
            else
                app.icm_bracket_id = zeros(numel(sess.spot.name), 1);
                app.icm_bracket_id(icm_mask) = 1;
            end

            % Initialize local correction pairs
            if isfield(sess.spot, 'icm_correction_pairs') && ~isempty(sess.spot.icm_correction_pairs)
                app.correction_pairs = sess.spot.icm_correction_pairs;
            else
                app.correction_pairs = table(double.empty(0,1), string.empty(0,1), string.empty(0,1), ...
                    'VariableNames', {'ID', 'Interferer', 'Target'});
            end

            update_icm_table(app);
            update_id_dropdown(app);
            update_analyte_listbox(app);
            update_assignment_table(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Closing will discard your interference correction configuration.', ...
                'Discard Configuration', ...
                'Options', {'Close', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 2);

            if strcmp(selection, 'Close')
                delete(app)
            end
        end

        % Button pushed function: ProceedtoDriftModuleButton
        function ProceedtoDriftModuleButtonPushed(app, event)
            pairs = app.correction_pairs;

            % No assignments at all
            if isempty(pairs) || height(pairs) == 0
                uialert(app.UIFigure, ...
                    'No interference corrections have been assigned. Add at least one Interferer → Target pair before proceeding.', ...
                    'No Assignments');
                return
            end

            % Incomplete rows (empty Interferer or Target)
            incomplete = pairs.Interferer == "" | pairs.Target == "";
            if any(incomplete)
                uialert(app.UIFigure, ...
                    'One or more rows have an empty Interferer or Target. Fill in all rows or remove incomplete ones before proceeding.', ...
                    'Incomplete Assignment');
                return
            end

            % Interferer same as Target
            if any(pairs.Interferer == pairs.Target)
                uialert(app.UIFigure, ...
                    'One or more rows have the same analyte as both Interferer and Target. An analyte cannot interfere with itself.', ...
                    'Invalid Assignment');
                return
            end

            % Target must have higher mass number than interferer
            interferer_mass = str2double(regexp(pairs.Interferer, '\d+$', 'match', 'once'));
            target_mass     = str2double(regexp(pairs.Target,     '\d+$', 'match', 'once'));
            if any(target_mass <= interferer_mass)
                uialert(app.UIFigure, ...
                    'Target must have a higher mass number than the interferer (e.g., Zr90 → Ag107).', ...
                    'Invalid Mass Order');
                return
            end

            % Warn if any ICM ID has only one spot (no regression possible)
            icm_mask   = app.source.base.project.session.spot.type == "ICM";
            icm_ids    = app.icm_bracket_id(icm_mask);
            unique_ids = unique(icm_ids);
            singleton_ids = unique_ids(arrayfun(@(id) sum(icm_ids == id) == 1, unique_ids));
            if ~isempty(singleton_ids)
                id_str    = strjoin(string(singleton_ids), ', ');
                selection = uiconfirm(app.UIFigure, ...
                    sprintf(['ICM ID(s) %s have only one spot assigned. Without multiple spots, ' ...
                    'no regression over time is possible — the same correction factor will be ' ...
                    'applied to all spots regardless of sensitivity drift, possibly resulting in errorneous correction. Proceed anyway?'], id_str), ...
                    'Single ICM Spot', ...
                    'Options', {'Proceed', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2);
                if strcmp(selection, 'Cancel')
                    return
                end
            end

            % Duplicate interferer→target across IDs
            unique_pairs = unique(pairs(:, {'Interferer', 'Target'}), 'rows');
            if height(unique_pairs) < height(pairs)
                uialert(app.UIFigure, ...
                    'The same Interferer → Target pair is assigned to multiple IDs. Each pair can only appear once across all IDs.', ...
                    'Duplicate Assignment');
                return
            end

            % Write local variables back to session
            app.source.base.project.session.spot.icm_bracket_id       = app.icm_bracket_id;
            app.source.base.project.session.spot.icm_correction_pairs = app.correction_pairs;

            delete(app.icm_progress)
            app.source.launch_drift_module()

            delete(app.source)
            delete(app)
        end

        % Cell edit callback: ICMTable
        function ICMTableCellEdit(app, event)
            newData = event.NewData;
            indices = event.Indices;

            if ~isnumeric(newData) || newData < 1 || floor(newData) ~= newData
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                update_icm_table(app);
                return
            end

            % Find which spot this row corresponds to
            spot_num = app.ICMTable.Data{indices(1), '#'};
            app.icm_bracket_id(spot_num) = newData;

            valid_ids = unique(app.icm_bracket_id(app.source.base.project.session.spot.type == "ICM"));
            app.correction_pairs = app.correction_pairs(ismember(app.correction_pairs.ID, valid_ids), :);

            update_icm_table(app);
            update_id_dropdown(app);
            update_assignment_table(app);
        end

        % Value changed function: ICMIDDropDown
        function ICMIDDropDownValueChanged(app, event)
           update_assignment_table(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            selected_id = app.ICMIDDropDown.Value;
            new_row     = table(selected_id, string(""), string(""), ...
                'VariableNames', {'ID', 'Interferer', 'Target'});
            app.correction_pairs = [app.correction_pairs; new_row];
            update_assignment_table(app);
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            if isempty(app.AssignmentTable.Selection)
                return
            end

            selected_id  = app.ICMIDDropDown.Value;
            selected_row = app.AssignmentTable.Selection(1, 1);
            mask         = app.correction_pairs.ID == selected_id;
            id_rows      = find(mask);

            if selected_row > numel(id_rows)
                return
            end

            app.correction_pairs(id_rows(selected_row), :) = [];
            update_assignment_table(app);
        end

        % Value changed function: AnalyteListBox
        function AnalyteListBoxValueChanged(app, event)
            if isempty(app.selected_assignment_cell)
                return
            end

            selected_analyte = app.AnalyteListBox.Value;
            selected_row     = app.selected_assignment_cell(1);
            selected_col     = app.selected_assignment_cell(2);

            selected_id = app.ICMIDDropDown.Value;
            mask        = app.correction_pairs.ID == selected_id;
            id_rows     = find(mask);

            if selected_row > numel(id_rows)
                return
            end

            global_row = id_rows(selected_row);

            switch selected_col
                case 1
                    app.correction_pairs.Interferer(global_row) = string(selected_analyte);
                case 2
                    app.correction_pairs.Target(global_row) = string(selected_analyte);
            end

            update_assignment_table(app);
        end

        % Callback function: AssignmentTable
        function AssignmentTableSelectionChanged(app, event)
            if ~isempty(app.AssignmentTable.Selection)
                app.selected_assignment_cell = app.AssignmentTable.Selection(1, :);
            end
        end

        % Button pushed function: AssignButton
        function AssignButtonPushed(app, event)
            pattern = app.toallspotscontainingEditField.Value;
            id_val  = app.AssignIDEditField.Value;

            if isnan(id_val) || id_val < 1 || floor(id_val) ~= id_val
                uialert(app.UIFigure, 'ID must be a positive integer.', 'Invalid Input');
                return
            end

            sess        = app.source.base.project.session;
            icm_mask    = sess.spot.type == "ICM";
            icm_indices = find(icm_mask);
            icm_names   = sess.spot.name(icm_mask);

            match_mask = contains(icm_names, pattern, 'IgnoreCase', true);
            if ~any(match_mask)
                uialert(app.UIFigure, 'No ICM spots found containing that string.', 'No Match');
                return
            end

            app.icm_bracket_id(icm_indices(match_mask)) = id_val;

            update_icm_table(app);
            update_id_dropdown(app);
            update_assignment_table(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Interference Correction Configuration';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProceedtoDriftModuleButton = uibutton(app.UIFigure, 'push');
            app.ProceedtoDriftModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoDriftModuleButtonPushed, true);
            app.ProceedtoDriftModuleButton.Text = 'Proceed to Drift Module';
            app.ICMTable = uitable(app.UIFigure);
            app.ICMTable.ColumnName = '';
            app.ICMTable.RowName = {};
            app.ICMTable.CellEditCallback = createCallbackFcn(app, @ICMTableCellEdit, true);
            app.AssignmentTable = uitable(app.UIFigure);
            app.AssignmentTable.ColumnName = '';
            app.AssignmentTable.RowName = {};
            app.AssignmentTable.SelectionChangedFcn = createCallbackFcn(app, @AssignmentTableSelectionChanged, true);
            app.AnalyteListBox = uilistbox(app.UIFigure);
            app.AnalyteListBox.Items = {};
            app.AnalyteListBox.ValueChangedFcn = createCallbackFcn(app, @AnalyteListBoxValueChanged, true);
            app.AnalyteListBox.Value = {};
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.RemoveRowButton = uibutton(app.UIFigure, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Text = 'Remove Row';
            app.AssignButton = uibutton(app.UIFigure, 'push');
            app.AssignButton.ButtonPushedFcn = createCallbackFcn(app, @AssignButtonPushed, true);
            app.AssignButton.Text = 'Assign';
            app.ICMIDLabel = uilabel(app.UIFigure);
            app.ICMIDLabel.Text = 'ICM ID:';
            app.ICMIDDropDown = uidropdown(app.UIFigure);
            app.ICMIDDropDown.Items = {};
            app.ICMIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ICMIDDropDownValueChanged, true);
            app.ICMIDDropDown.Value = {};
            app.AssignIDEditFieldLabel = uilabel(app.UIFigure);
            app.AssignIDEditFieldLabel.Text = 'Assign ID';
            app.AssignIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AssignIDEditField.Limits = [1 Inf];
            app.AssignIDEditField.Value = 1;
            app.toallspotscontainingEditFieldLabel = uilabel(app.UIFigure);
            app.toallspotscontainingEditFieldLabel.Text = 'to all spots containing';
            app.toallspotscontainingEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interference_Correction_Submodule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end